classdef CIEL_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        CIELAnalysisAppUIFigure         matlab.ui.Figure
        SavereducedButton               matlab.ui.control.Button
        OverwritecurrentmetaCheckBox    matlab.ui.control.CheckBox
        ReducebyEditField               matlab.ui.control.NumericEditField
        toLabel                         matlab.ui.control.Label
        ReduceButton                    matlab.ui.control.Button
        SavemetaButton                  matlab.ui.control.Button
        PathtometaEditField             matlab.ui.control.EditField
        PathtodatafileLabel_2           matlab.ui.control.Label
        BrowsemetaButton                matlab.ui.control.Button
        LoadmetaButton                  matlab.ui.control.Button
        PathtofileEditField             matlab.ui.control.EditField
        PathtodatafileLabel             matlab.ui.control.Label
        BrowsedataButton                matlab.ui.control.Button
        LoaddataButton                  matlab.ui.control.Button
        TabGroup                        matlab.ui.container.TabGroup
        PreviewTab                      matlab.ui.container.Tab
        Prev_MetaAquisitiontimeEditField  matlab.ui.control.NumericEditField
        AquisitiontimeminLabel          matlab.ui.control.Label
        PrevTabs                        matlab.ui.container.TabGroup
        Prev_ConfigTab                  matlab.ui.container.Tab
        Prev_Config_detdataLabel_3      matlab.ui.control.Label
        Prev_Config_detdataLabel_2      matlab.ui.control.Label
        Prev_Config_EventDetExistsCheckBox  matlab.ui.control.CheckBox
        Prev_Config_IonExistsCheckBox   matlab.ui.control.CheckBox
        Prev_Config_ElectronExistsCheckBox  matlab.ui.control.CheckBox
        Prev_Config_EventdetectorEditField  matlab.ui.control.NumericEditField
        EventdetectorEditFieldLabel     matlab.ui.control.Label
        Prev_Config_IondetectorEditField  matlab.ui.control.NumericEditField
        IondetectorEditFieldLabel       matlab.ui.control.Label
        Prev_Config_ElectrondetectorEditField  matlab.ui.control.NumericEditField
        ElectrondetectorEditFieldLabel  matlab.ui.control.Label
        Prev_Config_DataInfoTextArea    matlab.ui.control.TextArea
        INFOLabel                       matlab.ui.control.Label
        Prev_Config_DatastructureLabel  matlab.ui.control.Label
        Prev_Config_DataStructTree      matlab.ui.container.Tree
        Prev_Config_detdataLabel        matlab.ui.control.Label
        Prev_Config_DelayYN             matlab.ui.control.CheckBox
        Prev_Config_CepYN               matlab.ui.control.CheckBox
        Prev_Config_DelayRowEditField   matlab.ui.control.NumericEditField
        DelayEditFieldLabel             matlab.ui.control.Label
        Prev_Config_CEPRowEditField     matlab.ui.control.NumericEditField
        CEPEditFieldLabel               matlab.ui.control.Label
        Prev_Config_TimestampRowEditField  matlab.ui.control.NumericEditField
        TimestampEditFieldLabel         matlab.ui.control.Label
        Prev_Config_MetaTable           matlab.ui.control.Table
        Prev_ElectronsTab               matlab.ui.container.Tab
        Prev_Ele_MetaEleAveragecountrateEditField  matlab.ui.control.NumericEditField
        AveragecountrateEditField_2Label_2  matlab.ui.control.Label
        Prev_Ele_MetaElectronsEditField  matlab.ui.control.NumericEditField
        ElectronsEditFieldLabel_2       matlab.ui.control.Label
        Prev_Ele_WrapEditField          matlab.ui.control.NumericEditField
        WrapEditFieldLabel              matlab.ui.control.Label
        Prev_Ele_ReelespanEditField     matlab.ui.control.NumericEditField
        ReelespanEditFieldLabel         matlab.ui.control.Label
        Prev_Ele_UseonlyrealeleCheckBox  matlab.ui.control.CheckBox
        Prev_Ele_Electront0EditField    matlab.ui.control.NumericEditField
        Electront0EditFieldLabel        matlab.ui.control.Label
        Prev_Ele_EleBinsizeEditField    matlab.ui.control.NumericEditField
        BinsizeEditFieldLabel_2         matlab.ui.control.Label
        Prev_Ele_EleEndTOFEditField     matlab.ui.control.NumericEditField
        EndTOFEditFieldLabel_2          matlab.ui.control.Label
        Prev_Ele_EleStartTOFEditField   matlab.ui.control.NumericEditField
        StartTOFEditFieldLabel_2        matlab.ui.control.Label
        Prev_Ele_HitsPerEvent           matlab.ui.control.UIAxes
        Prev_Ele_Countrates             matlab.ui.control.UIAxes
        Prev_Ele_ElectronTof            matlab.ui.control.UIAxes
        Prev_Ele_EleXY                  matlab.ui.control.UIAxes
        Prev_Ele_FishYTOF               matlab.ui.control.UIAxes
        Prev_Ele_FishXTOF               matlab.ui.control.UIAxes
        Prev_IonsTab                    matlab.ui.container.Tab
        Prev_Ion_MetaIonAveragecountrateEditField  matlab.ui.control.NumericEditField
        AveragecountrateEditFieldLabel_2  matlab.ui.control.Label
        Prev_Ion_MetaIonsEditField      matlab.ui.control.NumericEditField
        IonsEditFieldLabel_2            matlab.ui.control.Label
        Prev_Ion_IonBinsizeEditField    matlab.ui.control.NumericEditField
        BinsizeEditFieldLabel           matlab.ui.control.Label
        Prev_Ion_IonEndTOFEditField     matlab.ui.control.NumericEditField
        EndTOFEditFieldLabel            matlab.ui.control.Label
        Prev_Ion_IonStartTOFEditField   matlab.ui.control.NumericEditField
        StartTOFEditFieldLabel          matlab.ui.control.Label
        Prev_Ion_IonYLogScaleCheckBox   matlab.ui.control.CheckBox
        Prev_Ion_HitsPerEvent           matlab.ui.control.UIAxes
        Prev_Ion_Countrates             matlab.ui.control.UIAxes
        Prev_Ion_FishYTOF               matlab.ui.control.UIAxes
        Prev_Ion_FishXTOF               matlab.ui.control.UIAxes
        Prev_Ion_IonTof                 matlab.ui.control.UIAxes
        Prev_Ion_IonXY                  matlab.ui.control.UIAxes
        Prev_ElectronIonTab             matlab.ui.container.Tab
        Prev_EI_MetaIonCoincidenceEditField  matlab.ui.control.NumericEditField
        CoincidenceEditFieldLabel       matlab.ui.control.Label
        Prev_EI_MetaIonAveragecountrateEditField  matlab.ui.control.NumericEditField
        AveragecountrateEditFieldLabel  matlab.ui.control.Label
        Prev_EI_MetaIonsEditField       matlab.ui.control.NumericEditField
        IonsEditFieldLabel              matlab.ui.control.Label
        Prev_EI_MetaEleCoincidenceEditField  matlab.ui.control.NumericEditField
        CoincidenceEditField_3Label     matlab.ui.control.Label
        Prev_EI_MetaEleAveragecountrateEditField  matlab.ui.control.NumericEditField
        AveragecountrateEditField_2Label  matlab.ui.control.Label
        Prev_EI_MetaElectronsEditField  matlab.ui.control.NumericEditField
        ElectronsEditFieldLabel         matlab.ui.control.Label
        Prev_EI_MetaEvCoincidenceEditField  matlab.ui.control.NumericEditField
        CoincidenceEditField_2Label     matlab.ui.control.Label
        Prev_EI_MetaEventsEditField     matlab.ui.control.NumericEditField
        EventsEditFieldLabel            matlab.ui.control.Label
        Prev_EI_HitsPerEvent            matlab.ui.control.UIAxes
        Prev_EI_Countrates              matlab.ui.control.UIAxes
        Prev_LightsourceTab             matlab.ui.container.Tab
        Prev_Config_DatastructureLabel_2  matlab.ui.control.Label
        Prev_LS_CEPPanel                matlab.ui.container.Panel
        Prev_LS_MetaCEPSTDEditField     matlab.ui.control.NumericEditField
        CEPSTDEditFieldLabel            matlab.ui.control.Label
        Prev_LS_MetaCEPmeanEditField    matlab.ui.control.NumericEditField
        CEPMeanLabel                    matlab.ui.control.Label
        Prev_LS_CEP                     matlab.ui.control.UIAxes
        Prev_LS_DelayPanel              matlab.ui.container.Panel
        Prev_LS_MetaStepsizeEditField   matlab.ui.control.NumericEditField
        StepsizeEditFieldLabel          matlab.ui.control.Label
        Prev_LS_MetaMaxdelayEditField   matlab.ui.control.NumericEditField
        MaxdelayEditFieldLabel          matlab.ui.control.Label
        Prev_LS_MetaMindelayEditField   matlab.ui.control.NumericEditField
        MindelayEditFieldLabel          matlab.ui.control.Label
        Prev_LS_Delay                   matlab.ui.control.UIAxes
        Prev_LS_TypeTabs                matlab.ui.container.TabGroup
        Prev_LS_LaserTab                matlab.ui.container.Tab
        Prev_LS_IsLaserCheckBox         matlab.ui.control.CheckBox
        Prev_LS_MetaNrBunchesEditField  matlab.ui.control.NumericEditField
        NrBunchesEditFieldLabel         matlab.ui.control.Label
        Prev_LS_MetaMaxTOFEditField     matlab.ui.control.NumericEditField
        MaxTOFminLabel                  matlab.ui.control.Label
        Prev_LS_MetaLaserrepetitionrateHzEditField  matlab.ui.control.NumericEditField
        LaserrepetitionrateHzEditFieldLabel  matlab.ui.control.Label
        FELTab                          matlab.ui.container.Tab
        Prev_LS_IsFELCheckBox           matlab.ui.control.CheckBox
        SynchrotronTab                  matlab.ui.container.Tab
        Prev_LS_IsSytrnCheckBox         matlab.ui.control.CheckBox
        Prev_UpdateplotsButton          matlab.ui.control.Button
        CalibrationTab                  matlab.ui.container.Tab
        Calib_UpdateliveCheckBox        matlab.ui.control.CheckBox
        Calib_EBoffsetEditField         matlab.ui.control.NumericEditField
        EBoffsetEditFieldLabel          matlab.ui.control.Label
        Calib_CIELparametersLabel       matlab.ui.control.Label
        Calib_toffsetEditField          matlab.ui.control.NumericEditField
        Calib_ScaleEBandtoffsCheckBox   matlab.ui.control.CheckBox
        Calib_EFieldEditField           matlab.ui.control.NumericEditField
        Calib_EFieldSlider              matlab.ui.control.Slider
        Calib_EFieldSumEditField        matlab.ui.control.NumericEditField
        Calib_BFieldEditField           matlab.ui.control.NumericEditField
        Calib_BFieldSlider              matlab.ui.control.Slider
        Calib_BFieldSumEditField        matlab.ui.control.NumericEditField
        EFieldVLabel_4                  matlab.ui.control.Label
        EFieldVLabel_3                  matlab.ui.control.Label
        EFieldVLabel_2                  matlab.ui.control.Label
        EFieldVLabel                    matlab.ui.control.Label
        LLabel                          matlab.ui.control.Label
        Calib_CyPeriodEditField         matlab.ui.control.NumericEditField
        Calib_dEditField                matlab.ui.control.NumericEditField
        Calib_LEditField                matlab.ui.control.NumericEditField
        Slider_2Label                   matlab.ui.control.Label
        CyclotronperiodLabel            matlab.ui.control.Label
        toffsetEditFieldLabel           matlab.ui.control.Label
        exDEditFieldLabel               matlab.ui.control.Label
        Calib_exDEditField              matlab.ui.control.NumericEditField
        dmEditFieldLabel                matlab.ui.control.Label
        SliderLabel                     matlab.ui.control.Label
        Calib_Tabs                      matlab.ui.container.TabGroup
        Calib_ElectronsTab              matlab.ui.container.Tab
        Calib_Ele_RotatexyzdegEditField  matlab.ui.control.EditField
        RotatexyzdegEditFieldLabel      matlab.ui.control.Label
        Calib_Ele_XYpolyEditField       matlab.ui.control.EditField
        TOFpolynomialcorrectionlowtohighordercommaseparatedLabel_2  matlab.ui.control.Label
        Calib_Ele_OffseteVEditField     matlab.ui.control.NumericEditField
        OffseteVEditFieldLabel          matlab.ui.control.Label
        Calib_Ele_SpacingeVEditField    matlab.ui.control.NumericEditField
        SpacingeVEditFieldLabel         matlab.ui.control.Label
        Calib_Ele_CalibrationlinesCheckBox  matlab.ui.control.CheckBox
        Calib_Ele_TOFpolyEditField      matlab.ui.control.EditField
        TOFpolynomialcorrectionlowtohighordercommaseparatedLabel  matlab.ui.control.Label
        Calib_Ele_NodesStopTOFEditField  matlab.ui.control.NumericEditField
        StopTOFLabel                    matlab.ui.control.Label
        Calib_Ele_NodesStartTOFEditField  matlab.ui.control.NumericEditField
        StartTOFEditFieldLabel_5        matlab.ui.control.Label
        Calib_Ele_Electront0EditField   matlab.ui.control.NumericEditField
        NominalTOFLabel                 matlab.ui.control.Label
        Calib_Ele_EleYEditField         matlab.ui.control.NumericEditField
        ElectronY0Label                 matlab.ui.control.Label
        Calib_Ele_EleXEditField         matlab.ui.control.NumericEditField
        ElectronX0Label                 matlab.ui.control.Label
        Calib_Ele_LiveconversionCheckBox  matlab.ui.control.CheckBox
        Calib_Ele_SpeciesfilterEditField  matlab.ui.control.NumericEditField
        SpeciesfilterEditFieldLabel     matlab.ui.control.Label
        Calib_Ele_SpeciesfilterCheckBox  matlab.ui.control.CheckBox
        Calib_Ele_IonposfilterCheckBox  matlab.ui.control.CheckBox
        Calib_Ele_IonposfilterEditField  matlab.ui.control.NumericEditField
        IonposfilterEditFieldLabel      matlab.ui.control.Label
        Calib_Ele_ConvertButton         matlab.ui.control.Button
        Calib_Ele_EleTOF                matlab.ui.control.UIAxes
        Calib_Ele_Nodes_YTOF            matlab.ui.control.UIAxes
        Calib_Ele_Nodes_XTOF            matlab.ui.control.UIAxes
        Calib_Ele_UpDown                matlab.ui.control.UIAxes
        Calib_Ele_eVtheta               matlab.ui.control.UIAxes
        Calib_Ele_pypz                  matlab.ui.control.UIAxes
        Calib_Ele_pxpz                  matlab.ui.control.UIAxes
        Calib_IonsTab                   matlab.ui.container.Tab
        Calib_IonYLogScaleCheckBox      matlab.ui.control.CheckBox
        Calib_IonYEditField             matlab.ui.control.NumericEditField
        IonY0Label                      matlab.ui.control.Label
        Calib_IonXEditField             matlab.ui.control.NumericEditField
        IonX0Label                      matlab.ui.control.Label
        Calib_SpeciesTable              matlab.ui.control.Table
        Calib_RemovespeciesButton       matlab.ui.control.Button
        Calib_AddspeciesButton          matlab.ui.control.Button
        Calib_IonTOF                    matlab.ui.control.UIAxes
        Calib_IonXY                     matlab.ui.control.UIAxes
        Calib_ElectronIonTab            matlab.ui.container.Tab
        Calib_LightsourceTab            matlab.ui.container.Tab
        Calib_SortingMultibunchmodeTab  matlab.ui.container.Tab
        Calib_DuplicatespeciesButton    matlab.ui.control.Button
        Sort_UpdateplotsButton          matlab.ui.control.Button
        Sort_PercentageofeventstosortEditField  matlab.ui.control.NumericEditField
        PercentageofeventstosortEditFieldLabel  matlab.ui.control.Label
        Sort_WrappingtimeEditField      matlab.ui.control.NumericEditField
        WrappingtimeEditFieldLabel      matlab.ui.control.Label
        Sort_ReplaceButton              matlab.ui.control.Button
        Sort_SaveButton                 matlab.ui.control.Button
        Sort_SortButton                 matlab.ui.control.Button
        Sort_RemovespeciesButton        matlab.ui.control.Button
        Sort_AddspeciesButton           matlab.ui.control.Button
        Sort_SpeciesTable               matlab.ui.control.Table
        Sort_XYfiltterEditField         matlab.ui.control.NumericEditField
        XYfiltterEditFieldLabel         matlab.ui.control.Label
        Sort_EleBinsizeEditField        matlab.ui.control.NumericEditField
        BinsizeEditFieldLabel_4         matlab.ui.control.Label
        Sort_EleEndTOFEditField         matlab.ui.control.NumericEditField
        EndTOFEditFieldLabel_4          matlab.ui.control.Label
        Sort_EleStartTOFEditField       matlab.ui.control.NumericEditField
        StartTOFEditFieldLabel_4        matlab.ui.control.Label
        Sort_IonBinsizeEditField        matlab.ui.control.NumericEditField
        BinsizeEditFieldLabel_3         matlab.ui.control.Label
        Sort_IonEndTOFEditField         matlab.ui.control.NumericEditField
        EndTOFEditFieldLabel_3          matlab.ui.control.Label
        Sort_IonStartTOFEditField       matlab.ui.control.NumericEditField
        StartTOFEditFieldLabel_3        matlab.ui.control.Label
        Sort_IonYLogScaleCheckBox       matlab.ui.control.CheckBox
        Sort_SortedElectronTof          matlab.ui.control.UIAxes
        Sort_SortedIonTof               matlab.ui.control.UIAxes
        Sort_IonTof                     matlab.ui.control.UIAxes
        Sort_ElectronTof                matlab.ui.control.UIAxes
        Calib_UpdateplotsButton         matlab.ui.control.Button
        ScansTab                        matlab.ui.container.Tab
        Scans_EnergystepEditField       matlab.ui.control.NumericEditField
        EnergystepLabel                 matlab.ui.control.Label
        Scans_SavescansButton           matlab.ui.control.Button
        Scans_NormalizesumperdelayCheckBox  matlab.ui.control.CheckBox
        Scans_EnergymaxEditField        matlab.ui.control.NumericEditField
        EnergymaxEditFieldLabel         matlab.ui.control.Label
        Scans_EnergyminEditField        matlab.ui.control.NumericEditField
        EnergyminEditFieldLabel         matlab.ui.control.Label
        Scans_UpdateplotsButton         matlab.ui.control.Button
        Scans_AngleSpanEditField        matlab.ui.control.NumericEditField
        AngledownLabel_2                matlab.ui.control.Label
        Scans_SpeciesfilterEditField    matlab.ui.control.NumericEditField
        SpeciesfilterEditFieldLabel_3   matlab.ui.control.Label
        Scans_IonposfilterEditField     matlab.ui.control.NumericEditField
        IonposfilterEditFieldLabel_3    matlab.ui.control.Label
        Scans_SpeciesfilterCheckBox     matlab.ui.control.CheckBox
        Scans_IonposfilterCheckBox      matlab.ui.control.CheckBox
        Scans_AngleDWNEditField         matlab.ui.control.NumericEditField
        AngledownLabel                  matlab.ui.control.Label
        Scans_AngleUPEditField          matlab.ui.control.NumericEditField
        AngleupLabel                    matlab.ui.control.Label
        Scans_CEPUP                     matlab.ui.control.UIAxes
        Scans_CEPDWN                    matlab.ui.control.UIAxes
        Scans_DelayDWN                  matlab.ui.control.UIAxes
        Scans_DelayUP                   matlab.ui.control.UIAxes
        AnacondaTab                     matlab.ui.container.Tab
        Ana_CopyfieldtocommandlineButton  matlab.ui.control.Button
        Ana_CreateUpdateTabs            matlab.ui.container.TabGroup
        PlotsTab                        matlab.ui.container.Tab
        Ana_CP_DetectorEditField        matlab.ui.control.EditField
        DetectorEditFieldLabel          matlab.ui.control.Label
        Ana_CP_NameEditField            matlab.ui.control.EditField
        NameEditFieldLabel              matlab.ui.control.Label
        Ana_CP_ConditionEditField       matlab.ui.control.EditField
        ConditionEditFieldLabel         matlab.ui.control.Label
        Ana_CP_XLabel2EditField         matlab.ui.control.EditField
        XLabelEditField_2Label_2        matlab.ui.control.Label
        Ana_CP_XLim2EditField           matlab.ui.control.EditField
        LimLabel_2                      matlab.ui.control.Label
        Ana_CP_Title1EditField          matlab.ui.control.EditField
        YLabelEditField_2Label          matlab.ui.control.Label
        Ana_CP_XLabel1EditField         matlab.ui.control.EditField
        XLabelEditFieldLabel            matlab.ui.control.Label
        Ana_CP_XLim1EditField           matlab.ui.control.EditField
        LimLabel                        matlab.ui.control.Label
        Ana_CP_Signal2EditField         matlab.ui.control.EditField
        Signal2EditFieldLabel           matlab.ui.control.Label
        Ana_CP_Signal1EditField         matlab.ui.control.EditField
        Signal1EditFieldLabel           matlab.ui.control.Label
        Ana_CP_CreateButton             matlab.ui.control.Button
        SignalsTab                      matlab.ui.container.Tab
        Ana_CloseallfiguresButton       matlab.ui.control.Button
        Ana_GoifdoButton                matlab.ui.control.Button
        Ana_plotCheckBox                matlab.ui.control.CheckBox
        Ana_convCheckBox                matlab.ui.control.CheckBox
        Ana_corrCheckBox                matlab.ui.control.CheckBox
        Ana_SetallifdofieldstoEditField  matlab.ui.control.NumericEditField
        SetallifdofieldstoEditFieldLabel  matlab.ui.control.Label
        Ana_GoButton                    matlab.ui.control.Button
        Ana_AnacondaLogTextArea         matlab.ui.control.TextArea
        LogLabel                        matlab.ui.control.Label
        Ana_PlotButton                  matlab.ui.control.Button
        Ana_ConvertButton               matlab.ui.control.Button
        Ana_CorrectButton               matlab.ui.control.Button
        Ana_RemovefieldButton           matlab.ui.control.Button
        Ana_DataLabel                   matlab.ui.control.Label
        Ana_mdataLabel                  matlab.ui.control.Label
        Ana_CommandEditField            matlab.ui.control.EditField
        CommandEditFieldLabel           matlab.ui.control.Label
        Ana_FieldvalueTextArea          matlab.ui.control.TextArea
        FieldvalueTextAreaLabel         matlab.ui.control.Label
        Ana_LoadTablesButton            matlab.ui.control.Button
        Ana_DataTree                    matlab.ui.container.Tree
        Ana_MdataTree                   matlab.ui.container.Tree
        AboutTab                        matlab.ui.container.Tab
        AbouttheappTextArea             matlab.ui.control.TextArea
        AbouttheappTextAreaLabel        matlab.ui.control.Label
    end

    % Other properties
    properties (Access = public)
        Data            % All loaded data
        Data_Sorted     % Data after sorting
        Data_Full       % To store full dataset when using subset, not working
        Data_Momentum   % Electron momentum data'
        mdata           % Anaconda metadata
    end
    
    properties (Access = private)
        app_path % Defined during start-up, to find the app settings from previous session, and also to save the same of shutdown

        % Constants
        me = 9.10938188e-31;
        e = 1.60217646e-19;
        Pamu = 1.99285191410e-24;
    end
    
    methods (Access = private)
        function plotPrev(app)
            % This function updates all elements in the Preview tab

            d = uiprogressdlg(app.CIELAnalysisAppUIFigure,'Title','Plotting...','Message','Updating plots','Indeterminate','on'); 

            h = findobj(app.PrevTabs,'Type','axes');
            for ii = 1:length(h)
                cla(h(ii));
            end

            % --------------------- Config --------------------------------

            % Data Tree
            a = app.Prev_Config_DataStructTree.Children;
            a.delete;
            fields = fieldnames(app.Data);
            for ii = 1:length(fields)
                tree = uitreenode(app.Prev_Config_DataStructTree,'Text',fields{ii},'NodeData','app.Data');
                fields2 = fieldnames(app.Data.(fields{ii}));
                for kk = 1:length(fields2)
                    tree2 = uitreenode(tree,'Text',fields2{kk},'NodeData',['app.Data.',fields{ii}]);
                    try 
                        fields3 = fieldnames(app.Data.(fields{ii}).(fields2{kk}));
                        for jj = 1:length(fields3)
                            uitreenode(tree2,'Text',fields3{jj},'NodeData',['app.Data.',fields{ii},'.', fields2{kk}]);
                        end
                    catch
                    end
                end
            end

            dirStyle = uistyle("FontWeight","bold");
            addStyle(app.Prev_Config_DataStructTree,dirStyle,"level",1)

            % ------------------ Meta info -------------------------------
            if app.Prev_Config_EventDetExistsCheckBox.Value
                metanr = app.Prev_Config_EventdetectorEditField.Value;
                metadet = ['det',num2str(metanr)];
                time_nr = app.Prev_Config_TimestampRowEditField.Value;
                app.Prev_MetaAquisitiontimeEditField.Value = max(app.Data.h.(metadet).raw(:,time_nr))/60; 
            end
        
            
            % ------------------ Electron tab -----------------------------
            if app.Prev_Config_ElectronExistsCheckBox.Value
                
                elenr = app.Prev_Config_ElectrondetectorEditField.Value;
                eledet = ['det',num2str(elenr)];
                                
                % General
                if app.Prev_Ele_UseonlyrealeleCheckBox.Value
                    e_filter = 1:length(app.Data.h.(eledet).raw(:,1));
                    e_filter = e_filter(abs(mod(app.Data.h.(eledet).raw(:,3),app.Prev_Ele_WrapEditField.Value)-app.Prev_Ele_Electront0EditField.Value) < app.Prev_Ele_ReelespanEditField.Value);
                    ev_index = ~ismember(app.Data.e.raw(:,elenr),e_filter);
            
                    Data_old = app.Data;
                    app.Data.e.raw(ev_index,1) = app.Data.e.raw(ev_index,1)*NaN;
                    app.Data.e.raw(~isnan(app.Data.e.raw(:,elenr)),elenr) = 1:sum(not(ev_index));
                    app.Data.h.(eledet).raw = app.Data.h.(eledet).raw(e_filter,:);
                end

                % Parameters
                app.Prev_Ele_MetaElectronsEditField.Value = length(app.Data.h.(eledet).raw(:,1));

                if app.Prev_Config_EventDetExistsCheckBox.Value
                    app.Prev_Ele_MetaEleAveragecountrateEditField.Value = length(app.Data.h.(eledet).raw(:,1))/max(app.Data.h.(metadet).raw(:,time_nr));
                    
                    % Countrates
                    ele_times = zeros(size(app.Data.h.(eledet).raw(:,1)));
                    ev_index = 1:length(app.Data.e.raw);
                    ele_times(app.Data.e.raw(~isnan(app.Data.e.raw(:,elenr)),elenr)) = app.Data.h.(metadet).raw(ev_index(~isnan(app.Data.e.raw(:,elenr))),time_nr);
                    for ia = 2:numel(ele_times)
                        if ele_times(ia) == 0
                            ele_times(ia) = ele_times(ia-1);
                        end
                    end

                    % Countrates
                    cla(app.Prev_Ele_Countrates)
                    [N,EDGES] = histcounts(app.Data.h.(metadet).raw(:,time_nr),0:1:max(app.Data.h.(metadet).raw(:,time_nr)));
                    Nn = interp1(EDGES(1:end-1),N,linspace(1,max(app.Data.h.(metadet).raw(:,time_nr)),1000));
                    plot(app.Prev_Ele_Countrates,linspace(1,max(app.Data.h.(metadet).raw(:,time_nr)),1000)/60,Nn);hold(app.Prev_Ele_Countrates,'on')

                    [N,EDGES] = histcounts(ele_times,0:1:max(ele_times));
                    Nn = interp1(EDGES(1:end-1),N,linspace(1,max(ele_times),1000));
                    plot(app.Prev_Ele_Countrates,linspace(1,max(ele_times),1000)/60,Nn);

                    title(app.Prev_Ele_Countrates,'Countrate')
                    xlabel(app.Prev_Ele_Countrates,'Aquisiton time (min)')
                    ylabel(app.Prev_Ele_Countrates,'Events/sec')
                    legend(app.Prev_Ele_Countrates,'Events','Electrons')
                end


                % Hits per event
                ele_hits_per_event = app.Data.e.raw(:,elenr);
                ele_hits_per_event(~isnan(ele_hits_per_event))= [diff(ele_hits_per_event(~isnan(ele_hits_per_event))); length(app.Data.h.(eledet).raw)-max(app.Data.e.raw(:,elenr))];
                ele_hits_per_event(isnan(ele_hits_per_event)) = zeros(size(ele_hits_per_event(isnan(ele_hits_per_event))));
                if max(ele_hits_per_event) ~= 1
                    ele_hits_per_event(ele_hits_per_event==max(ele_hits_per_event)) = 0;
                end

                cla(app.Prev_Ele_HitsPerEvent,'reset')
                histogram(app.Prev_Ele_HitsPerEvent,ele_hits_per_event,0-0.5:1:max(ele_hits_per_event)+0.5,'EdgeColor',[0 0 1],'FaceColor',[0 0 1],'FaceAlpha',0.33);
                title(app.Prev_Ele_HitsPerEvent,'Hits per bunch')
                xlabel(app.Prev_Ele_HitsPerEvent,'Nr hits')
                xlim(app.Prev_Ele_HitsPerEvent,[-1 max(ele_hits_per_event)+1])
                

                % TOF
                edges_e = app.Prev_Ele_EleStartTOFEditField.Value:app.Prev_Ele_EleBinsizeEditField.Value:app.Prev_Ele_EleEndTOFEditField.Value;
                cla(app.Prev_Ele_ElectronTof,'reset')
                H = histogram(app.Prev_Ele_ElectronTof,app.Data.h.(eledet).raw(:,3),edges_e);hold(app.Prev_Ele_ElectronTof,'on')
                if app.Prev_Ele_UseonlyrealeleCheckBox.Value
                    plot(app.Prev_Ele_ElectronTof,[1 1]*app.Prev_Ele_Electront0EditField.Value,[min(H.Values) max(H.Values)],'r')
                    plot(app.Prev_Ele_ElectronTof,[1 1]*app.Prev_Ele_Electront0EditField.Value-app.Prev_Ele_ReelespanEditField.Value,[min(H.Values) max(H.Values)],'r:')
                    plot(app.Prev_Ele_ElectronTof,[1 1]*app.Prev_Ele_Electront0EditField.Value+app.Prev_Ele_ReelespanEditField.Value,[min(H.Values) max(H.Values)],'r:')
                end
                title(app.Prev_Ele_ElectronTof,['Ele tof (', num2str(sum(H.Values),2),' counts)'])
                xlabel(app.Prev_Ele_ElectronTof,'TOF (ns)')

                % Ele XY
                cla(app.Prev_Ele_EleXY,'reset')
                [N,XEDGES,YEDGES] = histcounts2(app.Data.h.(eledet).raw(:,2),app.Data.h.(eledet).raw(:,1),-40:.2:40,-40:.2:40);hold(app.Prev_Ele_EleXY,"on")
                surf(app.Prev_Ele_EleXY,YEDGES(1:end-1),((XEDGES(1:end-1))),N,'edgecolor','none');view(app.Prev_Ele_EleXY,0,90)
                plot3(app.Prev_Ele_EleXY,[-1 1]*40,[0 0]*40,[1 1]*max(max(N))*2,'r')
                plot3(app.Prev_Ele_EleXY,[0 0],[-1 1]*40,[1 1]*max(max(N))*2,'r')
                xlabel(app.Prev_Ele_EleXY,'Y (mm)')
                ylabel(app.Prev_Ele_EleXY,'X (mm)')
                title(app.Prev_Ele_EleXY,'Electron XY')

                % Fish XTOF
                cla(app.Prev_Ele_FishXTOF,'reset')
                [N,XEDGES,YEDGES] = histcounts2(app.Data.h.(eledet).raw(:,1),app.Data.h.(eledet).raw(:,3),-40:.2:40,edges_e);hold(app.Prev_Ele_FishXTOF,"on")
                surf(app.Prev_Ele_FishXTOF,YEDGES(1:end-1),((XEDGES(1:end-1))),N,'edgecolor','none');view(app.Prev_Ele_FishXTOF,0,90)
                plot3(app.Prev_Ele_FishXTOF,[min(edges_e) max(edges_e)],[0 0],[1 1]*max(max(N))*2,'r')
                plot3(app.Prev_Ele_FishXTOF,[0 0]+app.Prev_Ele_Electront0EditField.Value,[-1 1]*40,[1 1]*max(max(N))*2,'r')
                xlabel(app.Prev_Ele_FishXTOF,'Y (mm)')
                ylabel(app.Prev_Ele_FishXTOF,'X (mm)')
                title(app.Prev_Ele_FishXTOF,'Fish XTOF')

                % Fish YTOF
                cla(app.Prev_Ele_FishYTOF,'reset')
                [N,XEDGES,YEDGES] = histcounts2(app.Data.h.(eledet).raw(:,2),app.Data.h.(eledet).raw(:,3),-40:.2:40,edges_e);hold(app.Prev_Ele_FishYTOF,"on")
                surf(app.Prev_Ele_FishYTOF,YEDGES(1:end-1),((XEDGES(1:end-1))),N,'edgecolor','none');view(app.Prev_Ele_FishYTOF,0,90)
                plot3(app.Prev_Ele_FishYTOF,[min(edges_e) max(edges_e)],[0 0],[1 1]*max(max(N))*2,'r')
                plot3(app.Prev_Ele_FishYTOF,[0 0]+app.Prev_Ele_Electront0EditField.Value,[-1 1]*40,[1 1]*max(max(N))*2,'r')
                xlabel(app.Prev_Ele_FishYTOF,'Y (mm)')
                ylabel(app.Prev_Ele_FishYTOF,'X (mm)')
                title(app.Prev_Ele_FishYTOF,'Fish XTOF')

            end

            % ------------------ Ion tab -----------------------------
            if app.Prev_Config_IonExistsCheckBox.Value
                
                ionnr = app.Prev_Config_IondetectorEditField.Value;
                iondet = ['det',num2str(ionnr)];

                % Parameters
                app.Prev_Ion_MetaIonsEditField.Value = length(app.Data.h.(iondet).raw(:,1));

                % Hits per event
                ion_hits_per_event = app.Data.e.raw(:,ionnr);
                ion_hits_per_event(~isnan(ion_hits_per_event))= [diff(ion_hits_per_event(~isnan(ion_hits_per_event))); length(app.Data.h.(iondet).raw)-max(app.Data.e.raw(:,ionnr))];
                ion_hits_per_event(isnan(ion_hits_per_event)) = zeros(size(ion_hits_per_event(isnan(ion_hits_per_event))));
                cla(app.Prev_Ion_HitsPerEvent,'reset')
                histogram(app.Prev_Ion_HitsPerEvent,ion_hits_per_event,0-0.5:1:max(ion_hits_per_event)+0.5,'EdgeColor',[1 0 0],'FaceColor',[1 0 0],'FaceAlpha',0.33);hold(app.Prev_EI_HitsPerEvent,'on');
                title(app.Prev_Ion_HitsPerEvent,'Hits per bunch')
                xlabel(app.Prev_Ion_HitsPerEvent,'Nr hits')
                xlim(app.Prev_Ion_HitsPerEvent,[-1 max(ion_hits_per_event)+1.5])

                if app.Prev_Config_EventDetExistsCheckBox.Value
                    app.Prev_Ion_MetaIonAveragecountrateEditField.Value = length(app.Data.h.(iondet).raw(:,1))/max(app.Data.h.(metadet).raw(:,time_nr));

                    % Countrates
                    ion_times = zeros(size(app.Data.h.(iondet).raw(:,1)));
                    ev_index = 1:length(app.Data.e.raw);
                    ion_times(app.Data.e.raw(~isnan(app.Data.e.raw(:,ionnr)),ionnr)) = app.Data.h.(metadet).raw(ev_index(~isnan(app.Data.e.raw(:,ionnr))),time_nr);
                    for ia = 2:numel(ion_times)
                        if ion_times(ia) == 0
                            ion_times(ia) = ion_times(ia-1);
                        end
                    end

                    % Countrates
                    cla(app.Prev_Ion_Countrates)
                    [N,EDGES] = histcounts(app.Data.h.(metadet).raw(:,time_nr),0:1:max(app.Data.h.(metadet).raw(:,time_nr)));
                    Nn = interp1(EDGES(1:end-1),N,linspace(1,max(app.Data.h.(metadet).raw(:,time_nr)),1000));
                    plot(app.Prev_Ion_Countrates,linspace(1,max(app.Data.h.(metadet).raw(:,time_nr)),1000)/60,Nn);hold(app.Prev_Ion_Countrates,'on')
                    [N,EDGES] = histcounts(ion_times,0:1:max(ion_times));
                    Nn = interp1(EDGES(1:end-1),N,linspace(1,max(ion_times),1000));
                    plot(app.Prev_Ion_Countrates,linspace(1,max(ion_times),1000)/60,Nn);

                    title(app.Prev_Ion_Countrates,'Countrate')
                    xlabel(app.Prev_Ion_Countrates,'Aquisiton time (min)')
                    ylabel(app.Prev_Ion_Countrates,'Events/sec')
                    legend(app.Prev_Ion_Countrates,'Events','Ions')
               
                end


                % TOF
                edges_i = app.Prev_Ion_IonStartTOFEditField.Value:app.Prev_Ion_IonBinsizeEditField.Value:app.Prev_Ion_IonEndTOFEditField.Value;
                cla(app.Prev_Ion_IonTof,'reset')
                H = histogram(app.Prev_Ion_IonTof,app.Data.h.(iondet).raw(:,3),edges_i);
                if app.Prev_Ion_IonYLogScaleCheckBox.Value
                    set(app.Prev_Ion_IonTof,'YScale','log')
                end
                title(app.Prev_Ion_IonTof,['Ion tof (', num2str(sum(H.Values),2),' counts)'])
                xlabel(app.Prev_Ion_IonTof,'TOF (ns)')

                % XY
                cla(app.Prev_Ion_IonXY,'reset')
                [N,XEDGES,YEDGES] = histcounts2(app.Data.h.(iondet).raw(:,2),app.Data.h.(iondet).raw(:,1),-40:.2:40,-40:.2:40);hold(app.Prev_Ion_IonXY,"on")
                surf(app.Prev_Ion_IonXY,YEDGES(1:end-1),((XEDGES(1:end-1))),N,'edgecolor','none');view(app.Prev_Ion_IonXY,0,90)
                plot3(app.Prev_Ion_IonXY,[-1 1]*40,[0 0]*40,[1 1]*max(max(N))*2,'r')
                plot3(app.Prev_Ion_IonXY,[0 0],[-1 1]*40,[1 1]*max(max(N))*2,'r')
                xlabel(app.Prev_Ion_IonXY,'Y (mm)')
                ylabel(app.Prev_Ion_IonXY,'X (mm)')
                title(app.Prev_Ion_IonXY,'Ion XY')

                % Fish XTOF
                cla(app.Prev_Ion_FishXTOF,'reset')
                [N,XEDGES,YEDGES] = histcounts2(app.Data.h.(iondet).raw(:,1),app.Data.h.(iondet).raw(:,3),-40:.2:40,edges_i);hold(app.Prev_Ion_FishXTOF,"on")
                surf(app.Prev_Ion_FishXTOF,YEDGES(1:end-1),((XEDGES(1:end-1))),N,'edgecolor','none');view(app.Prev_Ion_FishXTOF,0,90)
                xlabel(app.Prev_Ion_FishXTOF,'Y (mm)')
                ylabel(app.Prev_Ion_FishXTOF,'X (mm)')
                title(app.Prev_Ion_FishXTOF,'Fish XTOF')

                % Fish YTOF
                cla(app.Prev_Ion_FishYTOF,'reset')
                [N,XEDGES,YEDGES] = histcounts2(app.Data.h.(iondet).raw(:,2),app.Data.h.(iondet).raw(:,3),-40:.2:40,edges_i);hold(app.Prev_Ion_FishYTOF,"on")
                surf(app.Prev_Ion_FishYTOF,YEDGES(1:end-1),((XEDGES(1:end-1))),N,'edgecolor','none');view(app.Prev_Ion_FishYTOF,0,90)
                xlabel(app.Prev_Ion_FishYTOF,'Y (mm)')
                ylabel(app.Prev_Ion_FishYTOF,'X (mm)')
                title(app.Prev_Ion_FishYTOF,'Fish XTOF')
            end

            % ------------------ Electron+Ion tab -----------------------------
            if app.Prev_Config_IonExistsCheckBox.Value && app.Prev_Config_ElectronExistsCheckBox.Value

                % Parameters
                app.Prev_EI_MetaElectronsEditField.Value = length(app.Data.h.(eledet).raw(:,1));
                app.Prev_EI_MetaEleCoincidenceEditField.Value = 100*length(app.Data.h.(eledet).raw(app.Data.e.raw(~isnan(sum(app.Data.e.raw,2)),elenr),1))/length(app.Data.h.(eledet).raw(:,1));
                app.Prev_EI_MetaIonsEditField.Value = length(app.Data.h.(iondet).raw(:,1));
                app.Prev_EI_MetaIonCoincidenceEditField.Value = 100*length(app.Data.h.(iondet).raw(app.Data.e.raw(~isnan(sum(app.Data.e.raw,2)),ionnr),1))/length(app.Data.h.(iondet).raw(:,1));

                % Hits per event
                cla(app.Prev_EI_HitsPerEvent,'reset')
                histogram(app.Prev_EI_HitsPerEvent,ion_hits_per_event,0-0.5:1:max(ion_hits_per_event)+0.5,'EdgeColor',[1 0 0],'FaceColor',[1 0 0],'FaceAlpha',0.33);hold(app.Prev_EI_HitsPerEvent,'on');
                histogram(app.Prev_EI_HitsPerEvent,ele_hits_per_event,0-0.5:1:max(ele_hits_per_event)+0.5,'EdgeColor',[0 0 1],'FaceColor',[0 0 1],'FaceAlpha',0.33);
                title(app.Prev_EI_HitsPerEvent,'Hits per bunch')
                xlabel(app.Prev_EI_HitsPerEvent,'Nr hits')
                legend(app.Prev_EI_HitsPerEvent,'Ions','Electrons')
                xlim(app.Prev_EI_HitsPerEvent,[-1 max(max(ele_hits_per_event),max(ion_hits_per_event))+1.5])

                if app.Prev_Config_EventDetExistsCheckBox.Value
                    app.Prev_EI_MetaEleAveragecountrateEditField.Value = length(app.Data.h.(eledet).raw(:,1))/max(app.Data.h.(metadet).raw(:,time_nr));
                    app.Prev_EI_MetaIonAveragecountrateEditField.Value = length(app.Data.h.(iondet).raw(:,1))/max(app.Data.h.(metadet).raw(:,time_nr));

                    % Countrates
                    cla(app.Prev_EI_Countrates)
                    [N,EDGES] = histcounts(app.Data.h.(metadet).raw(:,time_nr),0:1:max(app.Data.h.(metadet).raw(:,time_nr)));
                    Nn = interp1(EDGES(1:end-1),N,linspace(1,max(app.Data.h.(metadet).raw(:,time_nr)),1000));
                    plot(app.Prev_EI_Countrates,linspace(1,max(app.Data.h.(metadet).raw(:,time_nr)),1000)/60,Nn);hold(app.Prev_EI_Countrates,'on')
                    [N,EDGES] = histcounts(ion_times,0:1:max(ion_times));
                    Nn = interp1(EDGES(1:end-1),N,linspace(1,max(ion_times),1000));
                    plot(app.Prev_EI_Countrates,linspace(1,max(ion_times),1000)/60,Nn);

                    [N,EDGES] = histcounts(ele_times,0:1:max(ele_times));
                    Nn = interp1(EDGES(1:end-1),N,linspace(1,max(ele_times),1000));
                    plot(app.Prev_EI_Countrates,linspace(1,max(ele_times),1000)/60,Nn);

                    title(app.Prev_EI_Countrates,'Countrate')
                    xlabel(app.Prev_EI_Countrates,'Aquisiton time (min)')
                    ylabel(app.Prev_EI_Countrates,'Events/sec')
                    legend(app.Prev_EI_Countrates,'Events','Ions','Electrons')
                end

            end

            % ------------------ Source tab -----------------------------
            app.Prev_EI_MetaEventsEditField.Value = length(app.Data.e.raw(:,1));
            app.Prev_EI_MetaEvCoincidenceEditField.Value = 100*sum(~isnan(sum(app.Data.e.raw,2)))/length(app.Data.e.raw(:,1));

            app.Prev_LS_MetaMaxTOFEditField.Value = max(app.Data.h.det1.raw(:,3));
            app.Prev_LS_MetaNrBunchesEditField.Value = round(max(app.Data.h.det1.raw(:,3))*1e-9*app.Prev_LS_MetaLaserrepetitionrateHzEditField.Value);
          
            app.Prev_LS_DelayPanel.Visible = app.Prev_Config_DelayYN.Value;
            app.Prev_LS_CEPPanel.Visible = app.Prev_Config_CepYN.Value;

            if app.Prev_Config_EventDetExistsCheckBox.Value
                if app.Prev_Config_DelayYN.Value 
                    app.Prev_LS_MetaMaxdelayEditField.Visible = app.Prev_Config_DelayYN.Value;
                    app.Prev_LS_MetaMindelayEditField.Visible = app.Prev_Config_DelayYN.Value;
                    app.Prev_LS_MetaStepsizeEditField.Visible = app.Prev_Config_DelayYN.Value;

                    delays = unique(app.Data.h.(metadet).raw(:,app.Prev_Config_DelayRowEditField.Value));

                    app.Prev_LS_MetaMaxdelayEditField.Value = max(delays);
                    app.Prev_LS_MetaMindelayEditField.Value = min(delays);
                    if ~isnan(mean(diff(delays)))
                        app.Prev_LS_MetaStepsizeEditField.Value = mean(diff(delays));
                    else
                        app.Prev_LS_MetaStepsizeEditField.Value = 0;
                    end

                    cla(app.Prev_LS_Delay,'reset')
                    histogram(app.Prev_LS_Delay,app.Data.h.(metadet).raw(:,app.Prev_Config_DelayRowEditField.Value),unique(app.Data.h.(metadet).raw(:,app.Prev_Config_DelayRowEditField.Value)),'EdgeColor',[1 0 0],'FaceColor',[1 0 0],'FaceAlpha',0.33);
                    xlabel(app.Prev_LS_Delay,'Delay')
                    ylabel(app.Prev_LS_Delay,'Events')

                end

                if app.Prev_Config_CepYN.Value
                    app.Prev_LS_MetaCEPmeanEditField.Value = mean(app.Data.h.(metadet).raw(:,app.Prev_Config_CEPRowEditField.Value));
                    app.Prev_LS_MetaCEPSTDEditField.Value = std(app.Data.h.(metadet).raw(:,app.Prev_Config_CEPRowEditField.Value));
                    cep_bins = linspace(app.Prev_LS_MetaCEPmeanEditField.Value-app.Prev_LS_MetaCEPSTDEditField.Value*3,...
                        app.Prev_LS_MetaCEPmeanEditField.Value+app.Prev_LS_MetaCEPSTDEditField.Value*3,...
                        1000);

                    cla(app.Prev_LS_CEP,'reset')
                    histogram(app.Prev_LS_CEP,app.Data.h.(metadet).raw(:,app.Prev_Config_CEPRowEditField.Value),cep_bins,'EdgeColor',[1 0 0],'FaceColor',[1 0 0],'FaceAlpha',0.33);
                    xlabel(app.Prev_LS_CEP,'Delay')
                    ylabel(app.Prev_LS_CEP,'Events')
                end
            end

            close(d)

            if app.Prev_Config_ElectronExistsCheckBox.Value && app.Prev_Ele_UseonlyrealeleCheckBox.Value
                app.Data = Data_old;
            end

        end
        
        function plotSort(app)
            % This function updates all plots in the Sorting tab
            d = uiprogressdlg(app.CIELAnalysisAppUIFigure,'Title','Plotting...','Message','Updating plots','Indeterminate','on'); 

           
            edges_e = app.Sort_EleStartTOFEditField.Value:app.Sort_EleBinsizeEditField.Value:app.Sort_EleEndTOFEditField.Value;
            edges_i = app.Sort_IonStartTOFEditField.Value:app.Sort_IonBinsizeEditField.Value:app.Sort_IonEndTOFEditField.Value;
            
            
            % Ion tof
            cla(app.Sort_IonTof,'reset')
            H = histogram(app.Sort_IonTof,mod(app.Data.h.det2.raw(:,3),app.Sort_WrappingtimeEditField.Value),edges_i);
            hold(app.Sort_IonTof,"on")
            temp = app.Sort_SpeciesTable.Data;
            temp_s = size(temp);
            for ii = 1:temp_s(1)   
                plot(app.Sort_IonTof,[1 1]*temp{ii,3},[min(H.Values) max(H.Values)]*1.25,':','color',[temp{ii,6} temp{ii,7} temp{ii,8}])
                edges_i_temp = abs(edges_i-temp{ii,3}) < temp{ii,4};
                if ~isempty(edges_i(edges_i_temp))
                histogram(app.Sort_IonTof,mod(app.Data.h.det2.raw(:,3),app.Sort_WrappingtimeEditField.Value),edges_i(edges_i_temp),...
                    'EdgeColor',[temp{ii,6} temp{ii,7} temp{ii,8}],'FaceColor',[temp{ii,6} temp{ii,7} temp{ii,8}]);
                end
                
            end

            if app.Sort_IonYLogScaleCheckBox.Value
                set(app.Sort_IonTof,'YScale','log')
            end
            title(app.Sort_IonTof,['Ion tof (', num2str(sum(H.Values),2),' counts)'])
            xlabel(app.Sort_IonTof,'TOF (ns)')
                                  
            % Ele tof
            TOFc = abs(app.Data.h.det1.raw(:,1)) < app.Sort_XYfiltterEditField.Value & abs(app.Data.h.det1.raw(:,2))< app.Sort_XYfiltterEditField.Value;            
            H = histogram(app.Sort_ElectronTof,mod(app.Data.h.det1.raw(TOFc,3),app.Sort_WrappingtimeEditField.Value),edges_e);
            title(app.Sort_ElectronTof,['Ele tof (', num2str(sum(H.Values),2),' counts)'])
            xlabel(app.Sort_ElectronTof,'TOF (ns)')

            if ~isempty(app.Data_Sorted)
                % Sorted ion tof
                cla(app.Sort_SortedIonTof,'reset')
                H = histogram(app.Sort_SortedIonTof,app.Data_Sorted.h.det2.raw(:,3),edges_i);
                lgnd = {'Raw'};
                hold(app.Sort_SortedIonTof,"on")
                temp = app.Sort_SpeciesTable.Data;
                temp_s = size(temp);
                for ii = 1:temp_s(1)
                    plot(app.Sort_SortedIonTof,[1 1]*(temp{ii,3}+temp{ii,5}*app.Sort_WrappingtimeEditField.Value),[min(H.Values) max(H.Values)]*1.25,':','color',[temp{ii,6} temp{ii,7} temp{ii,8}])
                    lgnd = [lgnd, [temp{ii,1},' center']];
                    edges_i_temp = abs(edges_i-(temp{ii,3}+temp{ii,5}*app.Sort_WrappingtimeEditField.Value)) < temp{ii,4};
                    if ~isempty(edges_i(edges_i_temp))
                    histogram(app.Sort_SortedIonTof,app.Data_Sorted.h.det2.raw(:,3),edges_i(edges_i_temp),...
                        'EdgeColor',[temp{ii,6} temp{ii,7} temp{ii,8}],'FaceColor',[temp{ii,6} temp{ii,7} temp{ii,8}]);
                    lgnd = [lgnd, temp{ii,1}];
                    end
                end

                if app.Sort_IonYLogScaleCheckBox.Value
                    set(app.Sort_SortedIonTof,'YScale','log')
                end
                title(app.Sort_SortedIonTof,['Sorted ion tof (', num2str(sum(H.Values),2),' counts)'])
                xlabel(app.Sort_SortedIonTof,'TOF (ns)')
                legend(app.Sort_SortedIonTof,lgnd)
                
                % Sorted ele tof
                cla(app.Sort_SortedElectronTof,'reset')
                TOFc = abs(app.Data.h.det1.raw(:,1)) < app.Sort_XYfiltterEditField.Value & abs(app.Data.h.det1.raw(:,2))< app.Sort_XYfiltterEditField.Value;            
                yyaxis(app.Sort_SortedElectronTof,'left')
                app.Sort_SortedElectronTof.YAxis(1).Color = 'k';
                histogram(app.Sort_SortedElectronTof,mod(app.Data.h.det1.raw(TOFc,3),app.Sort_WrappingtimeEditField.Value),edges_e,...
                        'EdgeColor',[0 0 0],'FaceColor',[0 0 0],'FaceAlpha',0.33); hold(app.Sort_SortedElectronTof,'on');
                lgnd = {'Raw'};
                
                yyaxis(app.Sort_SortedElectronTof,'right')
                app.Sort_SortedElectronTof.YAxis(2).Color = [temp{1,6} temp{1,7} temp{1,8}];
                for ii = 1:temp_s(1)
                    logic_iTOF_filter   = abs(app.Data_Sorted.h.det2.raw(:,3) - ((temp{ii,3}+temp{ii,5}*app.Sort_WrappingtimeEditField.Value))) < temp{ii,4};  % logic ion hit filter
                    pos_iTOF_filter     = find(logic_iTOF_filter);                                         % row index ion hit filter
                    ev_iTOF_filter      = ismember(app.Data_Sorted.e.raw(:,2),pos_iTOF_filter);            % logic ion event filter
                    ev_iTOF_filter_e    = app.Data_Sorted.e.raw(ev_iTOF_filter,1);                         % row index electron hit filter 
                    ev_iTOF_filter_e    = ev_iTOF_filter_e(~isnan(ev_iTOF_filter_e));                      % row index electron hit filter, no NaNs
                    e_data = app.Data_Sorted.h.det1.raw(ev_iTOF_filter_e,:);
                    TOFc_new = abs(e_data(:,1)) < app.Sort_XYfiltterEditField.Value & abs(e_data(:,2)) < app.Sort_XYfiltterEditField.Value;
                    H = histogram(app.Sort_SortedElectronTof,e_data(TOFc_new,3),edges_e,...
                        'EdgeColor',[temp{ii,6} temp{ii,7} temp{ii,8}],'FaceColor',[temp{ii,6} temp{ii,7} temp{ii,8}],'FaceAlpha',0.33);
                    lgnd = [lgnd, temp{ii,1}];
                end

                title(app.Sort_SortedElectronTof,['Sorted ele tof (', num2str(sum(H.Values),2),' counts)'])
                xlabel(app.Sort_SortedElectronTof,'TOF (ns)')
                legend(app.Sort_SortedElectronTof,lgnd)
            end
            close(d)
        end
        
        function plotCalib(app)
            % This function updates all elements in the Calibration tab

            d = uiprogressdlg(app.CIELAnalysisAppUIFigure,'Title','Plotting...','Message','Updating plots','Indeterminate','on'); 

            h = findobj(app.Calib_Tabs,'Type','axes');
            for ii = 1:length(h)
                cla(h(ii));
            end
            
            % ------------------ Spectrometer stuff ----------------------------- 
            E   = app.Calib_EFieldSumEditField.Value;
            B   = app.Calib_BFieldSumEditField.Value;
            dd   = app.Calib_dEditField.Value;
            L   = app.Calib_LEditField.Value;
            t0  = sqrt(2*app.me*dd/(app.e*E))*(2*dd+L)/(2*dd)*1e9;
            T_cyc = 2*pi*app.me/B/app.e*1e9;
            app.Calib_CyPeriodEditField.Value = T_cyc;

            if app.Prev_Config_IonExistsCheckBox.Value

                ionnr = app.Prev_Config_IondetectorEditField.Value;
                iondet = ['det',num2str(ionnr)];

                % ------------------ Ion tab -----------------------------
                edges_i = app.Prev_Ion_IonStartTOFEditField.Value:app.Prev_Ion_IonBinsizeEditField.Value:app.Prev_Ion_IonEndTOFEditField.Value;

                % Ion tof
                cla(app.Calib_IonTOF,'reset')
                H = histogram(app.Calib_IonTOF,app.Data.h.(iondet).raw(:,3),edges_i);
                hold(app.Calib_IonTOF,"on")
                temp = app.Calib_SpeciesTable.Data;
                temp_s = size(temp);
                if ~isempty(temp_s)
                    for ii = 1:temp_s(1)
                        plot(app.Calib_IonTOF,[1 1]*(temp{ii,3}+temp{ii,5}*app.Sort_WrappingtimeEditField.Value),[min(H.Values) max(H.Values)]*1.25,':','color',[temp{ii,6} temp{ii,7} temp{ii,8}])
                        edges_i_temp = abs(edges_i-(temp{ii,3}+temp{ii,5}*app.Sort_WrappingtimeEditField.Value)) < temp{ii,4};
                        if ~isempty(edges_i_temp(edges_i_temp))
                        histogram(app.Calib_IonTOF,app.Data.h.(iondet).raw(:,3),edges_i(edges_i_temp),...
                            'EdgeColor',[temp{ii,6} temp{ii,7} temp{ii,8}],'FaceColor',[temp{ii,6} temp{ii,7} temp{ii,8}]);
                        end
                    end
                end

                if app.Calib_IonYLogScaleCheckBox.Value
                    set(app.Calib_IonTOF,'YScale','log')
                end

                title(app.Calib_IonTOF,['Ion tof (', num2str(sum(H.Values),2),' counts)'])
                xlabel(app.Calib_IonTOF,'TOF (ns)')

                % Ion XY
                cla(app.Calib_IonXY,'reset')
                XY_edges = -40:.2:40;

                [N,XEDGES,YEDGES] = histcounts2(app.Data.h.(iondet).raw(:,2),app.Data.h.(iondet).raw(:,1),XY_edges,XY_edges);hold(app.Calib_IonXY,"on")
                surf(app.Calib_IonXY,YEDGES(1:end-1),((XEDGES(1:end-1))),N,'edgecolor','none');view(app.Calib_IonXY,0,90)
                plot3(app.Calib_IonXY,[min(XY_edges) max(XY_edges)],[0 0]+app.Calib_IonXEditField.Value,[1 1]*max(max(N))*2,'r')
                plot3(app.Calib_IonXY,[0 0]+app.Calib_IonYEditField.Value,[min(XY_edges) max(XY_edges)],[1 1]*max(max(N))*2,'r')
                xlabel(app.Calib_IonXY,'Y (mm)')
                ylabel(app.Calib_IonXY,'X (mm)')
                title(app.Calib_IonXY,'Ion XY')
            end

            % ------------------ Electron tab -----------------------------
            if app.Prev_Config_ElectronExistsCheckBox.Value
                
                elenr = app.Prev_Config_ElectrondetectorEditField.Value;
                eledet = ['det',num2str(elenr)];

                 % Ele tof
                edges_e = app.Prev_Ele_EleStartTOFEditField.Value:app.Prev_Ele_EleBinsizeEditField.Value:app.Prev_Ele_EleEndTOFEditField.Value;

                cla(app.Calib_Ele_EleTOF,'reset')
                H = histogram(app.Calib_Ele_EleTOF,app.Data.h.(eledet).raw(:,3),edges_e);hold(app.Calib_Ele_EleTOF,"on")
                plot(app.Calib_Ele_EleTOF,[1 1]*app.Calib_Ele_Electront0EditField.Value,[min(H.Values) max(H.Values)],'r')
                title(app.Calib_Ele_EleTOF,['Ele tof (', num2str(sum(H.Values),2),' counts)'])
                xlabel(app.Calib_Ele_EleTOF,'TOF (ns)')


                XY_edges = -40:.2:40;


                % Fish XTOF
                cla(app.Calib_Ele_Nodes_XTOF,'reset')
                edges_n = app.Calib_Ele_NodesStartTOFEditField.Value:0.1:app.Calib_Ele_NodesStopTOFEditField.Value;

                [N,XEDGES,YEDGES] = histcounts2(app.Data.h.det1.raw(:,1),app.Data.h.(eledet).raw(:,3),XY_edges,edges_n);hold(app.Calib_Ele_Nodes_XTOF,"on")
                surf(app.Calib_Ele_Nodes_XTOF,YEDGES(1:end-1),((XEDGES(1:end-1))),log(N),'edgecolor','none');view(app.Calib_Ele_Nodes_XTOF,0,90)
                plot3(app.Calib_Ele_Nodes_XTOF,[min(edges_n) max(edges_n)],[0 0],[1 1]*max(max(N))*2,':r')
                plot3(app.Calib_Ele_Nodes_XTOF,[0 0]+app.Calib_Ele_Electront0EditField.Value,[min(XY_edges) max(XY_edges)],[1 1]*max(max(N))*2,'r')
                plot3(app.Calib_Ele_Nodes_XTOF,[0 0]+app.Calib_toffsetEditField.Value,[min(XY_edges) max(XY_edges)],[1 1]*max(max(N))*2,'k')
                plot3(app.Calib_Ele_Nodes_XTOF,[0 0]+app.Calib_toffsetEditField.Value+t0,[min(XY_edges) max(XY_edges)],[1 1]*max(max(N))*2,'k')
                plot3(app.Calib_Ele_Nodes_XTOF,[0 0]+app.Calib_toffsetEditField.Value+T_cyc,[min(XY_edges) max(XY_edges)],[1 1]*max(max(N))*2,'b')
                plot3(app.Calib_Ele_Nodes_XTOF,[0 0]+app.Calib_toffsetEditField.Value+T_cyc*2,[min(XY_edges) max(XY_edges)],[1 1]*max(max(N))*2,'b')
                plot3(app.Calib_Ele_Nodes_XTOF,[0 0]+app.Calib_toffsetEditField.Value+T_cyc*1.5,[min(XY_edges) max(XY_edges)],[1 1]*max(max(N))*2,'b:')

                xlabel(app.Calib_Ele_Nodes_XTOF,'TOF (mm)')
                ylabel(app.Calib_Ele_Nodes_XTOF,'X (mm)')
                title(app.Calib_Ele_Nodes_XTOF,'Nodes XTOF')
                xlim(app.Calib_Ele_Nodes_XTOF,[min(edges_n) max(edges_n)])

                % Fish XTOF
                cla(app.Calib_Ele_Nodes_YTOF,'reset')
                [N,XEDGES,YEDGES] = histcounts2(app.Data.h.(eledet).raw(:,1),app.Data.h.(eledet).raw(:,3),XY_edges,edges_n);hold(app.Calib_Ele_Nodes_YTOF,"on")
                surf(app.Calib_Ele_Nodes_YTOF,YEDGES(1:end-1),((XEDGES(1:end-1))),log(N),'edgecolor','none');view(app.Calib_Ele_Nodes_YTOF,0,90)
                plot3(app.Calib_Ele_Nodes_YTOF,[min(edges_n) max(edges_n)],[0 0],[1 1]*max(max(N))*2,':r')
                plot3(app.Calib_Ele_Nodes_YTOF,[0 0]+app.Calib_Ele_Electront0EditField.Value,[min(XY_edges) max(XY_edges)],[1 1]*max(max(N))*2,'r')
                plot3(app.Calib_Ele_Nodes_YTOF,[min(edges_n) max(edges_n)],[0 0],[1 1]*max(max(N))*2,':r')
                plot3(app.Calib_Ele_Nodes_YTOF,[0 0]+app.Calib_Ele_Electront0EditField.Value,[min(XY_edges) max(XY_edges)],[1 1]*max(max(N))*2,'r')
                plot3(app.Calib_Ele_Nodes_YTOF,[0 0]+app.Calib_toffsetEditField.Value,[min(XY_edges) max(XY_edges)],[1 1]*max(max(N))*2,'k')
                plot3(app.Calib_Ele_Nodes_YTOF,[0 0]+app.Calib_toffsetEditField.Value+t0,[min(XY_edges) max(XY_edges)],[1 1]*max(max(N))*2,'k')
                plot3(app.Calib_Ele_Nodes_YTOF,[0 0]+app.Calib_toffsetEditField.Value+T_cyc,[min(XY_edges) max(XY_edges)],[1 1]*max(max(N))*2,'b')
                plot3(app.Calib_Ele_Nodes_YTOF,[0 0]+app.Calib_toffsetEditField.Value+T_cyc*2,[min(XY_edges) max(XY_edges)],[1 1]*max(max(N))*2,'b')
                plot3(app.Calib_Ele_Nodes_YTOF,[0 0]+app.Calib_toffsetEditField.Value+T_cyc*1.5,[min(XY_edges) max(XY_edges)],[1 1]*max(max(N))*2,'b:')

                xlabel(app.Calib_Ele_Nodes_YTOF,'TOF (mm)')
                ylabel(app.Calib_Ele_Nodes_YTOF,'Y (mm)')
                title(app.Calib_Ele_Nodes_YTOF,'Nodes YTOF')
                xlim(app.Calib_Ele_Nodes_YTOF,[min(edges_n) max(edges_n)])

                if ~isempty(app.Data_Momentum)

                    logic_eup_filter   = app.Data_Momentum.TH > 0;                              % logic ele hit filter
                    pos_eup_filter     = find(logic_eup_filter);                                % row index ele hit filter
                    filter_up_logic      = ismember(app.Data.e.raw(:,elenr),pos_eup_filter);    % logic ele event filter

                    logic_edwn_filter   = app.Data_Momentum.TH < 0;                             % logic ele hit filter
                    pos_edwn_filter     = find(logic_edwn_filter);                              % row index ele hit filter
                    filter_dwn_logic    = ismember(app.Data.e.raw(:,elenr),pos_edwn_filter);    % logic ele event filter

                    filter_logic = ones(size(app.Data.e.raw(:,elenr)));

                    if app.Calib_Ele_IonposfilterCheckBox.Value && app.Prev_Config_IonExistsCheckBox.Value
                        logic_ipos_filter   = abs(app.Data.h.(iondet).raw(:,1)) < app.Calib_Ele_IonposfilterEditField.Value;  % logic ion hit filter
                        pos_ipos_filter     = find(logic_ipos_filter);                                                % row index ion hit filter
                        ev_ipos_filter      = ismember(app.Data.e.raw(:,ionnr),pos_ipos_filter);                          % logic ion event filter
                        filter_logic        = filter_logic & ev_ipos_filter;
                        filter_up_logic     = filter_up_logic & ev_ipos_filter;
                        filter_dwn_logic    = filter_dwn_logic & ev_ipos_filter;
                    end

                    if app.Calib_Ele_SpeciesfilterCheckBox.Value && app.Prev_Config_IonExistsCheckBox.Value
                        temp = app.Calib_SpeciesTable.Data;
                        temp_s = size(temp);
                        if app.Calib_Ele_SpeciesfilterEditField.Value > 0 && app.Calib_Ele_SpeciesfilterEditField.Value <= temp_s(1)
                            logic_iTOF_filter   = abs(app.Data.h.(iondet).raw(:,3) - (temp{app.Calib_Ele_SpeciesfilterEditField.Value,3})) < temp{app.Calib_Ele_SpeciesfilterEditField.Value,4};  % logic ion hit filter
                            pos_iTOF_filter     = find(logic_iTOF_filter);                                    % row index ion hit filter
                            ev_iTOF_filter      = ismember(app.Data.e.raw(:,ionnr),pos_iTOF_filter);       % logic ion event filter
                            filter_logic        = filter_logic & ev_iTOF_filter;
                            filter_up_logic     = filter_up_logic & ev_iTOF_filter;
                            filter_dwn_logic    = filter_dwn_logic & ev_iTOF_filter;
                        end
                    end

                    filter_row   = app.Data.e.raw(filter_logic,elenr);
                    filter_up_row   = app.Data.e.raw(filter_up_logic,elenr);
                    filter_dwn_row   = app.Data.e.raw(filter_dwn_logic,elenr);

                    filter = filter_row(~isnan(filter_row));
                    filter_up = filter_up_row(~isnan(filter_up_row));
                    filter_dwn = filter_dwn_row(~isnan(filter_dwn_row));

                    cla(app.Calib_Ele_pxpz,'reset')
                    edges_p = -1.5:0.01:1.5;
                    [N,XEDGES,YEDGES] = histcounts2(app.Data_Momentum.p_cart(filter,1), app.Data_Momentum.p_cart(filter,2),edges_p,edges_p);
                    surf(app.Calib_Ele_pxpz,YEDGES(1:end-1),((XEDGES(1:end-1))),N,'edgecolor','none');view(app.Calib_Ele_pxpz,0,90);hold(app.Calib_Ele_pxpz,'on')
                    plot3(app.Calib_Ele_pxpz,[0 0],[min(XEDGES) max(XEDGES)],[1 1]*max(max(N))*1.25,'color','r')
                    plot3(app.Calib_Ele_pxpz,[min(YEDGES) max(YEDGES)],[0 0],[1 1]*max(max(N))*1.25,'color','r')
                    xlabel(app.Calib_Ele_pxpz,'p_z (au)');ylabel(app.Calib_Ele_pxpz,'p_y (au)')
                    colormap(app.Calib_Ele_pxpz,'jet');axis(app.Calib_Ele_pxpz,'square');title(app.Calib_Ele_pxpz,'px pz')

                    cla(app.Calib_Ele_pypz,'reset')
                    [N,XEDGES,YEDGES] = histcounts2(app.Data_Momentum.p_cart(filter,2), app.Data_Momentum.p_cart(filter,3),edges_p,edges_p);
                    surf(app.Calib_Ele_pypz,YEDGES(1:end-1),((XEDGES(1:end-1))),N,'edgecolor','none');view(app.Calib_Ele_pypz,0,90);hold(app.Calib_Ele_pypz,'on')
                    plot3(app.Calib_Ele_pypz,[0 0],[min(XEDGES) max(XEDGES)],[1 1]*max(max(N))*1.25,'color','r')
                    plot3(app.Calib_Ele_pypz,[min(YEDGES) max(YEDGES)],[0 0],[1 1]*max(max(N))*1.25,'color','r')
                    xlabel(app.Calib_Ele_pypz,'p_z (au)');ylabel(app.Calib_Ele_pypz,'p_y (au)');title(app.Calib_Ele_pypz,'py pz')
                    colormap(app.Calib_Ele_pypz,'jet');axis(app.Calib_Ele_pypz,'square')

                    cla(app.Calib_Ele_eVtheta,'reset')
                    eV = 0:0.1:40;
                    th = -pi/2:0.1:pi/2;

                    [N,XEDGES,YEDGES] = histcounts2(app.Data_Momentum.PHI(filter_up),app.Data_Momentum.E(filter_up),th,eV);
                    surf(app.Calib_Ele_eVtheta,YEDGES(1:end-1),((XEDGES(1:end-1)))+pi/2,N,'edgecolor','none');view(app.Calib_Ele_eVtheta,0,90);hold(app.Calib_Ele_eVtheta,'on')
                    [N,XEDGES,YEDGES] = histcounts2(app.Data_Momentum.PHI(filter_dwn),app.Data_Momentum.E(filter_dwn),th,eV);
                    surf(app.Calib_Ele_eVtheta,YEDGES(1:end-1),((XEDGES(1:end-1)))-pi/2,N,'edgecolor','none');view(app.Calib_Ele_eVtheta,0,90);hold(app.Calib_Ele_eVtheta,'on')
                    if app.Calib_Ele_CalibrationlinesCheckBox.Value
                        for ii = 1:round(max(eV)/app.Calib_Ele_SpacingeVEditField.Value)
                            plot3(app.Calib_Ele_eVtheta,[1 1]*ii*app.Calib_Ele_SpacingeVEditField.Value+app.Calib_Ele_OffseteVEditField.Value,[min(th) max(th)]*2,[1 1]*max(max(N))*1.25,'w')
                        end
                    end
                    ylim(app.Calib_Ele_eVtheta,[-pi pi])
                    xlim(app.Calib_Ele_eVtheta,[min(eV) max(eV)])
                    xlabel(app.Calib_Ele_eVtheta,'Energy eV');ylabel(app.Calib_Ele_eVtheta,'Angle (rad)')

                    cla(app.Calib_Ele_UpDown,'reset')
                    [N,~,YEDGES] = histcounts2(app.Data_Momentum.PHI(filter_up),app.Data_Momentum.E(filter_up),th,eV);
                    plot(app.Calib_Ele_UpDown,YEDGES(1:end-1),sum(N),'r');hold(app.Calib_Ele_UpDown,'on')
                    [N,~,YEDGES] = histcounts2(app.Data_Momentum.PHI(filter_dwn),app.Data_Momentum.E(filter_dwn),th,eV);
                    plot(app.Calib_Ele_UpDown,YEDGES(1:end-1),sum(N),'b')
                    xlabel(app.Calib_Ele_UpDown,'Energy eV')
                    legend(app.Calib_Ele_UpDown,'Up','Down')
                end
            end

            close(d)

        end
        
        function plotScans(app,varargin)
            if ~isempty(varargin)
                save_yn = 1;
            else
                save_yn = 0;
            end
            
            if isempty(app.Data_Momentum)
                uialert(app.CIELAnalysisAppUIFigure,'No momentum data!','No data','icon','warning');
            else
                d = uiprogressdlg(app.CIELAnalysisAppUIFigure,'Title','Plotting...','Message','Updating plots','Indeterminate','on'); 

                eV = app.Scans_EnergyminEditField.Value:app.Scans_EnergystepEditField.Value:app.Scans_EnergymaxEditField.Value;

                logic_filter_up = ones(size(app.Data.e.raw(:,1)));
                logic_filter_dwn = ones(size(app.Data.e.raw(:,1)));

                % Ion filters
                if app.Scans_IonposfilterCheckBox.Value
                    logic_ipos_filter   = abs(app.Data.h.det2.raw(:,1)) < app.Scans_IonposfilterEditField.Value;  % logic ion hit filter
                    pos_ipos_filter     = find(logic_ipos_filter);                                                % row index ion hit filter
                    ev_ipos_filter      = ismember(app.Data.e.raw(:,2),pos_ipos_filter);                          % logic ion event filter
                    logic_filter_up     = logic_filter_up & ev_ipos_filter;
                    logic_filter_dwn    = logic_filter_dwn & ev_ipos_filter;
                end

                if app.Scans_SpeciesfilterCheckBox.Value
                    temp = app.Calib_SpeciesTable.Data;
                    logic_iTOF_filter   = abs(app.Data.h.det2.raw(:,3) - (temp{app.Scans_SpeciesfilterEditField.Value,3})) < temp{app.Scans_SpeciesfilterEditField.Value,4};  % logic ion hit filter
                    pos_iTOF_filter     = find(logic_iTOF_filter);                                    % row index ion hit filter
                    ev_iTOF_filter      = ismember(app.Data.e.raw(:,2),pos_iTOF_filter);       % logic ion event filter
                    logic_filter_up     = logic_filter_up & ev_iTOF_filter;
                    logic_filter_dwn    = logic_filter_dwn & ev_iTOF_filter;
                end

                % Ele filters
                logic_ele_filt_up   = app.Data_Momentum.TH < 0 & abs(app.Data_Momentum.PHI) < deg2rad(app.Scans_AngleSpanEditField.Value);   % logic ele hit filter
                pos_ele_filt_up     = find(logic_ele_filt_up);                                       % row index ele hit filter
                ev_ele_filter_up    = ismember(app.Data.e.raw(:,1),pos_ele_filt_up);                 % logic ele event filter
                logic_filter_up     = logic_filter_up & ev_ele_filter_up;

                logic_ele_filt_dwn   = app.Data_Momentum.TH > 0 &  abs(app.Data_Momentum.PHI) < deg2rad(app.Scans_AngleSpanEditField.Value);   % logic ele hit filter
                pos_ele_filt_dwn     = find(logic_ele_filt_dwn);                                       % row index ele hit filter
                ev_ele_filter_dwn    = ismember(app.Data.e.raw(:,1),pos_ele_filt_dwn);                 % logic ele event filter
                logic_filter_dwn     = logic_filter_dwn & ev_ele_filter_dwn;


                % Get filters
                row_filter_up_ele = app.Data.e.raw(logic_filter_up,1);
                filter_up_ele = row_filter_up_ele(~isnan(row_filter_up_ele));

                row_filter_dwn_ele = app.Data.e.raw(logic_filter_dwn,1);
                filter_dwn_ele = row_filter_dwn_ele(~isnan(row_filter_dwn_ele));

                row_filter_up_meta = app.Data.e.raw(logic_filter_up,3);
                filter_up_meta = row_filter_up_meta(~isnan(row_filter_up_meta));

                row_filter_dwn_meta = app.Data.e.raw(logic_filter_dwn,3);
                filter_dwn_meta = row_filter_dwn_meta(~isnan(row_filter_dwn_meta));

                if app.Prev_Config_DelayYN.Value
                    delays = unique(app.Data.h.det3.raw(:,app.Prev_Config_DelayRowEditField.Value));
                    delays = [delays; delays(end)+mean(diff(delays))]-mean(diff(delays))/2;
                    data.delayAxis = delays;
                    data.energyAxis = eV;
        
                    % Delay Up
                    cla(app.Scans_DelayUP,'reset')
                    [N,XEDGES,YEDGES] = histcounts2(app.Data_Momentum.E(filter_up_ele), app.Data.h.det3.raw(filter_up_meta,app.Prev_Config_DelayRowEditField.Value),eV,delays);
                    if app.Scans_NormalizesumperdelayCheckBox.Value
                        surf(app.Scans_DelayUP,YEDGES(1:end-1),((XEDGES(1:end-1))),N./sum(N),'edgecolor','none');view(app.Scans_DelayUP,0,90);hold(app.Scans_DelayUP,'on')
                        num2str(app.Scans_AngleUPEditField.Value)
                        tmp = num2str(app.Scans_AngleUPEditField.Value+180);
                        data.(['scan_',tmp]) = N./sum(N);
                    else
                        surf(app.Scans_DelayUP,YEDGES(1:end-1),((XEDGES(1:end-1))),N,'edgecolor','none');view(app.Scans_DelayUP,0,90);hold(app.Scans_DelayUP,'on')
                        tmp = num2str(app.Scans_AngleUPEditField.Value+180);
                        data.(['scan_',tmp]) = N;
                    end
                    
        
                    xlabel(app.Scans_DelayUP,'Delay (fs)')
                    ylabel(app.Scans_DelayUP,'Energy (eV)')
                    xlim(app.Scans_DelayUP,[min(delays) max(delays)])
                    ylim(app.Scans_DelayUP,[0 30])
                    colormap(app.Scans_DelayUP,"jet")


                     % Delay Dwn
                    cla(app.Scans_DelayDWN,'reset')
                    [N,XEDGES,YEDGES] = histcounts2(app.Data_Momentum.E(filter_dwn_ele), app.Data.h.det3.raw(filter_dwn_meta,app.Prev_Config_DelayRowEditField.Value),eV,delays);
                    if app.Scans_NormalizesumperdelayCheckBox.Value
                        surf(app.Scans_DelayDWN,YEDGES(1:end-1),((XEDGES(1:end-1))),N./sum(N),'edgecolor','none');view(app.Scans_DelayDWN,0,90);hold(app.Scans_DelayDWN,'on')
                        tmp = num2str(app.Scans_AngleDWNEditField.Value+180);
                        data.(['scan_',tmp])  = N./sum(N);
                    else
                        surf(app.Scans_DelayDWN,YEDGES(1:end-1),((XEDGES(1:end-1))),N,'edgecolor','none');view(app.Scans_DelayDWN,0,90);hold(app.Scans_DelayDWN,'on')
                        tmp = num2str(app.Scans_AngleDWNEditField.Value+180);
                        data.(['scan_',tmp])  = N;
                    end
        
                    xlabel(app.Scans_DelayDWN,'Delay (fs)')
                    ylabel(app.Scans_DelayDWN,'Energy (eV)')
                    xlim(app.Scans_DelayDWN,[min(delays) max(delays)])
                    ylim(app.Scans_DelayDWN,[0 30])
                    colormap(app.Scans_DelayDWN,"jet")

                    if save_yn
                        save([varargin{2},varargin{1}],'data');
                    end
                end
                if app.Prev_Config_CepYN.Value

                    cep = linspace( mean(app.Data.h.det3.raw(:,app.Prev_Config_CEPRowEditField.Value))-3*std(app.Data.h.det3.raw(:,app.Prev_Config_CEPRowEditField.Value)), ...
                        mean(app.Data.h.det3.raw(:,app.Prev_Config_CEPRowEditField.Value))+3*std(app.Data.h.det3.raw(:,app.Prev_Config_CEPRowEditField.Value)), ...
                        100);

                    % CEP Up
                    cla(app.Scans_CEPUP,'reset')
                    [N,XEDGES,YEDGES] = histcounts2(app.Data_Momentum.E(filter_up_ele), app.Data.h.det3.raw(filter_up_meta,app.Prev_Config_CEPRowEditField.Value),eV,cep);
                    surf(app.Scans_CEPUP,YEDGES(1:end-1),((XEDGES(1:end-1))),N,'edgecolor','none');view(app.Scans_CEPUP,0,90);hold(app.Scans_CEPUP,'on')
        
        
                    xlabel(app.Scans_CEPUP,'CEP')
                    ylabel(app.Scans_CEPUP,'Energy (eV)')
                    xlim(app.Scans_CEPUP,[min(cep) max(cep)])
                    ylim(app.Scans_CEPUP,[0 22])

                     % CEP Dwn
                    cla(app.Scans_CEPDWN,'reset')
                    [N,XEDGES,YEDGES] = histcounts2(app.Data_Momentum.E(filter_dwn_ele), app.Data.h.det3.raw(filter_dwn_meta,app.Prev_Config_CEPRowEditField.Value),eV,cep);
                    surf(app.Scans_CEPDWN,YEDGES(1:end-1),((XEDGES(1:end-1))),N,'edgecolor','none');view(app.Scans_CEPDWN,0,90);hold(app.Scans_CEPDWN,'on')
        
        
                    xlabel(app.Scans_CEPDWN,'CEP')
                    ylabel(app.Scans_CEPDWN,'Energy (eV)')
                    xlim(app.Scans_CEPDWN,[min(cep) max(cep)])
                    ylim(app.Scans_CEPDWN,[0 22])

                end

                close(d)
            end
        end
        
        function  fields = add_fieldnames_rec(app,tree,struct,field)
            try
                fields_tmp = fieldnames(struct.(field));
%                 disp(field)
%                 disp(fields_tmp)
                for ii = 1:length(fields_tmp)
                    new_tree = uitreenode(tree,'Text',fields_tmp{ii});
                    fields = add_fieldnames_rec(app,new_tree,struct.(field),fields_tmp{ii});
                end
            catch
                fields = struct;
                return;
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.app_path = mfilename('fullpath');
            
            load([app.app_path,'_appsettings.mat']);

            app.Prev_Ion_IonStartTOFEditField.Value = appsettings.Prev_IonStartTOFEditField;
            app.Prev_Ion_IonEndTOFEditField.Value = appsettings.Prev_IonEndTOFEditField;
            app.Prev_Ion_IonBinsizeEditField.Value = appsettings.Prev_IonBinsizeEditField;
            app.Prev_Ele_EleStartTOFEditField.Value = appsettings.Prev_EleStartTOFEditField;
            app.Prev_Ele_EleEndTOFEditField.Value = appsettings.Prev_EleEndTOFEditField;
            app.Prev_Ele_EleBinsizeEditField.Value = appsettings.Prev_EleBinsizeEditField;
            app.Prev_Ele_Electront0EditField.Value = appsettings.Prev_Electront0EditField;

            app.Sort_IonStartTOFEditField.Value = appsettings.Sort_IonStartTOFEditField;
            app.Sort_IonEndTOFEditField.Value = appsettings.Sort_IonEndTOFEditField;
            app.Sort_IonBinsizeEditField.Value = appsettings.Sort_IonBinsizeEditField;
            app.Sort_EleStartTOFEditField.Value = appsettings.Sort_EleStartTOFEditField;
            app.Sort_EleEndTOFEditField.Value = appsettings.Sort_EleEndTOFEditField;
            app.Sort_EleBinsizeEditField.Value = appsettings.Sort_EleBinsizeEditField;
            app.Sort_WrappingtimeEditField.Value = appsettings.Sort_WrappingtimeEditField;
            app.Sort_XYfiltterEditField.Value = appsettings.Sort_XYfiltterEditField;
            app.Sort_SpeciesTable.Data = appsettings.Sort_SpeciesTable;

            app.Calib_BFieldEditField.Value = appsettings.Calib_BFieldEditField;
            app.Calib_BFieldSlider.Value = appsettings.Calib_BFieldSlider;
            app.Calib_EFieldEditField.Value = appsettings.Calib_EFieldEditField;
            app.Calib_EFieldSlider.Value = appsettings.Calib_EFieldSlider;
            app.Calib_toffsetEditField.Value = appsettings.Calib_toffsetEditField;
            app.Calib_Ele_Electront0EditField.Value = appsettings.Calib_Electront0EditField;
            app.Calib_CyPeriodEditField.Value = appsettings.Calib_CyFreqEditField;
            app.Calib_Ele_EleYEditField.Value = appsettings.Calib_EleYEditField;
            app.Calib_Ele_EleXEditField.Value = appsettings.Calib_EleXEditField;
            app.Calib_IonXEditField.Value = appsettings.Calib_IonXEditField;
            app.Calib_IonYEditField.Value = appsettings.Calib_IonYEditField;
            app.Calib_dEditField.Value = appsettings.Calib_dEditField;
            app.Calib_LEditField.Value = appsettings.Calib_LEditField;
            app.Calib_exDEditField.Value = appsettings.Calib_exDEditField;
            app.Calib_Ele_XYpolyEditField.Value = appsettings.Calib_Ele_XYpolyEditField;
            app.Calib_Ele_TOFpolyEditField.Value = appsettings.Calib_Ele_TOFpolyEditField;
            app.Calib_Ele_RotatexyzdegEditField.Value = appsettings.Calib_Ele_RotatexyzdegEditField;
            app.Calib_Ele_NodesStartTOFEditField.Value = appsettings.Calib_NodesStartTOFEditField;
            app.Calib_Ele_NodesStopTOFEditField.Value = appsettings.Calib_NodesStopTOFEditField;
            app.Calib_SpeciesTable.Data = appsettings.Calib_SpeciesTable;

            app.PathtofileEditField.Value = appsettings.PathtofileEditField;
            app.PathtometaEditField.Value = appsettings.PathtometaEditField;

        end

        % Button pushed function: BrowsedataButton
        function BrowsedataButtonPushed(app, event)
            [file,path] = uigetfile;                                    % Open dialoge to get file path
            if file ~= 0                                                %If dialoge canceled
                app.PathtofileEditField.Value = [path,file];            % Set field value
                drawnow;                                                % refresh app 
                figure(app.CIELAnalysisAppUIFigure)                     % refresh app 
            end
        end

        % Button pushed function: LoaddataButton
        function LoaddataButtonPushed(app, event)

            if ~isfile(app.PathtofileEditField.Value) %Check if right mat file exists
                selection = uiconfirm(app.CIELAnalysisAppUIFigure,'No such .mat file. Starting Anaconda conversion?','No file','icon','info');
                switch selection
                    case 'OK'
                        try
                            app.LoaddataButton.BackgroundColor = [0.9 0.8 0.5];
                            d = uiprogressdlg(app.CIELAnalysisAppUIFigure,'Title','Converting data...','Message','Loading ASCII','Indeterminate','on');
                            tStart = tic;

                            s = app.PathtofileEditField.Value;
                            [fPath, fName, ~] = fileparts(s);

                            % Load the data into Matlab workspace with ANACONDA
                            exp = IO.COBOLD.convert_2_ANA2([fPath,'\'],fName);

                            % Save data with same name and in same folder as original data in ANACONDA
                            % format as a .mat file.
                            d.Message = 'Saving .mat file';
                            IO.save_exp(exp,[fPath,'\'],fName);

                            d.Message = 'Loading';
                            app.Data = IO.import_raw(app.PathtofileEditField.Value);
                            tEnd = toc(tStart);
                            close(d);
                            uialert(app.CIELAnalysisAppUIFigure,['Done! Loading took ',num2str(tEnd), ' seconds.'],'Loading finished','icon','success');
                            app.LoaddataButton.BackgroundColor = [0.5 0.9 0.5];

                            plotPrev(app);

                        catch ME
                            app.LoaddataButton.BackgroundColor = [0.9 0.5 0.5];
                            report = getReport(ME);
                            uialert(app.CIELAnalysisAppUIFigure,report,'Error Message','icon','error','Interpreter','html');
                            return;
                        end
                    case 'Cancel'
                        return
                end
            else
                try
                d = uiprogressdlg(app.CIELAnalysisAppUIFigure,'Title','Loading data...','Message','Loading','Indeterminate','on');
                app.LoaddataButton.BackgroundColor = [0.9 0.8 0.5];
                tStart = tic;
                app.Data = IO.import_raw(app.PathtofileEditField.Value);
                tEnd = toc(tStart);

                close(d);
                uialert(app.CIELAnalysisAppUIFigure,['Done! Loading took ',num2str(tEnd), ' seconds.'],'Loading finished','icon','success');
                app.LoaddataButton.BackgroundColor = [0.5 0.9 0.5];

                app.TabGroup.Visible = "on";
               
                % Config
                app.Prev_Config_detdataLabel.Text = 'det1 data:';
                app.Prev_Config_MetaTable.Data = [];
                for ii = 1:size(app.Data.h.det1.raw,2)
                    temp = app.Prev_Config_MetaTable.Data;
                    temp2 = {min(app.Data.h.det1.raw(:,ii)),  ...
                        max(app.Data.h.det1.raw(:,ii)), ...
                        mean(diff(app.Data.h.det1.raw(:,ii))), ...
                        mean(app.Data.h.det1.raw(:,ii)), ...
                        std(app.Data.h.det1.raw(:,ii)) ...
                        };
                    temp3 = [temp;temp2];
                    app.Prev_Config_MetaTable.Data = temp3;
                end


                % Data Tree
                a = app.Prev_Config_DataStructTree.Children;
                a.delete;
                fields = fieldnames(app.Data);
                for ii = 1:length(fields)
                    tree = uitreenode(app.Prev_Config_DataStructTree,'Text',fields{ii},'NodeData','app.Data');
                    fields2 = fieldnames(app.Data.(fields{ii}));
                    for kk = 1:length(fields2)
                        tree2 = uitreenode(tree,'Text',fields2{kk},'NodeData',['app.Data.',fields{ii}]);
                        try
                            fields3 = fieldnames(app.Data.(fields{ii}).(fields2{kk}));
                            for jj = 1:length(fields3)
                                uitreenode(tree2,'Text',fields3{jj},'NodeData',['app.Data.',fields{ii},'.', fields2{kk}]);
                            end
                        catch
                        end
                    end
                end

                dirStyle = uistyle("FontWeight","bold");
                addStyle(app.Prev_Config_DataStructTree,dirStyle,"level",1)
                catch ME
                    app.LoaddataButton.BackgroundColor = [0.9 0.5 0.5];
                    report = getReport(ME);
                    uialert(app.CIELAnalysisAppUIFigure,report,'Error Message','icon','error','Interpreter','html');
                    return;
                end
            end
        end

        % Callback function: Prev_Ele_EleBinsizeEditField, 
        % ...and 10 other components
        function Prev_IonStartTOFEditFieldValueChanged(app, event)
            plotPrev(app);       
        end

        % Callback function: Sort_EleBinsizeEditField, 
        % ...and 9 other components
        function Sort_UpdateplotsButtonPushed(app, event)
            plotSort(app); 
        end

        % Button pushed function: Sort_AddspeciesButton
        function Sort_AddspeciesButtonPushed(app, event)
            temp = app.Sort_SpeciesTable.Data;
            temp2 = {'Name',0,0,0,0,0,0,0};
            temp3 = [temp;temp2];
            app.Sort_SpeciesTable.Data = temp3;
        end

        % Close request function: CIELAnalysisAppUIFigure
        function CIELAnalysisAppUIFigureCloseRequest(app, event)
            appsettings.Prev_IonStartTOFEditField = app.Prev_Ion_IonStartTOFEditField.Value;
            appsettings.Prev_IonEndTOFEditField = app.Prev_Ion_IonEndTOFEditField.Value;
            appsettings.Prev_IonBinsizeEditField = app.Prev_Ion_IonBinsizeEditField.Value;
            appsettings.Prev_EleStartTOFEditField = app.Prev_Ele_EleStartTOFEditField.Value;
            appsettings.Prev_EleEndTOFEditField = app.Prev_Ele_EleEndTOFEditField.Value;
            appsettings.Prev_EleBinsizeEditField = app.Prev_Ele_EleBinsizeEditField.Value;
            appsettings.Prev_Electront0EditField = app.Prev_Ele_Electront0EditField.Value;

            appsettings.Sort_IonStartTOFEditField = app.Sort_IonStartTOFEditField.Value;
            appsettings.Sort_IonEndTOFEditField = app.Sort_IonEndTOFEditField.Value;
            appsettings.Sort_IonBinsizeEditField = app.Sort_IonBinsizeEditField.Value;
            appsettings.Sort_EleStartTOFEditField = app.Sort_EleStartTOFEditField.Value;
            appsettings.Sort_EleEndTOFEditField = app.Sort_EleEndTOFEditField.Value;
            appsettings.Sort_EleBinsizeEditField = app.Sort_EleBinsizeEditField.Value;
            appsettings.Sort_WrappingtimeEditField = app.Sort_WrappingtimeEditField.Value;
            appsettings.Sort_XYfiltterEditField = app.Sort_XYfiltterEditField.Value;
            appsettings.Sort_SpeciesTable = app.Sort_SpeciesTable.Data;

            appsettings.Calib_BFieldEditField = app.Calib_BFieldEditField.Value;
            appsettings.Calib_BFieldSlider = app.Calib_BFieldSlider.Value;
            appsettings.Calib_EFieldEditField = app.Calib_EFieldEditField.Value;
            appsettings.Calib_EFieldSlider = app.Calib_EFieldSlider.Value;
            appsettings.Calib_toffsetEditField = app.Calib_toffsetEditField.Value;
            appsettings.Calib_Electront0EditField = app.Calib_Ele_Electront0EditField.Value;
            appsettings.Calib_CyFreqEditField = app.Calib_CyPeriodEditField.Value;
            appsettings.Calib_EleYEditField = app.Calib_Ele_EleYEditField.Value;
            appsettings.Calib_EleXEditField = app.Calib_Ele_EleXEditField.Value;
            appsettings.Calib_IonXEditField = app.Calib_IonXEditField.Value;
            appsettings.Calib_IonYEditField = app.Calib_IonYEditField.Value;
            appsettings.Calib_dEditField = app.Calib_dEditField.Value;
            appsettings.Calib_LEditField = app.Calib_LEditField.Value;
            appsettings.Calib_exDEditField = app.Calib_exDEditField.Value;
            appsettings.Calib_Ele_XYpolyEditField = app.Calib_Ele_XYpolyEditField.Value;
            appsettings.Calib_Ele_TOFpolyEditField = app.Calib_Ele_TOFpolyEditField.Value;
            appsettings.Calib_Ele_RotatexyzdegEditField = app.Calib_Ele_RotatexyzdegEditField.Value;
            appsettings.Calib_NodesStartTOFEditField = app.Calib_Ele_NodesStartTOFEditField.Value;
            appsettings.Calib_NodesStopTOFEditField = app.Calib_Ele_NodesStopTOFEditField.Value;
            appsettings.Calib_SpeciesTable = app.Calib_SpeciesTable.Data;

            appsettings.PathtofileEditField = app.PathtofileEditField.Value;
            appsettings.PathtometaEditField = app.PathtometaEditField.Value; 

            save([app.app_path,'_appsettings.mat'],'appsettings','-mat')
            delete(app)
            
        end

        % Button pushed function: Sort_RemovespeciesButton
        function Sort_RemovespeciesButtonPushed(app, event)
            temp = app.Sort_SpeciesTable.Data;
            temp_s = size(temp);
            app.Sort_SpeciesTable.Data = temp(1:(temp_s(1)-1),1:temp_s(2));
        end

        % Button pushed function: Sort_SortButton
        function Sort_SortButtonPushed(app, event)
            %% Do the sorting

            % Time between laser shots, factor to wrap tof axis with
            modd = app.Sort_WrappingtimeEditField.Value;
            TOFs = app.Sort_SpeciesTable.Data(:,3);
            TOFspans = app.Sort_SpeciesTable.Data(:,4);
            cycles = app.Sort_SpeciesTable.Data(:,5);
            
            d = uiprogressdlg(app.CIELAnalysisAppUIFigure,'Title','Sorting...','Message','Sorting multi-bunch data');
            tStart = tic;

            %OBS argon is now put at the wrapped TOF, not the real one!
        
            %Pre-allocation
            data_new.e.raw      = zeros(length(app.Data.h.det2.raw(:,3)),3) *NaN;
            data_new.h.det1.raw = zeros(length(app.Data.h.det2.raw(:,3)),3) *NaN;
            data_new.h.det2.raw = zeros(length(app.Data.h.det2.raw(:,3)),3) *NaN;
            data_new.h.det3.raw = zeros(length(app.Data.h.det2.raw(:,3)),3) *NaN;

            % index counters
            c = 1;          % ion hit counter
            he_ar = 0;      % helium or argon
            ev_i = 1;       % new ion event counter (same as c probably)
            ev_el = 1;      % new electron event counter
            ev_meta = 1;    % new meta event counter

            for ev = 1:(length(app.Data.e.raw)*app.Sort_PercentageofeventstosortEditField.Value/100)

                d.Value = ev/(length(app.Data.e.raw)*app.Sort_PercentageofeventstosortEditField.Value/100);

                % find hits during bunch
                if ev == length(app.Data.e.raw) % last bunch special case
                    e_span = app.Data.e.raw(ev,1):(length(app.Data.h.det1.raw));
                    i_span = app.Data.e.raw(ev,2):(length(app.Data.h.det2.raw));
                else
                    counter_e = 1;
                    while isnan(app.Data.e.raw(ev+counter_e,1)) && (ev+counter_e < length(app.Data.e.raw))
                        counter_e = counter_e+1;
                    end
                    e_span = app.Data.e.raw(ev,1):(app.Data.e.raw(ev+counter_e,1)-1);

                    counter_i = 1;
                    while isnan(app.Data.e.raw(ev+counter_i,2)) && (ev+counter_i < length(app.Data.e.raw))
                        counter_i = counter_i+1;
                    end
                    i_span = app.Data.e.raw(ev,2):(app.Data.e.raw(ev+counter_i,2)-1);

                end

                if sum(isnan(e_span)) || sum(isnan(i_span)) % if no hits
                    continue
                end

                % hit info
                det3 = app.Data.h.det3.raw(ev,:);
                det1 = app.Data.h.det1.raw(e_span,:);
                det2 = app.Data.h.det2.raw(i_span,:);

                for k = 1:length(det2(:,1)) % iterate over ion hits
                    data_new.h.det2.raw(c,:) = det2(k,:);
                    data_new.h.det2.raw(c,3) = mod(data_new.h.det2.raw(c,3),modd);
                    data_new.e.raw(c,2) = c;%ev_i;
                    data_new.e.raw(c,3) = ev_meta;
                    data_new.h.det3.raw(c,:) = det3;

                    ev_meta = ev_meta+1;
                    ev_i = ev_i+1;
                    current_cycle = 0;

                    for kk = 1:length(TOFs)
                    %is it species?
                        if (mod(det2(k,3)-TOFs{kk}, modd)< TOFspans{kk} || mod(det2(k,3)-TOFs{kk},modd)>app.Sort_WrappingtimeEditField.Value-TOFspans{kk})

                            data_new.h.det2.raw(c,3) = mod(data_new.h.det2.raw(c,3),modd)+cycles{kk}*modd;

                            %in which cycle am I?
                            cycle = ceil(det2(k,3)/modd)-cycles{kk};

                            if current_cycle == cycle
                                he_ar = he_ar + 1;
                                continue
                            end

                            target_time = (cycle-1)* modd + app.Prev_Ele_Electront0EditField.Value;
                            [minimum,index] = min(abs(det1(:,3)-target_time));

                            if minimum < 150 % electron at right time
                                data_new.h.det1.raw(c,:) = det1(index,:);
                                data_new.h.det1.raw(c,3) = mod(data_new.h.det1.raw(c,3),modd);
                                data_new.e.raw(c,1) = ev_el;
                                ev_el = ev_el+1;
                            end
                            current_cycle = cycle;
                        end

                    end

                    c = c+1;
                end
            end

            % clean up pre-allocated arrays, only keep actual data
            data_new.h.det1.raw = data_new.h.det1.raw(~isnan(sum(data_new.h.det1.raw,2)),:);
            data_new.h.det2.raw = data_new.h.det2.raw(~isnan(sum(data_new.h.det2.raw,2)),:);
            data_new.h.det3.raw = data_new.h.det3.raw(~isnan(sum(data_new.h.det3.raw,2)),:);
            data_new.e.raw      = data_new.e.raw(     ~isnan(    data_new.e.raw(:,2)   ),:);

            app.Data_Sorted.h = data_new.h;
            app.Data_Sorted.e = data_new.e;

            close(d);

            tEnd = toc(tStart);
            uialert(app.CIELAnalysisAppUIFigure,['Done! Sorting took ',num2str(tEnd), ' seconds.'],'Sorting finished','icon','success');

            plotSort(app);
        end

        % Button pushed function: Sort_SaveButton
        function Sort_SaveButtonPushed(app, event)
            if ~isempty(app.Data_Sorted)
                e = app.Data_Sorted.e;
                h = app.Data_Sorted.h;

                try 
                    [filename, filepath] = uiputfile('*.mat', 'Save the sorted data:');
                    FileName = fullfile(filepath, filename);
                    save(FileName, 'e','h', '-v7.3');
                catch ME
                    report = getReport(ME);
                    uialert(fig,report,'Error Message','Interpreter','html');
                end
                drawnow;                              % refresh app 
                figure(app.CIELAnalysisAppUIFigure)   % refresh app 
            else
                selection = uiconfirm(app.CIELAnalysisAppUIFigure,...
                    'There is no current sorted dataset. Do you wish to save the active dataset insted?','No sorted data','icon','info');
                switch selection
                    case 'OK'
                        e = app.Data.e;
                        h = app.Data.h;
                        
                        try
                            [filename, filepath] = uiputfile('*.mat', 'Save the sorted data:');
                            FileName = fullfile(filepath, filename);
                            save(FileName, {'e','h'}, '-v7.3');
                        catch ME
                            report = getReport(ME);
                            uialert(fig,report,'Error Message','Interpreter','html');
                        end
                        drawnow;                             % refresh app 
                        figure(app.CIELAnalysisAppUIFigure)  % refresh app 
                    case 'Cancel'
                end
            end
        end

        % Button pushed function: Sort_ReplaceButton
        function Sort_ReplaceButtonPushed(app, event)
            selection = uiconfirm(app.CIELAnalysisAppUIFigure,'Warning! Raw data will be replaced by the sorted data in app memory. Loaded dataset will be cleared.','Confirm replace','icon','warning');
                switch selection
                    case 'OK'
                        app.Data = app.Data_Sorted;
                        app.Data_Sorted = [];
                    case 'Cancel'
                end
        end

        % Callback function: Calib_BFieldEditField,
        % Calib_CyPeriodEditField, 
        % ...and 22 other components
        function Calib_UpdateplotsButtonPushed(app, event)
            try strcmp(event.Source.Tag,'Calib_UpdatePlots')
                if ~strcmp(event.Source.Tag,'Calib_UpdatePlots')
                    if app.Calib_UpdateliveCheckBox.Value
                        yn = 1;
                    else 
                        yn = 0;
                    end
                else
                    yn = 1;
                end
            catch
                if app.Calib_UpdateliveCheckBox.Value
                    yn = 1;
                else
                    yn = 0;
                end
            end
            if yn
                app.Calib_EFieldSumEditField.Value = app.Calib_EFieldEditField.Value.*(1+app.Calib_EFieldSlider.Value/100)/app.Calib_exDEditField.Value;
                app.Calib_BFieldSumEditField.Value = app.Calib_BFieldEditField.Value.*(1+app.Calib_BFieldSlider.Value/100);
                if app.Calib_Ele_LiveconversionCheckBox.Value
                    app.Calib_Ele_ConvertButtonPushed(app);
                else
                    plotCalib(app);
                end
            end
        end

        % Button pushed function: Calib_AddspeciesButton
        function Calib_AddspeciesButtonPushed(app, event)
            temp = app.Calib_SpeciesTable.Data;
            temp2 = {'Name',0,0,50,0,1,0,0};
            temp3 = [temp;temp2];
            app.Calib_SpeciesTable.Data = temp3;
        end

        % Button pushed function: Calib_RemovespeciesButton
        function Calib_RemovespeciesButtonPushed(app, event)
            temp = app.Calib_SpeciesTable.Data;
            temp_s = size(temp);
            app.Calib_SpeciesTable.Data = temp(1:(temp_s(1)-1),1:temp_s(2));
        end

        % Button pushed function: Calib_DuplicatespeciesButton
        function Calib_DuplicatespeciesButtonPushed(app, event)
            temp = app.Calib_SpeciesTable.Data;
            app.Sort_SpeciesTable.Data = temp;
        end

        % Callback function
        function Calib_EFieldSliderValueChanging(app, event)
                E_mod = event.Value;
                E = app.Calib_EFieldEditField.Value.*(1+E_mod/100)/app.Calib_exDEditField.Value;
                app.Calib_EFieldSumEditField.Value = E;
                if app.Calib_LiveplottingCheckBox.Value
                    plotCalib(app);
                end                  
        end

        % Callback function
        function UsesubsetCheckBoxValueChanged(app, event)
            if app.UsesubsetCheckBox.Value
                app.Data_Full = app.Data;
                events = app.Data.e.raw(1:app.SubsetEventsEditField.Value:end,:);
                
                det1h = app.Data.h.det1.raw(events(~isnan(events(:,1)),1),:);
                det2h = app.Data.h.det2.raw(events(~isnan(events(:,2)),2),:);
                det3h = app.Data.h.det3.raw(events(~isnan(events(:,3)),3),:);

                events(~isnan(events(:,1)),1) = 1:sum(~isnan(events(:,1)));
                events(~isnan(events(:,2)),1) = 1:sum(~isnan(events(:,2)));
                events(~isnan(events(:,3)),1) = 1:sum(~isnan(events(:,3)));
                
                app.Data.e.raw = events;
                app.Data.h.det1.raw = det1h;
                app.Data.h.det2.raw = det2h;
                app.Data.h.det3.raw = det3h;
                app.Data_Momentum = [];
                cla(app.Calib_Ele_pxpz,'reset')
                cla(app.Calib_Ele_pypz,'reset')
                cla(app.Calib_Ele_eVtheta,'reset')
                cla(app.Calib_Ele_UpDown,'reset')
                plotCalib(app);
            else
                app.Data = app.Data_Full;
                app.Data_Full = [];
                app.Data_Momentum = []; 
                cla(app.Calib_Ele_pxpz,'reset')
                cla(app.Calib_Ele_pypz,'reset')
                cla(app.Calib_Ele_eVtheta,'reset')
                cla(app.Calib_Ele_UpDown,'reset')
                plotCalib(app);
            end
            
        end

        % Callback function
        function Calib_BFieldSliderValueChanging(app, event)
            B_mod = event.Value;
            B = app.Calib_BFieldEditField.Value.*(1+B_mod/100);
            app.Calib_BFieldSumEditField.Value = B;
            if app.Calib_LiveplottingCheckBox.Value
                plotCalib(app);
            end
        end

        % Button pushed function: Calib_Ele_ConvertButton
        function Calib_Ele_ConvertButtonPushed(app, event)
            app.Data_Momentum =[];
            d = uiprogressdlg(app.CIELAnalysisAppUIFigure,'Title','Converting...','Message','Converting to momentum','Indeterminate','on');
            tStart = tic;

            TOF_poly = app.Calib_Ele_TOFpolyEditField.Value;
            TOF_poly =str2double(strsplit(TOF_poly,','));
            TOF_poly = [flip(TOF_poly) 0];
            xy_poly = app.Calib_Ele_XYpolyEditField.Value;
            xy_poly = str2double(strsplit(xy_poly,','));
            xy_poly = [flip(xy_poly) 0];

            x       = (app.Data.h.det1.raw(:,1)-app.Calib_Ele_EleXEditField.Value);
            y       = (app.Data.h.det1.raw(:,2)-app.Calib_Ele_EleYEditField.Value);
            t       = app.Data.h.det1.raw(:,3)-app.Calib_toffsetEditField.Value;


            omega   = app.e*app.Calib_BFieldSumEditField.Value/app.me;
            t0      = sqrt(2*app.me*app.Calib_dEditField.Value/(app.e*app.Calib_EFieldSumEditField.Value))*(2*app.Calib_dEditField.Value+app.Calib_LEditField.Value)/(2*app.Calib_dEditField.Value)*1e9;
            t_corr = polyval(TOF_poly,(t0-t)*1e-9);
            x_corr = polyval(xy_poly,x);
            y_corr = polyval(xy_poly,y);

            app.Data_Momentum.p_cart(:,1)   =   0.5*app.me*omega*(x_corr.*cot(omega*(t)*1e-9/2)+y_corr)*1e-3/app.Pamu;    % normalize to amu unit
            app.Data_Momentum.p_cart(:,2)   =   0.5*app.me*omega*(y_corr.*cot(omega*(t)*1e-9/2)-x_corr)*1e-3/app.Pamu;    % normalize to amu unit
%             app.Data_Momentum.p_cart(:,3)   =   app.e*app.Calib_EFieldSumEditField.Value*((t0-t)*1e-9 + 2e-13*((t0-t)).^3*0)/app.Pamu;   % orignal time file is in ns unit
            app.Data_Momentum.p_cart(:,3)   =   app.e*app.Calib_EFieldSumEditField.Value*(t_corr)/app.Pamu;   % orignal time file is in ns unit
            
            rot_xyz = app.Calib_Ele_RotatexyzdegEditField.Value;
            rot_xyz = str2double(strsplit(rot_xyz,','));
            
            % rot matrix
            a = deg2rad(rot_xyz(1));
            b = deg2rad(rot_xyz(2));
            g = deg2rad(rot_xyz(3));
            rot = [cos(a) -sin(a) 0; sin(a) cos(a) 0; 0 0 1]*[cos(b) 0 sin(b); 0 1 0; -sin(b) 0 cos(b)]*[1 0 0; 0 cos(g) -sin(g); 0 sin(g) cos(g)];
            temp1 = app.Data_Momentum.p_cart(:,1)*rot(1,1)+app.Data_Momentum.p_cart(:,2)*rot(1,2)+app.Data_Momentum.p_cart(:,3)*rot(1,3);
            temp2 = app.Data_Momentum.p_cart(:,1)*rot(2,1)+app.Data_Momentum.p_cart(:,2)*rot(2,2)+app.Data_Momentum.p_cart(:,3)*rot(2,3);
            temp3 = app.Data_Momentum.p_cart(:,1)*rot(3,1)+app.Data_Momentum.p_cart(:,2)*rot(3,2)+app.Data_Momentum.p_cart(:,3)*rot(3,3);

            app.Data_Momentum.p_cart(:,2) = temp1;
            app.Data_Momentum.p_cart(:,1) = temp2;
            app.Data_Momentum.p_cart(:,3) = temp3;

            [app.Data_Momentum.TH,app.Data_Momentum.PHI,app.Data_Momentum.R] = cart2sph(app.Data_Momentum.p_cart(:,1),app.Data_Momentum.p_cart(:,3),app.Data_Momentum.p_cart(:,2));
            app.Data_Momentum.E = app.Data_Momentum.R.^2*(app.Pamu^2/app.me/app.e)/2; 

            tEnd = toc(tStart);
            close(d);

            plotCalib(app);

        end

        % Value changed function: Calib_EFieldSlider
        function Calib_EFieldSliderValueChanged(app, event)
            if app.Calib_ScaleEBandtoffsCheckBox.Value
                E = app.Calib_EFieldEditField.Value.*(1+app.Calib_EFieldSlider.Value/100)/app.Calib_exDEditField.Value;
                t0 = sqrt(2*app.me*app.Calib_dEditField.Value/(app.e*E))*(2*app.Calib_dEditField.Value+app.Calib_LEditField.Value)/(2*app.Calib_dEditField.Value)*1e9;
                toffs = app.Calib_Ele_Electront0EditField.Value-t0;
                T_cyc = (t0+app.Calib_EBoffsetEditField.Value)/1.5;
                B = 2*pi*app.me/T_cyc/app.e*1e9;

                
                app.Calib_toffsetEditField.Value = toffs;
                app.Calib_CyPeriodEditField.Value = T_cyc;
                app.Calib_BFieldEditField.Value = B;
                app.Calib_BFieldSlider.Value = 0;
            end
            app.Calib_UpdateplotsButtonPushed(app);
        end

        % Value changed function: Calib_BFieldSlider
        function Calib_BFieldSliderValueChanged(app, event)
            if app.Calib_ScaleEBandtoffsCheckBox.Value
            end
            app.Calib_UpdateplotsButtonPushed(app);            
        end

        % Value changed function: Calib_toffsetEditField
        function Calib_toffsetEditFieldValueChanged(app, event)
            if app.Calib_ScaleEBandtoffsCheckBox.Value
            end
            app.Calib_UpdateplotsButtonPushed(app);            
        end

        % Button pushed function: Scans_UpdateplotsButton
        function Scans_UpdateplotsButtonPushed(app, event)
            plotScans(app);
        end

        % Selection changed function: Prev_Config_DataStructTree
        function Prev_Config_DataStructTreeSelectionChanged(app, event)
            selectedNodes = app.Prev_Config_DataStructTree.SelectedNodes;
            app.Prev_Config_DataInfoTextArea.Value = evalc(['disp(',selectedNodes.NodeData,')']);

            if contains(selectedNodes.Text,'det')
                app.Prev_Config_detdataLabel.Text = [selectedNodes.Text, ' data:'];
                app.Prev_Config_MetaTable.Data = [];
                for ii = 1:size(app.Data.h.(selectedNodes.Text).raw,2)
                    temp = app.Prev_Config_MetaTable.Data;
                    temp2 = {min(app.Data.h.(selectedNodes.Text).raw(:,ii)),  ...
                        max(app.Data.h.(selectedNodes.Text).raw(:,ii)), ...
                        mean(diff(app.Data.h.(selectedNodes.Text).raw(:,ii))), ...
                        mean(app.Data.h.(selectedNodes.Text).raw(:,ii)), ...
                        std(app.Data.h.(selectedNodes.Text).raw(:,ii)) ...
                        };
                    temp3 = [temp;temp2];
                    app.Prev_Config_MetaTable.Data = temp3;
                end
            end

        end

        % Value changed function: Prev_Config_ElectronExistsCheckBox
        function Prev_Config_ElectronExistsCheckBoxValueChanged(app, event)
            value = app.Prev_Config_ElectronExistsCheckBox.Value;
%             childs = allchild(app.Prev_ElectronsTab);
            children = get(app.Prev_ElectronsTab,'Children');
            if value
                set(children,'Visible','on');
            else
                set(children,'Visible','off');
            end

            children = get(app.Prev_ElectronIonTab,'Children');
            if value && app.Prev_Config_IonExistsCheckBox.Value
                set(children,'Visible','on');
            else
                set(children,'Visible','off');
            end

            children = get(app.Calib_ElectronsTab,'Children');
            if value
                set(children,'Visible','on');
            else
                set(children,'Visible','off');
            end

            children = get(app.Calib_ElectronIonTab,'Children');
            if value && app.Prev_Config_IonExistsCheckBox.Value
                set(children,'Visible','on');
            else
                set(children,'Visible','off');
            end


        end

        % Value changed function: Prev_Config_IonExistsCheckBox
        function Prev_Config_IonExistsCheckBoxValueChanged(app, event)
            value = app.Prev_Config_IonExistsCheckBox.Value;

            children = get(app.Prev_IonsTab,'Children');
            if value
                set(children,'Visible','on');
            else
                set(children,'Visible','off');
            end

            children = get(app.Prev_ElectronIonTab,'Children');
            if value && app.Prev_Config_ElectronExistsCheckBox.Value
                set(children,'Visible','on');
            else
                set(children,'Visible','off');
            end

            children = get(app.Calib_IonsTab,'Children');
            if value
                set(children,'Visible','on');
            else
                set(children,'Visible','off');
            end

            children = get(app.Calib_ElectronIonTab,'Children');
            if value && app.Prev_Config_ElectronExistsCheckBox.Value
                set(children,'Visible','on');
            else
                set(children,'Visible','off');
            end

        end

        % Button pushed function: ReduceButton
        function ReduceButtonPushed(app, event)
            selection = uiconfirm(app.CIELAnalysisAppUIFigure,'Reducing the data set clears the original data. To use the full dataset, you must reload the file. Proceed?','Reduce data','icon','warning');
            switch selection
                case 'OK'
                    endEvent = round(app.ReducebyEditField.Value/100*length(app.Data.e.raw));
                    Datanew.e.raw = app.Data.e.raw(1:endEvent,:);
                    fields = fieldnames(app.Data.h);
                    for ii=1:length(fields)
                        if strcmp(fields{ii},'det3')
                            Datanew.h.(fields{ii}).raw = app.Data.h.(fields{ii}).raw(1:endEvent,:);
                        else
                            lastHit = max(app.Data.e.raw(1:endEvent,ii));
                            nextHitAll = app.Data.e.raw((app.Data.e.raw(:,ii) > lastHit),ii);
                            nextHit = nextHitAll(1);
                            Datanew.h.(fields{ii}).raw = app.Data.h.(fields{ii}).raw(1:nextHit-1,:);
                        end
                    end
                    app.Data = Datanew;
                    uialert(app.CIELAnalysisAppUIFigure,'Done!','Data reduced','icon','success');
                case 'Cancel'
                    return
            end
        end

        % Button pushed function: LoadmetaButton
        function LoadmetaButtonPushed(app, event)
            
            if ~isfile(app.PathtofileEditField.Value) %Check if right mat file exists
                uialert(app.CIELAnalysisAppUIFigure,'No such file!','No file','icon','info');
            else
                app.LoadmetaButton.BackgroundColor = [0.9 0.5 0.5];
                load(app.PathtometaEditField.Value);
                app.mdata = mdata;
                app.LoadmetaButton.BackgroundColor = [0.5 0.9 0.5];


                app.Calib_EFieldEditField.Value     = app.mdata.spec.volt.V;
                app.Calib_BFieldEditField.Value     = app.mdata.spec.Bfield;
                app.Calib_dEditField.Value          = app.mdata.spec.dist.d;
                app.Calib_LEditField.Value          = app.mdata.spec.dist.l;
                app.Calib_exDEditField.Value        = app.mdata.spec.dist.exD; 
                app.Calib_BFieldSlider.Value        = 0;
                app.Calib_EFieldSlider.Value        = 0;

                app.Calib_IonXEditField.Value       = app.mdata.corr.det2.dY;
                app.Calib_IonYEditField.Value       = app.mdata.corr.det2.dX;	

                app.Calib_Ele_EleXEditField.Value   = app.mdata.corr.det1.dX;
                app.Calib_Ele_EleYEditField.Value   = app.mdata.corr.det1.dY;
                app.Calib_toffsetEditField.Value    = app.mdata.corr.det1.dTOF;

                app.Calib_Ele_Electront0EditField.Value   = app.mdata.conv.det1.m2q.factor*sqrt(app.mdata.conv.det1.m2q_label.labels)+app.Calib_toffsetEditField.Value;

                app.Calib_SpeciesTable.Data = app.mdata.app.speciestable;

                app.Calib_Ele_TOFpolyEditField.Value = app.mdata.app.TOFpoly;
                app.Calib_Ele_XYpolyEditField.Value = app.mdata.app.XYpoly; 
                app.Calib_Ele_RotatexyzdegEditField.Value = app.mdata.app.rotationXYZ; 

            end
        end

        % Button pushed function: BrowsemetaButton
        function BrowsemetaButtonPushed(app, event)
            [file,path] = uigetfile;                                    % Open dialoge to get file path
            if file ~= 0                                                %If dialoge canceled
                app.PathtometaEditField.Value = [path,file];            % Set field value
                drawnow;                                                % refresh app 
                figure(app.CIELAnalysisAppUIFigure)                     % refresh app 
            end
        end

        % Button pushed function: SavemetaButton
        function SavemetaButtonPushed(app, event)
            app.SavemetaButton.BackgroundColor = [0.9 0.5 0.5];
            mdata = app.mdata;

            mdata.spec.volt.V           = app.Calib_EFieldEditField.Value.*(1+app.Calib_EFieldSlider.Value/100);
            mdata.spec.Bfield           = app.Calib_BFieldEditField.Value.*(1+app.Calib_BFieldSlider.Value/100);

            mdata.spec.dist.d           = app.Calib_dEditField.Value;
            mdata.spec.dist.l           = app.Calib_LEditField.Value;
            mdata.spec.dist.exD         = app.Calib_exDEditField.Value;
            mdata.spec.Efield           = app.mdata.spec.volt.V /app.mdata.spec.dist.exD;

            mdata.corr.det2.dY          = app.Calib_IonXEditField.Value;
            mdata.corr.det2.dX          = app.Calib_IonYEditField.Value;
            mdata.corr.det2.dTOF        = app.Calib_toffsetEditField.Value;

            mdata.corr.det1.dX          = app.Calib_Ele_EleXEditField.Value;
            mdata.corr.det1.dY          = app.Calib_Ele_EleYEditField.Value;
            mdata.corr.det1.dTOF        = app.Calib_toffsetEditField.Value;

            mdata.conv.det1.m2q.factor  = (app.Calib_Ele_Electront0EditField.Value - app.Calib_toffsetEditField.Value)/sqrt(app.mdata.conv.det1.m2q_label.labels);
            mdata.app.TOFpoly           = app.Calib_Ele_TOFpolyEditField.Value;
            mdata.app.XYpoly            = app.Calib_Ele_XYpolyEditField.Value;
            mdata.app.rotationXYZ       = app.Calib_Ele_RotatexyzdegEditField.Value;

            temp = app.Calib_SpeciesTable.Data;
            
            if ~isempty(temp)
                mdata.app.speciestable                 = temp;
                mdata.sample.constituent.masses		   = cell2mat(temp(:,2)); % [a.m.u.] mass of constituents of sample
                mdata.sample.constituent.names 		   = temp(:,1); % [] names of constituents of sample
                mdata.sample.fragment.masses           = cell2mat(temp(:,2)); % [a.m.u.] mass of fragments of sample
                mdata.sample.fragment.names            = temp(:,1); % [] names of fragments
                mdata.sample.fragment.nof              = [1]; % [] number of fragment masses a single fragment can be composed of.
                mdata.sample.mass                      = sum(mdata.sample.fragment.masses.*mdata.sample.fragment.nof);% [a.m.u] mass of sample

                mdata.sample.m_avg                     = mean(mdata.sample.constituent.masses); % [a.m.u.] The average mass of the incoming sample.

                mdata.conv.det2.m2q.factor          = (temp{1,3} - app.Calib_toffsetEditField.Value)/sqrt(mdata.sample.constituent.masses(1)); %Conversion factor from mass 2 charge to TOF (ions)
                mdata.conv.det2.m2q_label.labels 	= [mdata.sample.fragment.masses]; % the available m2q labels.
                mdata.conv.det2.m2q_label.mass 		= mdata.conv.det2.m2q_label.labels;
            end

            if app.OverwritecurrentmetaCheckBox.Value
                save(app.PathtometaEditField.Value, 'mdata');
            else
                uisave('mdata','MDATA')
            end

            drawnow;                              % refresh app
            figure(app.CIELAnalysisAppUIFigure)   % refresh app 
            app.SavemetaButton.BackgroundColor = [0.5 0.9 0.5];
        end

        % Button pushed function: Scans_SavescansButton
        function Scans_SavescansButtonPushed(app, event)
            [filepath,name,ext] = fileparts(app.PathtofileEditField.Value);
            [file,path] = uiputfile([filepath,'\']);
            plotScans(app,file,path);
        end

        % Button pushed function: Ana_LoadTablesButton
        function Ana_LoadTablesButtonPushed(app, event)
            app.Ana_LoadTablesButton.BackgroundColor = [0.9 0.9 0.5];
            d = uiprogressdlg(app.CIELAnalysisAppUIFigure,'Title','Searching structure...','Message','Loading structures','Indeterminate','on');
            if ~isempty(app.Data)
                a = app.Ana_DataTree.Children;
                a.delete;
                fields = add_fieldnames_rec(app,app.Ana_DataTree,app,'Data');
            end
            if ~isempty(app.mdata)
                a = app.Ana_MdataTree.Children;
                a.delete;
                fields = add_fieldnames_rec(app,app.Ana_MdataTree,app,'mdata');
            end
            close(d);
            app.Ana_LoadTablesButton.BackgroundColor = [0.5 0.9 0.5];
        end

        % Selection changed function: Ana_DataTree
        function Ana_DataTreeSelectionChanged(app, event)
            selectedNodes = app.Ana_DataTree.SelectedNodes;
            dispStr = selectedNodes.Text;
            fieldArr = {selectedNodes.Text};
            currentNode = selectedNodes;
            parentNode = currentNode.Parent;
            itr = 0;
            while isprop(parentNode,'Text') && itr < 10;
                   currentNode = currentNode.Parent;
                   parentNode = currentNode.Parent;
                   dispStr = [currentNode.Text,'.',dispStr];
                   fieldArr = [fieldArr, currentNode.Text];
                   itr = itr+1;
            end
            value = app.Data;
            for ii = -length(fieldArr):-1
                kk = -ii;
                value = value.(fieldArr{kk});
            end

            if numel(value) <100
                app.Ana_FieldvalueTextArea.Value = evalc(['disp(value)']);
            else
                str = ['Min',char(9),char(9),'| Max',char(9),'| Mean',char(9),'| STD ',newline];
                for ii = 1:size(value,2)
                    str = [str,newline,num2str(min(value(:,ii))),char(9),'| ',num2str(max(value(:,ii))),char(9),'| ',num2str(mean(value(:,ii))),char(9),'| ',num2str(std(value(:,ii)))];
                end
                app.Ana_FieldvalueTextArea.Value = str;
            end

        end

        % Selection changed function: Ana_MdataTree
        function Ana_MdataTreeSelectionChanged(app, event)
            selectedNodes = app.Ana_MdataTree.SelectedNodes;
            dispStr = selectedNodes.Text;
            fieldArr = {selectedNodes.Text};
            currentNode = selectedNodes;
            parentNode = currentNode.Parent;
            itr = 0;
            while isprop(parentNode,'Text') && itr < 100;
                   currentNode = currentNode.Parent;
                   parentNode = currentNode.Parent;
                   dispStr = [currentNode.Text,'.',dispStr];
                   fieldArr = [fieldArr, currentNode.Text];
                   itr = itr+1;
            end
            value = app.mdata;
            for ii = -length(fieldArr):-1
                kk = -ii;
                value = value.(fieldArr{kk});
            end

            if numel(value) <100
                app.Ana_FieldvalueTextArea.Value = evalc(['disp(value)']);
            end
            
        end

        % Button pushed function: Ana_RemovefieldButton
        function Ana_RemovefieldButtonPushed(app, event)
            selectedNodes = app.Ana_MdataTree.SelectedNodes;
            if isprop(selectedNodes,'Text')
                dispStr = selectedNodes.Text;
                fieldArr = {selectedNodes.Text};
                currentNode = selectedNodes;
                parentNode = currentNode.Parent;
                itr = 0;
                while isprop(parentNode,'Text') && itr < 100;
                    currentNode = currentNode.Parent;
                    parentNode = currentNode.Parent;
                    dispStr = [currentNode.Text,'.',dispStr];
                    fieldArr = [fieldArr, currentNode.Text];
                    itr = itr+1;
                end

                fieldStr = 'app.mdata';
                for ii = -length(fieldArr):-2
                    kk = -ii;
                    fieldStr = [fieldStr,'.', fieldArr{kk}];
                end

                evalStr = [fieldStr,' = rmfield(',fieldStr,',''',selectedNodes.Text,''')'];
                selection = uiconfirm(app.CIELAnalysisAppUIFigure,['Remove run command: "',evalStr,'"?'],'Confirm removal',...
                    'Icon','warning');
                switch selection
                    case "OK"
                        eval(evalStr);
                        Ana_LoadTablesButtonPushed(app, event);
                    case "Cancel"
                end
            end
                  
        end

        % Button pushed function: Ana_CorrectButton
        function Ana_CorrectButtonPushed(app, event)
            if  isempty(app.Data) ||  isempty(app.mdata)
                uialert(app.CIELAnalysisAppUIFigure,'No data or metadata loaded!','No data','Icon','error');
            else
                d = uiprogressdlg(app.CIELAnalysisAppUIFigure,'Title','Anaconda Correct','Message','Running correction macro','Indeterminate','on');
                try
                    T = evalc('app.Data = macro.correct(app.Data, app.mdata);');
                    app.Ana_AnacondaLogTextArea.Value = T;
                catch ME
                    report = getReport(ME);
                    app.Ana_AnacondaLogTextArea.Value = report;
                end

                close(d);

                a = app.Ana_DataTree.Children;
                a.delete;
                fields = add_fieldnames_rec(app,app.Ana_DataTree,app,'Data');
            end
        end

        % Button pushed function: Ana_ConvertButton
        function Ana_ConvertButtonPushed(app, event)
            if  isempty(app.Data) ||  isempty(app.mdata)
                uialert(app.CIELAnalysisAppUIFigure,'No data or metadata loaded!','No data','Icon','error');
            else
                d = uiprogressdlg(app.CIELAnalysisAppUIFigure,'Title','Anaconda Convert','Message','Running conversion macro','Indeterminate','on');
                try
                    T = evalc('app.Data = macro.convert(app.Data, app.mdata);');
                    app.Ana_AnacondaLogTextArea.Value = T;
                catch ME
                    report = getReport(ME);
                    app.Ana_AnacondaLogTextArea.Value = report;
                end
                close(d);
                a = app.Ana_DataTree.Children;
                a.delete;
                fields = add_fieldnames_rec(app,app.Ana_DataTree,app,'Data');
            end
        end

        % Button pushed function: Ana_PlotButton
        function Ana_PlotButtonPushed(app, event)
            if  isempty(app.Data) ||  isempty(app.mdata)
                uialert(app.CIELAnalysisAppUIFigure,'No data or metadata loaded!','No data','Icon','error');
            else
                d = uiprogressdlg(app.CIELAnalysisAppUIFigure,'Title','Anaconda Plot','Message','Running plot macro','Indeterminate','on');
                try
                    T = evalc('macro.plot(app.Data, app.mdata);');
                    app.Ana_AnacondaLogTextArea.Value = T;
                catch ME
                    report = getReport(ME);
                    app.Ana_AnacondaLogTextArea.Value = report;
                end
                close(d);
            end
        end

        % Button pushed function: Ana_GoButton
        function Ana_GoButtonPushed(app, event)
            value = app.Ana_CommandEditField.Value;
            if length(value) > 4 && strcmp(value(1:5),'mdata')
                value = ['app.',value];
                eval(value);
                Ana_LoadTablesButtonPushed(app, event);
            else
                uialert(app.CIELAnalysisAppUIFigure,'Start with mdata.','Invalid command!','Icon','error')
            end
        end

        % Button pushed function: Ana_GoifdoButton
        function Ana_GoifdoButtonPushed(app, event)
            value = app.Ana_SetallifdofieldstoEditField.Value;

            if app.Ana_corrCheckBox.Value
                fieldnr = {fieldnames(app.mdata.corr.det1.ifdo); fieldnames(app.mdata.corr.det2.ifdo)};
                for ii = 1:2
                    for kk = 1:length(fieldnr{ii})
                        cells = fieldnr{ii};
                        app.mdata.corr.(['det',num2str(ii)]).ifdo.(cells{kk}) = value;
                    end
                end
            end

            if app.Ana_convCheckBox.Value
                fieldnr = {fieldnames(app.mdata.conv.det1.ifdo); fieldnames(app.mdata.conv.det2.ifdo)};
                for ii = 1:2
                    for kk = 1:length(fieldnr{ii})
                        cells = fieldnr{ii};
                        app.mdata.conv.(['det',num2str(ii)]).ifdo.(cells{kk}) = value;
                    end
                end
            end

            if app.Ana_plotCheckBox.Value
                fieldnr = {fieldnames(app.mdata.plot.det1.ifdo); fieldnames(app.mdata.plot.det2.ifdo)};
                for ii = 1:2
                    for kk = 1:length(fieldnr{ii})
                        cells = fieldnr{ii};
                        app.mdata.plot.(['det',num2str(ii)]).ifdo.(cells{kk}) = value;
                    end
                end
            end

            Ana_LoadTablesButtonPushed(app, event);
        end

        % Button pushed function: Ana_CloseallfiguresButton
        function Ana_CloseallfiguresButtonPushed(app, event)
            Figures = findobj( 'Type', 'Figure' , '-not' , 'Tag' , get( app.CIELAnalysisAppUIFigure , 'Tag' ) );
            NFigures = length( Figures );
            for nFigures = 1 : NFigures
                close( Figures( nFigures ) );
            end
        end

        % Button pushed function: Ana_CP_CreateButton
        function Ana_CP_CreateButtonPushed(app, event)
            if ~isempty(app.mdata)
                try
                    if ~isempty(app.Ana_CP_Signal2EditField.Value)
                        app.mdata.plot.(app.Ana_CP_DetectorEditField.Value).ifdo.(app.Ana_CP_NameEditField.Value) = true;
                        app.mdata.plot.(app.Ana_CP_DetectorEditField.Value).(app.Ana_CP_NameEditField.Value) =  ...
                            metadata.create.plot.signal_2_plot({app.mdata.plot.signal.(app.Ana_CP_Signal1EditField.Value),app.mdata.plot.signal.(app.Ana_CP_Signal2EditField.Value)});
                        app.mdata.plot.(app.Ana_CP_DetectorEditField.Value).(app.Ana_CP_NameEditField.Value).axes.XLim = str2double(strsplit(app.Ana_CP_XLim1EditField.Value,','));
                        app.mdata.plot.(app.Ana_CP_DetectorEditField.Value).(app.Ana_CP_NameEditField.Value).axes.XLabel.String = app.Ana_CP_XLabel1EditField.Value;
                        app.mdata.plot.(app.Ana_CP_DetectorEditField.Value).(app.Ana_CP_NameEditField.Value).axes.YLim = str2double(strsplit(app.Ana_CP_XLim2EditField.Value,','));
                        app.mdata.plot.(app.Ana_CP_DetectorEditField.Value).(app.Ana_CP_NameEditField.Value).axes.YLabel.String = app.Ana_CP_XLabel2EditField.Value;
                        app.mdata.plot.(app.Ana_CP_DetectorEditField.Value).(app.Ana_CP_NameEditField.Value).axes.Title.String = app.Ana_CP_Title1EditField.Value;
                        if ~isempty(app.Ana_CP_ConditionEditField.Value)
                            app.mdata.plot.(app.Ana_CP_DetectorEditField.Value).(app.Ana_CP_NameEditField.Value).cond = app.mdata.cond.(app.Ana_CP_ConditionEditField.Value);
                        end
                    else
                        app.mdata.plot.(app.Ana_CP_DetectorEditField.Value).ifdo.(app.Ana_CP_NameEditField.Value) = true;
                        app.mdata.plot.(app.Ana_CP_DetectorEditField.Value).(app.Ana_CP_NameEditField.Value) =  ...
                            metadata.create.plot.signal_2_plot({app.mdata.plot.signal.(app.Ana_CP_Signal1EditField.Value)});
                        app.mdata.plot.(app.Ana_CP_DetectorEditField.Value).(app.Ana_CP_NameEditField.Value).axes.XLim = str2double(strsplit(app.Ana_CP_XLim1EditField.Value,','));
                        app.mdata.plot.(app.Ana_CP_DetectorEditField.Value).(app.Ana_CP_NameEditField.Value).axes.XLabel.String = app.Ana_CP_XLabel1EditField.Value;
                        app.mdata.plot.(app.Ana_CP_DetectorEditField.Value).(app.Ana_CP_NameEditField.Value).axes.Title.String = app.Ana_CP_Title1EditField.Value;
                        if ~isempty(app.Ana_CP_ConditionEditField.Value)
                            app.mdata.plot.(app.Ana_CP_DetectorEditField.Value).(app.Ana_CP_NameEditField.Value).cond = app.mdata.cond.(app.Ana_CP_ConditionEditField.Value);
                        end
                    end
                    Ana_LoadTablesButtonPushed(app,event);
                catch ME
                    uialert(app.CIELAnalysisAppUIFigure,'Something went wrong! Check that all fields are entered properly.','Error!','Icon','error')
                    report = getReport(ME);
                    app.Ana_AnacondaLogTextArea.Value = report;
                end
            end
        end

        % Button pushed function: SavereducedButton
        function SavereducedButtonPushed(app, event)
            e.raw = app.Data.e.raw;
            h.det1.raw = app.Data.h.det1.raw;
            h.det2.raw = app.Data.h.det2.raw;
            h.det3.raw = app.Data.h.det3.raw;
            uisave({'e','h'},'data_reduced');
        end

        % Button pushed function: Ana_CopyfieldtocommandlineButton
        function Ana_CopyfieldtocommandlineButtonPushed(app, event)
            selectedNodes = app.Ana_MdataTree.SelectedNodes;
            dispStr = selectedNodes.Text;
            fieldArr = {selectedNodes.Text};
            currentNode = selectedNodes;
            parentNode = currentNode.Parent;
            itr = 0;
            while isprop(parentNode,'Text') && itr < 100;
                   currentNode = currentNode.Parent;
                   parentNode = currentNode.Parent;
                   dispStr = [currentNode.Text,'.',dispStr];
                   fieldArr = [fieldArr, currentNode.Text];
                   itr = itr+1;
            end
            value = app.mdata;
            for ii = -length(fieldArr):-1
                kk = -ii;
                value = value.(fieldArr{kk});
            end
            
        if ~isstruct(value)
            if ischar(value)
                app.Ana_CommandEditField.Value = ['mdata.',dispStr,' = ''',evalc(['disp(value)']),''';'];
            elseif numel(value) > 1
                if size(value,1) == 1
                    app.Ana_CommandEditField.Value = ['mdata.',dispStr,' = [',evalc(['disp(value)']),'];'];
                else
                    value = (strsplit(convertCharsToStrings(evalc(['disp(value)'])),'\n'))
                    dispValue = value{1};
                    for ii = 2:length(value)-1
                        dispValue = [dispValue,';', value{ii}];
                    end
                    app.Ana_CommandEditField.Value = ['mdata.',dispStr,' = [',dispValue,'];'];
%                 else
%                     app.Ana_CommandEditField.Value = ['mdata.',dispStr,' = '];
                end
            else
                app.Ana_CommandEditField.Value = ['mdata.',dispStr,' = ',evalc(['disp(value)']),';'];
            end
        else
            app.Ana_CommandEditField.Value  = ['mdata.',dispStr];
        end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.CIELAnalysisAppUIFigure = uifigure('Visible', 'off');
            app.CIELAnalysisAppUIFigure.Name = 'CIEL Analysis App';
            app.CIELAnalysisAppUIFigure.CloseRequestFcn = createCallbackFcn(app, @CIELAnalysisAppUIFigureCloseRequest, true);
            app.CIELAnalysisAppUIFigure.Tag = 'MainWindow';
            app.TabGroup = uitabgroup(app.CIELAnalysisAppUIFigure);
            app.PreviewTab = uitab(app.TabGroup);
            app.PreviewTab.Title = 'Preview';
            app.Prev_UpdateplotsButton = uibutton(app.PreviewTab, 'push');
            app.Prev_UpdateplotsButton.ButtonPushedFcn = createCallbackFcn(app, @Prev_IonStartTOFEditFieldValueChanged, true);
            app.Prev_UpdateplotsButton.Text = 'Update plots';
            app.PrevTabs = uitabgroup(app.PreviewTab);
            app.Prev_ConfigTab = uitab(app.PrevTabs);
            app.Prev_ConfigTab.Title = 'Config';
            app.Prev_Config_MetaTable = uitable(app.Prev_ConfigTab);
            app.Prev_Config_MetaTable.ColumnName = {'Min Value'; 'Max Value'; 'Average diff'; 'Mean'; 'STD'};
            app.Prev_Config_MetaTable.RowName = {};
            app.TimestampEditFieldLabel = uilabel(app.Prev_ConfigTab);
            app.TimestampEditFieldLabel.Text = 'Timestamp';
            app.Prev_Config_TimestampRowEditField = uieditfield(app.Prev_ConfigTab, 'numeric');
            app.Prev_Config_TimestampRowEditField.Limits = [0 Inf];
            app.Prev_Config_TimestampRowEditField.RoundFractionalValues = 'on';
            app.Prev_Config_TimestampRowEditField.Value = 1;
            app.CEPEditFieldLabel = uilabel(app.Prev_ConfigTab);
            app.CEPEditFieldLabel.Text = 'CEP';
            app.Prev_Config_CEPRowEditField = uieditfield(app.Prev_ConfigTab, 'numeric');
            app.Prev_Config_CEPRowEditField.Limits = [0 Inf];
            app.Prev_Config_CEPRowEditField.RoundFractionalValues = 'on';
            app.Prev_Config_CEPRowEditField.Value = 3;
            app.DelayEditFieldLabel = uilabel(app.Prev_ConfigTab);
            app.DelayEditFieldLabel.Text = 'Delay';
            app.Prev_Config_DelayRowEditField = uieditfield(app.Prev_ConfigTab, 'numeric');
            app.Prev_Config_DelayRowEditField.Limits = [0 Inf];
            app.Prev_Config_DelayRowEditField.RoundFractionalValues = 'on';
            app.Prev_Config_DelayRowEditField.Value = 2;
            app.Prev_Config_CepYN = uicheckbox(app.Prev_ConfigTab);
            app.Prev_Config_CepYN.Text = 'CEP exists';
            app.Prev_Config_DelayYN = uicheckbox(app.Prev_ConfigTab);
            app.Prev_Config_DelayYN.Text = 'Delay exists';
            app.Prev_Config_detdataLabel = uilabel(app.Prev_ConfigTab);
            app.Prev_Config_detdataLabel.Text = 'det3 data:';
            app.Prev_Config_DataStructTree = uitree(app.Prev_ConfigTab);
            app.Prev_Config_DataStructTree.SelectionChangedFcn = createCallbackFcn(app, @Prev_Config_DataStructTreeSelectionChanged, true);
            app.Prev_Config_DatastructureLabel = uilabel(app.Prev_ConfigTab);
            app.Prev_Config_DatastructureLabel.Text = 'Data structure:';
            app.INFOLabel = uilabel(app.Prev_ConfigTab);
            app.INFOLabel.Text = 'INFO';
            app.Prev_Config_DataInfoTextArea = uitextarea(app.Prev_ConfigTab);
            app.ElectrondetectorEditFieldLabel = uilabel(app.Prev_ConfigTab);
            app.ElectrondetectorEditFieldLabel.Text = 'Electron detector';
            app.Prev_Config_ElectrondetectorEditField = uieditfield(app.Prev_ConfigTab, 'numeric');
            app.Prev_Config_ElectrondetectorEditField.Value = 1;
            app.IondetectorEditFieldLabel = uilabel(app.Prev_ConfigTab);
            app.IondetectorEditFieldLabel.Text = 'Ion detector';
            app.Prev_Config_IondetectorEditField = uieditfield(app.Prev_ConfigTab, 'numeric');
            app.Prev_Config_IondetectorEditField.Value = 2;
            app.EventdetectorEditFieldLabel = uilabel(app.Prev_ConfigTab);
            app.EventdetectorEditFieldLabel.Text = 'Event detector';
            app.Prev_Config_EventdetectorEditField = uieditfield(app.Prev_ConfigTab, 'numeric');
            app.Prev_Config_EventdetectorEditField.Value = 3;
            app.Prev_Config_ElectronExistsCheckBox = uicheckbox(app.Prev_ConfigTab);
            app.Prev_Config_ElectronExistsCheckBox.ValueChangedFcn = createCallbackFcn(app, @Prev_Config_ElectronExistsCheckBoxValueChanged, true);
            app.Prev_Config_ElectronExistsCheckBox.Text = 'Exists';
            app.Prev_Config_ElectronExistsCheckBox.Value = true;
            app.Prev_Config_IonExistsCheckBox = uicheckbox(app.Prev_ConfigTab);
            app.Prev_Config_IonExistsCheckBox.ValueChangedFcn = createCallbackFcn(app, @Prev_Config_IonExistsCheckBoxValueChanged, true);
            app.Prev_Config_IonExistsCheckBox.Text = 'Exists';
            app.Prev_Config_IonExistsCheckBox.Value = true;
            app.Prev_Config_EventDetExistsCheckBox = uicheckbox(app.Prev_ConfigTab);
            app.Prev_Config_EventDetExistsCheckBox.Text = 'Exists';
            app.Prev_Config_EventDetExistsCheckBox.Value = true;
            app.Prev_Config_detdataLabel_2 = uilabel(app.Prev_ConfigTab);
            app.Prev_Config_detdataLabel_2.Text = 'Event detector assignments:';
            app.Prev_Config_detdataLabel_3 = uilabel(app.Prev_ConfigTab);
            app.Prev_Config_detdataLabel_3.Text = 'Detector assignments:';
            app.Prev_ElectronsTab = uitab(app.PrevTabs);
            app.Prev_ElectronsTab.Title = 'Electrons';
            app.Prev_Ele_FishXTOF = uiaxes(app.Prev_ElectronsTab);
            title(app.Prev_Ele_FishXTOF, 'Fish X-TOF')
            xlabel(app.Prev_Ele_FishXTOF, 'TOF')
            ylabel(app.Prev_Ele_FishXTOF, 'X (mm)')
            zlabel(app.Prev_Ele_FishXTOF, 'Z')
            app.Prev_Ele_FishYTOF = uiaxes(app.Prev_ElectronsTab);
            title(app.Prev_Ele_FishYTOF, 'Fish Y-TOF')
            xlabel(app.Prev_Ele_FishYTOF, 'TOF')
            ylabel(app.Prev_Ele_FishYTOF, 'Y (mm)')
            zlabel(app.Prev_Ele_FishYTOF, 'Z')
            app.Prev_Ele_EleXY = uiaxes(app.Prev_ElectronsTab);
            title(app.Prev_Ele_EleXY, 'Electrons XY')
            xlabel(app.Prev_Ele_EleXY, 'X (mm)')
            ylabel(app.Prev_Ele_EleXY, 'Y (mm)')
            zlabel(app.Prev_Ele_EleXY, 'Z')
            app.Prev_Ele_ElectronTof = uiaxes(app.Prev_ElectronsTab);
            title(app.Prev_Ele_ElectronTof, 'Electron tof')
            xlabel(app.Prev_Ele_ElectronTof, 'TOF (ns)')
            ylabel(app.Prev_Ele_ElectronTof, 'Counts')
            app.Prev_Ele_Countrates = uiaxes(app.Prev_ElectronsTab);
            title(app.Prev_Ele_Countrates, 'Title')
            xlabel(app.Prev_Ele_Countrates, 'X')
            ylabel(app.Prev_Ele_Countrates, 'Y')
            zlabel(app.Prev_Ele_Countrates, 'Z')
            app.Prev_Ele_HitsPerEvent = uiaxes(app.Prev_ElectronsTab);
            title(app.Prev_Ele_HitsPerEvent, 'Title')
            xlabel(app.Prev_Ele_HitsPerEvent, 'X')
            ylabel(app.Prev_Ele_HitsPerEvent, 'Y')
            zlabel(app.Prev_Ele_HitsPerEvent, 'Z')
            app.StartTOFEditFieldLabel_2 = uilabel(app.Prev_ElectronsTab);
            app.StartTOFEditFieldLabel_2.Text = 'Start TOF';
            app.Prev_Ele_EleStartTOFEditField = uieditfield(app.Prev_ElectronsTab, 'numeric');
            app.Prev_Ele_EleStartTOFEditField.ValueChangedFcn = createCallbackFcn(app, @Prev_IonStartTOFEditFieldValueChanged, true);
            app.Prev_Ele_EleStartTOFEditField.Value = -500;
            app.EndTOFEditFieldLabel_2 = uilabel(app.Prev_ElectronsTab);
            app.EndTOFEditFieldLabel_2.Text = 'End TOF';
            app.Prev_Ele_EleEndTOFEditField = uieditfield(app.Prev_ElectronsTab, 'numeric');
            app.Prev_Ele_EleEndTOFEditField.ValueChangedFcn = createCallbackFcn(app, @Prev_IonStartTOFEditFieldValueChanged, true);
            app.Prev_Ele_EleEndTOFEditField.Value = 5000;
            app.BinsizeEditFieldLabel_2 = uilabel(app.Prev_ElectronsTab);
            app.BinsizeEditFieldLabel_2.Text = 'Bin size';
            app.Prev_Ele_EleBinsizeEditField = uieditfield(app.Prev_ElectronsTab, 'numeric');
            app.Prev_Ele_EleBinsizeEditField.ValueChangedFcn = createCallbackFcn(app, @Prev_IonStartTOFEditFieldValueChanged, true);
            app.Prev_Ele_EleBinsizeEditField.Value = 5;
            app.Electront0EditFieldLabel = uilabel(app.Prev_ElectronsTab);
            app.Electront0EditFieldLabel.Text = 'Electron t0';
            app.Prev_Ele_Electront0EditField = uieditfield(app.Prev_ElectronsTab, 'numeric');
            app.Prev_Ele_Electront0EditField.ValueChangedFcn = createCallbackFcn(app, @Prev_IonStartTOFEditFieldValueChanged, true);
            app.Prev_Ele_Electront0EditField.Value = 100;
            app.Prev_Ele_UseonlyrealeleCheckBox = uicheckbox(app.Prev_ElectronsTab);
            app.Prev_Ele_UseonlyrealeleCheckBox.ValueChangedFcn = createCallbackFcn(app, @Prev_IonStartTOFEditFieldValueChanged, true);
            app.Prev_Ele_UseonlyrealeleCheckBox.Text = 'Use only real ele';
            app.ReelespanEditFieldLabel = uilabel(app.Prev_ElectronsTab);
            app.ReelespanEditFieldLabel.Text = 'Re ele span';
            app.Prev_Ele_ReelespanEditField = uieditfield(app.Prev_ElectronsTab, 'numeric');
            app.Prev_Ele_ReelespanEditField.Limits = [2.22044604925031e-16 Inf];
            app.Prev_Ele_ReelespanEditField.ValueChangedFcn = createCallbackFcn(app, @Prev_IonStartTOFEditFieldValueChanged, true);
            app.Prev_Ele_ReelespanEditField.Value = 50;
            app.WrapEditFieldLabel = uilabel(app.Prev_ElectronsTab);
            app.WrapEditFieldLabel.Text = 'Wrap';
            app.Prev_Ele_WrapEditField = uieditfield(app.Prev_ElectronsTab, 'numeric');
            app.Prev_Ele_WrapEditField.Limits = [2.22044604925031e-16 Inf];
            app.Prev_Ele_WrapEditField.ValueDisplayFormat = '%11.6g';
            app.Prev_Ele_WrapEditField.Value = 4998.7;
            app.ElectronsEditFieldLabel_2 = uilabel(app.Prev_ElectronsTab);
            app.ElectronsEditFieldLabel_2.Text = 'Electrons';
            app.Prev_Ele_MetaElectronsEditField = uieditfield(app.Prev_ElectronsTab, 'numeric');
            app.Prev_Ele_MetaElectronsEditField.Editable = 'off';
            app.AveragecountrateEditField_2Label_2 = uilabel(app.Prev_ElectronsTab);
            app.AveragecountrateEditField_2Label_2.Text = 'Average countrate';
            app.Prev_Ele_MetaEleAveragecountrateEditField = uieditfield(app.Prev_ElectronsTab, 'numeric');
            app.Prev_Ele_MetaEleAveragecountrateEditField.ValueDisplayFormat = '%11.5g';
            app.Prev_Ele_MetaEleAveragecountrateEditField.Editable = 'off';
            app.Prev_IonsTab = uitab(app.PrevTabs);
            app.Prev_IonsTab.Title = 'Ions ';
            app.Prev_Ion_IonXY = uiaxes(app.Prev_IonsTab);
            title(app.Prev_Ion_IonXY, 'Ions XY')
            xlabel(app.Prev_Ion_IonXY, 'X (mm)')
            ylabel(app.Prev_Ion_IonXY, 'Y (mm)')
            zlabel(app.Prev_Ion_IonXY, 'Z')
            app.Prev_Ion_IonTof = uiaxes(app.Prev_IonsTab);
            title(app.Prev_Ion_IonTof, 'Ion tof')
            xlabel(app.Prev_Ion_IonTof, 'TOF (ns)')
            ylabel(app.Prev_Ion_IonTof, 'Counts')
            app.Prev_Ion_FishXTOF = uiaxes(app.Prev_IonsTab);
            title(app.Prev_Ion_FishXTOF, 'Fish X-TOF')
            xlabel(app.Prev_Ion_FishXTOF, 'TOF')
            ylabel(app.Prev_Ion_FishXTOF, 'X (mm)')
            zlabel(app.Prev_Ion_FishXTOF, 'Z')
            app.Prev_Ion_FishYTOF = uiaxes(app.Prev_IonsTab);
            title(app.Prev_Ion_FishYTOF, 'Fish Y-TOF')
            xlabel(app.Prev_Ion_FishYTOF, 'TOF')
            ylabel(app.Prev_Ion_FishYTOF, 'Y (mm)')
            zlabel(app.Prev_Ion_FishYTOF, 'Z')
            app.Prev_Ion_Countrates = uiaxes(app.Prev_IonsTab);
            title(app.Prev_Ion_Countrates, 'Title')
            xlabel(app.Prev_Ion_Countrates, 'X')
            ylabel(app.Prev_Ion_Countrates, 'Y')
            zlabel(app.Prev_Ion_Countrates, 'Z')
            app.Prev_Ion_HitsPerEvent = uiaxes(app.Prev_IonsTab);
            title(app.Prev_Ion_HitsPerEvent, 'Title')
            xlabel(app.Prev_Ion_HitsPerEvent, 'X')
            ylabel(app.Prev_Ion_HitsPerEvent, 'Y')
            zlabel(app.Prev_Ion_HitsPerEvent, 'Z')
            app.Prev_Ion_IonYLogScaleCheckBox = uicheckbox(app.Prev_IonsTab);
            app.Prev_Ion_IonYLogScaleCheckBox.ValueChangedFcn = createCallbackFcn(app, @Prev_IonStartTOFEditFieldValueChanged, true);
            app.Prev_Ion_IonYLogScaleCheckBox.Text = 'Y Log Scale';
            app.StartTOFEditFieldLabel = uilabel(app.Prev_IonsTab);
            app.StartTOFEditFieldLabel.Text = 'Start TOF';
            app.Prev_Ion_IonStartTOFEditField = uieditfield(app.Prev_IonsTab, 'numeric');
            app.Prev_Ion_IonStartTOFEditField.ValueChangedFcn = createCallbackFcn(app, @Prev_IonStartTOFEditFieldValueChanged, true);
            app.Prev_Ion_IonStartTOFEditField.Value = -500;
            app.EndTOFEditFieldLabel = uilabel(app.Prev_IonsTab);
            app.EndTOFEditFieldLabel.Text = 'End TOF';
            app.Prev_Ion_IonEndTOFEditField = uieditfield(app.Prev_IonsTab, 'numeric');
            app.Prev_Ion_IonEndTOFEditField.ValueChangedFcn = createCallbackFcn(app, @Prev_IonStartTOFEditFieldValueChanged, true);
            app.Prev_Ion_IonEndTOFEditField.Value = 5000;
            app.BinsizeEditFieldLabel = uilabel(app.Prev_IonsTab);
            app.BinsizeEditFieldLabel.Text = 'Bin size';
            app.Prev_Ion_IonBinsizeEditField = uieditfield(app.Prev_IonsTab, 'numeric');
            app.Prev_Ion_IonBinsizeEditField.ValueChangedFcn = createCallbackFcn(app, @Prev_IonStartTOFEditFieldValueChanged, true);
            app.Prev_Ion_IonBinsizeEditField.Value = 5;
            app.IonsEditFieldLabel_2 = uilabel(app.Prev_IonsTab);
            app.IonsEditFieldLabel_2.Text = 'Ions';
            app.Prev_Ion_MetaIonsEditField = uieditfield(app.Prev_IonsTab, 'numeric');
            app.Prev_Ion_MetaIonsEditField.Editable = 'off';
            app.AveragecountrateEditFieldLabel_2 = uilabel(app.Prev_IonsTab);
            app.AveragecountrateEditFieldLabel_2.Text = 'Average countrate';
            app.Prev_Ion_MetaIonAveragecountrateEditField = uieditfield(app.Prev_IonsTab, 'numeric');
            app.Prev_Ion_MetaIonAveragecountrateEditField.ValueDisplayFormat = '%11.5g';
            app.Prev_Ion_MetaIonAveragecountrateEditField.Editable = 'off';
            app.Prev_ElectronIonTab = uitab(app.PrevTabs);
            app.Prev_ElectronIonTab.Title = 'Electron + Ion';
            app.Prev_EI_Countrates = uiaxes(app.Prev_ElectronIonTab);
            title(app.Prev_EI_Countrates, 'Title')
            xlabel(app.Prev_EI_Countrates, 'X')
            ylabel(app.Prev_EI_Countrates, 'Y')
            zlabel(app.Prev_EI_Countrates, 'Z')
            app.Prev_EI_HitsPerEvent = uiaxes(app.Prev_ElectronIonTab);
            title(app.Prev_EI_HitsPerEvent, 'Title')
            xlabel(app.Prev_EI_HitsPerEvent, 'X')
            ylabel(app.Prev_EI_HitsPerEvent, 'Y')
            zlabel(app.Prev_EI_HitsPerEvent, 'Z')
            app.EventsEditFieldLabel = uilabel(app.Prev_ElectronIonTab);
            app.EventsEditFieldLabel.Text = 'Events';
            app.Prev_EI_MetaEventsEditField = uieditfield(app.Prev_ElectronIonTab, 'numeric');
            app.Prev_EI_MetaEventsEditField.Editable = 'off';
            app.CoincidenceEditField_2Label = uilabel(app.Prev_ElectronIonTab);
            app.CoincidenceEditField_2Label.Text = 'Coincidence %';
            app.Prev_EI_MetaEvCoincidenceEditField = uieditfield(app.Prev_ElectronIonTab, 'numeric');
            app.Prev_EI_MetaEvCoincidenceEditField.Editable = 'off';
            app.ElectronsEditFieldLabel = uilabel(app.Prev_ElectronIonTab);
            app.ElectronsEditFieldLabel.Text = 'Electrons';
            app.Prev_EI_MetaElectronsEditField = uieditfield(app.Prev_ElectronIonTab, 'numeric');
            app.Prev_EI_MetaElectronsEditField.Editable = 'off';
            app.AveragecountrateEditField_2Label = uilabel(app.Prev_ElectronIonTab);
            app.AveragecountrateEditField_2Label.Text = 'Average countrate';
            app.Prev_EI_MetaEleAveragecountrateEditField = uieditfield(app.Prev_ElectronIonTab, 'numeric');
            app.Prev_EI_MetaEleAveragecountrateEditField.ValueDisplayFormat = '%11.5g';
            app.Prev_EI_MetaEleAveragecountrateEditField.Editable = 'off';
            app.CoincidenceEditField_3Label = uilabel(app.Prev_ElectronIonTab);
            app.CoincidenceEditField_3Label.Text = 'Coincidence %';
            app.Prev_EI_MetaEleCoincidenceEditField = uieditfield(app.Prev_ElectronIonTab, 'numeric');
            app.Prev_EI_MetaEleCoincidenceEditField.Editable = 'off';
            app.IonsEditFieldLabel = uilabel(app.Prev_ElectronIonTab);
            app.IonsEditFieldLabel.Text = 'Ions';
            app.Prev_EI_MetaIonsEditField = uieditfield(app.Prev_ElectronIonTab, 'numeric');
            app.Prev_EI_MetaIonsEditField.Editable = 'off';
            app.AveragecountrateEditFieldLabel = uilabel(app.Prev_ElectronIonTab);
            app.AveragecountrateEditFieldLabel.Text = 'Average countrate';
            app.Prev_EI_MetaIonAveragecountrateEditField = uieditfield(app.Prev_ElectronIonTab, 'numeric');
            app.Prev_EI_MetaIonAveragecountrateEditField.ValueDisplayFormat = '%11.5g';
            app.Prev_EI_MetaIonAveragecountrateEditField.Editable = 'off';
            app.CoincidenceEditFieldLabel = uilabel(app.Prev_ElectronIonTab);
            app.CoincidenceEditFieldLabel.Text = 'Coincidence %';
            app.Prev_EI_MetaIonCoincidenceEditField = uieditfield(app.Prev_ElectronIonTab, 'numeric');
            app.Prev_EI_MetaIonCoincidenceEditField.Editable = 'off';
            app.Prev_LightsourceTab = uitab(app.PrevTabs);
            app.Prev_LightsourceTab.Title = 'Light source';
            app.Prev_LS_TypeTabs = uitabgroup(app.Prev_LightsourceTab);
            app.Prev_LS_LaserTab = uitab(app.Prev_LS_TypeTabs);
            app.Prev_LS_LaserTab.Title = 'Laser';
            app.LaserrepetitionrateHzEditFieldLabel = uilabel(app.Prev_LS_LaserTab);
            app.LaserrepetitionrateHzEditFieldLabel.Text = 'Laser repetition rate (Hz)';
            app.Prev_LS_MetaLaserrepetitionrateHzEditField = uieditfield(app.Prev_LS_LaserTab, 'numeric');
            app.Prev_LS_MetaLaserrepetitionrateHzEditField.Value = 200000;
            app.MaxTOFminLabel = uilabel(app.Prev_LS_LaserTab);
            app.MaxTOFminLabel.Text = 'Max TOF';
            app.Prev_LS_MetaMaxTOFEditField = uieditfield(app.Prev_LS_LaserTab, 'numeric');
            app.Prev_LS_MetaMaxTOFEditField.Editable = 'off';
            app.NrBunchesEditFieldLabel = uilabel(app.Prev_LS_LaserTab);
            app.NrBunchesEditFieldLabel.Text = 'Nr Bunches';
            app.Prev_LS_MetaNrBunchesEditField = uieditfield(app.Prev_LS_LaserTab, 'numeric');
            app.Prev_LS_MetaNrBunchesEditField.Editable = 'off';
            app.Prev_LS_IsLaserCheckBox = uicheckbox(app.Prev_LS_LaserTab);
            app.Prev_LS_IsLaserCheckBox.Text = 'Light source is laser';
            app.Prev_LS_IsLaserCheckBox.Value = true;
            app.FELTab = uitab(app.Prev_LS_TypeTabs);
            app.FELTab.Title = 'FEL';
            app.Prev_LS_IsFELCheckBox = uicheckbox(app.FELTab);
            app.Prev_LS_IsFELCheckBox.Text = 'Light source is FEL';
            app.SynchrotronTab = uitab(app.Prev_LS_TypeTabs);
            app.SynchrotronTab.Title = 'Synchrotron';
            app.Prev_LS_IsSytrnCheckBox = uicheckbox(app.SynchrotronTab);
            app.Prev_LS_IsSytrnCheckBox.Text = 'Light source is synchrotron';
            app.Prev_LS_DelayPanel = uipanel(app.Prev_LightsourceTab);
            app.Prev_LS_DelayPanel.Title = 'Delay';
            app.Prev_LS_Delay = uiaxes(app.Prev_LS_DelayPanel);
            title(app.Prev_LS_Delay, 'Title')
            xlabel(app.Prev_LS_Delay, 'X')
            ylabel(app.Prev_LS_Delay, 'Y')
            zlabel(app.Prev_LS_Delay, 'Z')
            app.MindelayEditFieldLabel = uilabel(app.Prev_LS_DelayPanel);
            app.MindelayEditFieldLabel.Text = 'Min delay';
            app.Prev_LS_MetaMindelayEditField = uieditfield(app.Prev_LS_DelayPanel, 'numeric');
            app.Prev_LS_MetaMindelayEditField.Editable = 'off';
            app.MaxdelayEditFieldLabel = uilabel(app.Prev_LS_DelayPanel);
            app.MaxdelayEditFieldLabel.Text = 'Max delay';
            app.Prev_LS_MetaMaxdelayEditField = uieditfield(app.Prev_LS_DelayPanel, 'numeric');
            app.Prev_LS_MetaMaxdelayEditField.Editable = 'off';
            app.StepsizeEditFieldLabel = uilabel(app.Prev_LS_DelayPanel);
            app.StepsizeEditFieldLabel.Text = 'Stepsize';
            app.Prev_LS_MetaStepsizeEditField = uieditfield(app.Prev_LS_DelayPanel, 'numeric');
            app.Prev_LS_MetaStepsizeEditField.Editable = 'off';
            app.Prev_LS_CEPPanel = uipanel(app.Prev_LightsourceTab);
            app.Prev_LS_CEPPanel.Title = 'CEP';
            app.Prev_LS_CEP = uiaxes(app.Prev_LS_CEPPanel);
            title(app.Prev_LS_CEP, 'Title')
            xlabel(app.Prev_LS_CEP, 'X')
            ylabel(app.Prev_LS_CEP, 'Y')
            zlabel(app.Prev_LS_CEP, 'Z')
            app.CEPMeanLabel = uilabel(app.Prev_LS_CEPPanel);
            app.CEPMeanLabel.Text = 'CEP mean';
            app.Prev_LS_MetaCEPmeanEditField = uieditfield(app.Prev_LS_CEPPanel, 'numeric');
            app.Prev_LS_MetaCEPmeanEditField.Editable = 'off';
            app.CEPSTDEditFieldLabel = uilabel(app.Prev_LS_CEPPanel);
            app.CEPSTDEditFieldLabel.Text = 'CEP STD';
            app.Prev_LS_MetaCEPSTDEditField = uieditfield(app.Prev_LS_CEPPanel, 'numeric');
            app.Prev_LS_MetaCEPSTDEditField.Editable = 'off';
            app.Prev_Config_DatastructureLabel_2 = uilabel(app.Prev_LightsourceTab);
            app.Prev_Config_DatastructureLabel_2.Text = 'Tagged source data:';
            app.AquisitiontimeminLabel = uilabel(app.PreviewTab);
            app.AquisitiontimeminLabel.Text = 'Aquisition time  (min)';
            app.Prev_MetaAquisitiontimeEditField = uieditfield(app.PreviewTab, 'numeric');
            app.Prev_MetaAquisitiontimeEditField.Editable = 'off';
            app.CalibrationTab = uitab(app.TabGroup);
            app.CalibrationTab.Title = 'Calibration';
            app.Calib_UpdateplotsButton = uibutton(app.CalibrationTab, 'push');
            app.Calib_UpdateplotsButton.ButtonPushedFcn = createCallbackFcn(app, @Calib_UpdateplotsButtonPushed, true);
            app.Calib_UpdateplotsButton.Tag = 'Calib_UpdatePlots';
            app.Calib_UpdateplotsButton.Text = 'Update plots';
            app.Calib_Tabs = uitabgroup(app.CalibrationTab);
            app.Calib_ElectronsTab = uitab(app.Calib_Tabs);
            app.Calib_ElectronsTab.Title = 'Electrons';
            app.Calib_Ele_pxpz = uiaxes(app.Calib_ElectronsTab);
            title(app.Calib_Ele_pxpz, 'px-pz')
            xlabel(app.Calib_Ele_pxpz, 'px')
            ylabel(app.Calib_Ele_pxpz, 'pz')
            zlabel(app.Calib_Ele_pxpz, 'Z')
            app.Calib_Ele_pypz = uiaxes(app.Calib_ElectronsTab);
            title(app.Calib_Ele_pypz, 'py pz')
            xlabel(app.Calib_Ele_pypz, 'py')
            ylabel(app.Calib_Ele_pypz, 'pz')
            zlabel(app.Calib_Ele_pypz, 'Z')
            app.Calib_Ele_eVtheta = uiaxes(app.Calib_ElectronsTab);
            title(app.Calib_Ele_eVtheta, 'eV theta')
            xlabel(app.Calib_Ele_eVtheta, 'eV')
            ylabel(app.Calib_Ele_eVtheta, 'rad')
            zlabel(app.Calib_Ele_eVtheta, 'Z')
            app.Calib_Ele_UpDown = uiaxes(app.Calib_ElectronsTab);
            title(app.Calib_Ele_UpDown, 'Up/Down')
            xlabel(app.Calib_Ele_UpDown, 'eV')
            zlabel(app.Calib_Ele_UpDown, 'Z')
            app.Calib_Ele_Nodes_XTOF = uiaxes(app.Calib_ElectronsTab);
            title(app.Calib_Ele_Nodes_XTOF, 'Nodes XTOF')
            xlabel(app.Calib_Ele_Nodes_XTOF, 'X')
            ylabel(app.Calib_Ele_Nodes_XTOF, 'Y')
            zlabel(app.Calib_Ele_Nodes_XTOF, 'Z')
            app.Calib_Ele_Nodes_YTOF = uiaxes(app.Calib_ElectronsTab);
            title(app.Calib_Ele_Nodes_YTOF, 'Nodes YTOF')
            xlabel(app.Calib_Ele_Nodes_YTOF, 'X')
            ylabel(app.Calib_Ele_Nodes_YTOF, 'Y')
            zlabel(app.Calib_Ele_Nodes_YTOF, 'Z')
            app.Calib_Ele_EleTOF = uiaxes(app.Calib_ElectronsTab);
            title(app.Calib_Ele_EleTOF, 'Electon TOF')
            xlabel(app.Calib_Ele_EleTOF, 'X')
            ylabel(app.Calib_Ele_EleTOF, 'Y')
            zlabel(app.Calib_Ele_EleTOF, 'Z')
            app.Calib_Ele_ConvertButton = uibutton(app.Calib_ElectronsTab, 'push');
            app.Calib_Ele_ConvertButton.ButtonPushedFcn = createCallbackFcn(app, @Calib_Ele_ConvertButtonPushed, true);
            app.Calib_Ele_ConvertButton.Text = 'Conver to momentum';
            app.IonposfilterEditFieldLabel = uilabel(app.Calib_ElectronsTab);
            app.IonposfilterEditFieldLabel.Text = 'Ion pos filter';
            app.Calib_Ele_IonposfilterEditField = uieditfield(app.Calib_ElectronsTab, 'numeric');
            app.Calib_Ele_IonposfilterEditField.Limits = [2.22044604925031e-16 Inf];
            app.Calib_Ele_IonposfilterEditField.ValueChangedFcn = createCallbackFcn(app, @Calib_UpdateplotsButtonPushed, true);
            app.Calib_Ele_IonposfilterEditField.Value = 2;
            app.Calib_Ele_IonposfilterCheckBox = uicheckbox(app.Calib_ElectronsTab);
            app.Calib_Ele_IonposfilterCheckBox.ValueChangedFcn = createCallbackFcn(app, @Calib_UpdateplotsButtonPushed, true);
            app.Calib_Ele_IonposfilterCheckBox.Text = '';
            app.Calib_Ele_SpeciesfilterCheckBox = uicheckbox(app.Calib_ElectronsTab);
            app.Calib_Ele_SpeciesfilterCheckBox.ValueChangedFcn = createCallbackFcn(app, @Calib_UpdateplotsButtonPushed, true);
            app.Calib_Ele_SpeciesfilterCheckBox.Text = '';
            app.SpeciesfilterEditFieldLabel = uilabel(app.Calib_ElectronsTab);
            app.SpeciesfilterEditFieldLabel.Text = 'Species filter';
            app.Calib_Ele_SpeciesfilterEditField = uieditfield(app.Calib_ElectronsTab, 'numeric');
            app.Calib_Ele_SpeciesfilterEditField.ValueChangedFcn = createCallbackFcn(app, @Calib_UpdateplotsButtonPushed, true);
            app.Calib_Ele_SpeciesfilterEditField.Value = 1;
            app.Calib_Ele_LiveconversionCheckBox = uicheckbox(app.Calib_ElectronsTab);
            app.Calib_Ele_LiveconversionCheckBox.Text = '"Live" conversion';
            app.ElectronX0Label = uilabel(app.Calib_ElectronsTab);
            app.ElectronX0Label.Text = 'Electron X0';
            app.Calib_Ele_EleXEditField = uieditfield(app.Calib_ElectronsTab, 'numeric');
            app.Calib_Ele_EleXEditField.ValueChangedFcn = createCallbackFcn(app, @Calib_UpdateplotsButtonPushed, true);
            app.ElectronY0Label = uilabel(app.Calib_ElectronsTab);
            app.ElectronY0Label.Text = 'Electron Y0';
            app.Calib_Ele_EleYEditField = uieditfield(app.Calib_ElectronsTab, 'numeric');
            app.Calib_Ele_EleYEditField.ValueChangedFcn = createCallbackFcn(app, @Calib_UpdateplotsButtonPushed, true);
            app.NominalTOFLabel = uilabel(app.Calib_ElectronsTab);
            app.NominalTOFLabel.Text = 'Nominal TOF';
            app.Calib_Ele_Electront0EditField = uieditfield(app.Calib_ElectronsTab, 'numeric');
            app.Calib_Ele_Electront0EditField.ValueChangedFcn = createCallbackFcn(app, @Calib_UpdateplotsButtonPushed, true);
            app.Calib_Ele_Electront0EditField.Value = 100;
            app.StartTOFEditFieldLabel_5 = uilabel(app.Calib_ElectronsTab);
            app.StartTOFEditFieldLabel_5.Text = 'Start TOF';
            app.Calib_Ele_NodesStartTOFEditField = uieditfield(app.Calib_ElectronsTab, 'numeric');
            app.StopTOFLabel = uilabel(app.Calib_ElectronsTab);
            app.StopTOFLabel.Text = 'Stop TOF';
            app.Calib_Ele_NodesStopTOFEditField = uieditfield(app.Calib_ElectronsTab, 'numeric');
            app.Calib_Ele_NodesStopTOFEditField.Value = 200;
            app.TOFpolynomialcorrectionlowtohighordercommaseparatedLabel = uilabel(app.Calib_ElectronsTab);
            app.TOFpolynomialcorrectionlowtohighordercommaseparatedLabel.Text = 'TOF polynomial correction (low to high order, comma separated):';
            app.Calib_Ele_TOFpolyEditField = uieditfield(app.Calib_ElectronsTab, 'text');
            app.Calib_Ele_TOFpolyEditField.ValueChangedFcn = createCallbackFcn(app, @Calib_UpdateplotsButtonPushed, true);
            app.Calib_Ele_TOFpolyEditField.Value = '1,0,0,0,0,0,0,0';
            app.Calib_Ele_CalibrationlinesCheckBox = uicheckbox(app.Calib_ElectronsTab);
            app.Calib_Ele_CalibrationlinesCheckBox.ValueChangedFcn = createCallbackFcn(app, @Calib_UpdateplotsButtonPushed, true);
            app.Calib_Ele_CalibrationlinesCheckBox.Text = 'Calibration lines';
            app.SpacingeVEditFieldLabel = uilabel(app.Calib_ElectronsTab);
            app.SpacingeVEditFieldLabel.Text = 'Spacing (eV)';
            app.Calib_Ele_SpacingeVEditField = uieditfield(app.Calib_ElectronsTab, 'numeric');
            app.Calib_Ele_SpacingeVEditField.Limits = [0 Inf];
            app.Calib_Ele_SpacingeVEditField.ValueChangedFcn = createCallbackFcn(app, @Calib_UpdateplotsButtonPushed, true);
            app.Calib_Ele_SpacingeVEditField.Value = 3;
            app.OffseteVEditFieldLabel = uilabel(app.Calib_ElectronsTab);
            app.OffseteVEditFieldLabel.Text = 'Offset (eV)';
            app.Calib_Ele_OffseteVEditField = uieditfield(app.Calib_ElectronsTab, 'numeric');
            app.Calib_Ele_OffseteVEditField.ValueChangedFcn = createCallbackFcn(app, @Calib_UpdateplotsButtonPushed, true);
            app.TOFpolynomialcorrectionlowtohighordercommaseparatedLabel_2 = uilabel(app.Calib_ElectronsTab);
            app.TOFpolynomialcorrectionlowtohighordercommaseparatedLabel_2.Text = 'XY polynomial correction (low to high order, comma separated):';
            app.Calib_Ele_XYpolyEditField = uieditfield(app.Calib_ElectronsTab, 'text');
            app.Calib_Ele_XYpolyEditField.ValueChangedFcn = createCallbackFcn(app, @Calib_UpdateplotsButtonPushed, true);
            app.Calib_Ele_XYpolyEditField.Value = '1,0,0,0,0,0,0,0';
            app.RotatexyzdegEditFieldLabel = uilabel(app.Calib_ElectronsTab);
            app.RotatexyzdegEditFieldLabel.Text = 'Rotate (x,y,z deg)';
            app.Calib_Ele_RotatexyzdegEditField = uieditfield(app.Calib_ElectronsTab, 'text');
            app.Calib_Ele_RotatexyzdegEditField.ValueChangedFcn = createCallbackFcn(app, @Calib_UpdateplotsButtonPushed, true);
            app.Calib_Ele_RotatexyzdegEditField.Value = '0,0,0';
            app.Calib_IonsTab = uitab(app.Calib_Tabs);
            app.Calib_IonsTab.Title = 'Ions';
            app.Calib_IonXY = uiaxes(app.Calib_IonsTab);
            title(app.Calib_IonXY, 'Ion XY')
            xlabel(app.Calib_IonXY, 'X')
            ylabel(app.Calib_IonXY, 'Y')
            zlabel(app.Calib_IonXY, 'Z')
            app.Calib_IonTOF = uiaxes(app.Calib_IonsTab);
            title(app.Calib_IonTOF, 'Ion TOF')
            xlabel(app.Calib_IonTOF, 'X')
            ylabel(app.Calib_IonTOF, 'Y')
            zlabel(app.Calib_IonTOF, 'Z')
            app.Calib_AddspeciesButton = uibutton(app.Calib_IonsTab, 'push');
            app.Calib_AddspeciesButton.ButtonPushedFcn = createCallbackFcn(app, @Calib_AddspeciesButtonPushed, true);
            app.Calib_AddspeciesButton.Text = 'Add species';
            app.Calib_RemovespeciesButton = uibutton(app.Calib_IonsTab, 'push');
            app.Calib_RemovespeciesButton.ButtonPushedFcn = createCallbackFcn(app, @Calib_RemovespeciesButtonPushed, true);
            app.Calib_RemovespeciesButton.Text = 'Remove species';
            app.Calib_SpeciesTable = uitable(app.Calib_IonsTab);
            app.Calib_SpeciesTable.ColumnName = {'Species'; 'm/q'; 'TOF'; 'TOF range'; 'Cycle'; 'R'; 'G'; 'B'};
            app.Calib_SpeciesTable.RowName = {};
            app.Calib_SpeciesTable.ColumnEditable = true;
            app.Calib_SpeciesTable.CellEditCallback = createCallbackFcn(app, @Calib_UpdateplotsButtonPushed, true);
            app.IonX0Label = uilabel(app.Calib_IonsTab);
            app.IonX0Label.Text = 'Ion X0';
            app.Calib_IonXEditField = uieditfield(app.Calib_IonsTab, 'numeric');
            app.Calib_IonXEditField.ValueChangedFcn = createCallbackFcn(app, @Calib_UpdateplotsButtonPushed, true);
            app.IonY0Label = uilabel(app.Calib_IonsTab);
            app.IonY0Label.Text = 'Ion Y0';
            app.Calib_IonYEditField = uieditfield(app.Calib_IonsTab, 'numeric');
            app.Calib_IonYEditField.ValueChangedFcn = createCallbackFcn(app, @Calib_UpdateplotsButtonPushed, true);
            app.Calib_IonYLogScaleCheckBox = uicheckbox(app.Calib_IonsTab);
            app.Calib_IonYLogScaleCheckBox.ValueChangedFcn = createCallbackFcn(app, @Calib_UpdateplotsButtonPushed, true);
            app.Calib_IonYLogScaleCheckBox.Text = 'Y Log Scale';
            app.Calib_ElectronIonTab = uitab(app.Calib_Tabs);
            app.Calib_ElectronIonTab.Title = 'Electron+Ion';
            app.Calib_LightsourceTab = uitab(app.Calib_Tabs);
            app.Calib_LightsourceTab.Title = 'Light source';
            app.Calib_SortingMultibunchmodeTab = uitab(app.Calib_Tabs);
            app.Calib_SortingMultibunchmodeTab.Title = 'Sorting (Multi-bunch mode)';
            app.Sort_ElectronTof = uiaxes(app.Calib_SortingMultibunchmodeTab);
            title(app.Sort_ElectronTof, 'Electron tof')
            xlabel(app.Sort_ElectronTof, 'TOF (ns)')
            ylabel(app.Sort_ElectronTof, 'Counts')
            app.Sort_IonTof = uiaxes(app.Calib_SortingMultibunchmodeTab);
            title(app.Sort_IonTof, 'Ion tof')
            xlabel(app.Sort_IonTof, 'TOF (ns)')
            ylabel(app.Sort_IonTof, 'Counts')
            app.Sort_SortedIonTof = uiaxes(app.Calib_SortingMultibunchmodeTab);
            title(app.Sort_SortedIonTof, 'Sorted ion tof')
            xlabel(app.Sort_SortedIonTof, 'TOF (ns)')
            ylabel(app.Sort_SortedIonTof, 'Counts')
            app.Sort_SortedElectronTof = uiaxes(app.Calib_SortingMultibunchmodeTab);
            title(app.Sort_SortedElectronTof, 'Sorted electron tof')
            xlabel(app.Sort_SortedElectronTof, 'TOF (ns)')
            ylabel(app.Sort_SortedElectronTof, 'Counts')
            app.Sort_IonYLogScaleCheckBox = uicheckbox(app.Calib_SortingMultibunchmodeTab);
            app.Sort_IonYLogScaleCheckBox.ValueChangedFcn = createCallbackFcn(app, @Sort_UpdateplotsButtonPushed, true);
            app.Sort_IonYLogScaleCheckBox.Text = 'Y Log Scale';
            app.StartTOFEditFieldLabel_3 = uilabel(app.Calib_SortingMultibunchmodeTab);
            app.StartTOFEditFieldLabel_3.Text = 'Start TOF';
            app.Sort_IonStartTOFEditField = uieditfield(app.Calib_SortingMultibunchmodeTab, 'numeric');
            app.Sort_IonStartTOFEditField.ValueChangedFcn = createCallbackFcn(app, @Sort_UpdateplotsButtonPushed, true);
            app.Sort_IonStartTOFEditField.Value = -500;
            app.EndTOFEditFieldLabel_3 = uilabel(app.Calib_SortingMultibunchmodeTab);
            app.EndTOFEditFieldLabel_3.Text = 'End TOF';
            app.Sort_IonEndTOFEditField = uieditfield(app.Calib_SortingMultibunchmodeTab, 'numeric');
            app.Sort_IonEndTOFEditField.ValueChangedFcn = createCallbackFcn(app, @Sort_UpdateplotsButtonPushed, true);
            app.Sort_IonEndTOFEditField.Value = 5000;
            app.BinsizeEditFieldLabel_3 = uilabel(app.Calib_SortingMultibunchmodeTab);
            app.BinsizeEditFieldLabel_3.Text = 'Bin size';
            app.Sort_IonBinsizeEditField = uieditfield(app.Calib_SortingMultibunchmodeTab, 'numeric');
            app.Sort_IonBinsizeEditField.ValueChangedFcn = createCallbackFcn(app, @Sort_UpdateplotsButtonPushed, true);
            app.Sort_IonBinsizeEditField.Value = 5;
            app.StartTOFEditFieldLabel_4 = uilabel(app.Calib_SortingMultibunchmodeTab);
            app.StartTOFEditFieldLabel_4.Text = 'Start TOF';
            app.Sort_EleStartTOFEditField = uieditfield(app.Calib_SortingMultibunchmodeTab, 'numeric');
            app.Sort_EleStartTOFEditField.ValueChangedFcn = createCallbackFcn(app, @Sort_UpdateplotsButtonPushed, true);
            app.Sort_EleStartTOFEditField.Value = -500;
            app.EndTOFEditFieldLabel_4 = uilabel(app.Calib_SortingMultibunchmodeTab);
            app.EndTOFEditFieldLabel_4.Text = 'End TOF';
            app.Sort_EleEndTOFEditField = uieditfield(app.Calib_SortingMultibunchmodeTab, 'numeric');
            app.Sort_EleEndTOFEditField.ValueChangedFcn = createCallbackFcn(app, @Sort_UpdateplotsButtonPushed, true);
            app.Sort_EleEndTOFEditField.Value = 5000;
            app.BinsizeEditFieldLabel_4 = uilabel(app.Calib_SortingMultibunchmodeTab);
            app.BinsizeEditFieldLabel_4.Text = 'Bin size';
            app.Sort_EleBinsizeEditField = uieditfield(app.Calib_SortingMultibunchmodeTab, 'numeric');
            app.Sort_EleBinsizeEditField.ValueChangedFcn = createCallbackFcn(app, @Sort_UpdateplotsButtonPushed, true);
            app.Sort_EleBinsizeEditField.Value = 5;
            app.XYfiltterEditFieldLabel = uilabel(app.Calib_SortingMultibunchmodeTab);
            app.XYfiltterEditFieldLabel.Text = 'XY filtter';
            app.Sort_XYfiltterEditField = uieditfield(app.Calib_SortingMultibunchmodeTab, 'numeric');
            app.Sort_XYfiltterEditField.ValueChangedFcn = createCallbackFcn(app, @Sort_UpdateplotsButtonPushed, true);
            app.Sort_XYfiltterEditField.Value = 5;
            app.Sort_SpeciesTable = uitable(app.Calib_SortingMultibunchmodeTab);
            app.Sort_SpeciesTable.ColumnName = {'Species'; 'm/q'; 'TOF'; 'TOF range'; 'Cycle'; 'R'; 'G'; 'B'};
            app.Sort_SpeciesTable.RowName = {};
            app.Sort_SpeciesTable.ColumnEditable = true;
            app.Sort_AddspeciesButton = uibutton(app.Calib_SortingMultibunchmodeTab, 'push');
            app.Sort_AddspeciesButton.ButtonPushedFcn = createCallbackFcn(app, @Sort_AddspeciesButtonPushed, true);
            app.Sort_AddspeciesButton.Text = 'Add species';
            app.Sort_RemovespeciesButton = uibutton(app.Calib_SortingMultibunchmodeTab, 'push');
            app.Sort_RemovespeciesButton.ButtonPushedFcn = createCallbackFcn(app, @Sort_RemovespeciesButtonPushed, true);
            app.Sort_RemovespeciesButton.Text = 'Remove species';
            app.Sort_SortButton = uibutton(app.Calib_SortingMultibunchmodeTab, 'push');
            app.Sort_SortButton.ButtonPushedFcn = createCallbackFcn(app, @Sort_SortButtonPushed, true);
            app.Sort_SortButton.Text = 'Sort';
            app.Sort_SaveButton = uibutton(app.Calib_SortingMultibunchmodeTab, 'push');
            app.Sort_SaveButton.ButtonPushedFcn = createCallbackFcn(app, @Sort_SaveButtonPushed, true);
            app.Sort_SaveButton.Text = 'Save';
            app.Sort_ReplaceButton = uibutton(app.Calib_SortingMultibunchmodeTab, 'push');
            app.Sort_ReplaceButton.ButtonPushedFcn = createCallbackFcn(app, @Sort_ReplaceButtonPushed, true);
            app.Sort_ReplaceButton.Text = 'Replace';
            app.WrappingtimeEditFieldLabel = uilabel(app.Calib_SortingMultibunchmodeTab);
            app.WrappingtimeEditFieldLabel.Text = 'Wrapping time';
            app.Sort_WrappingtimeEditField = uieditfield(app.Calib_SortingMultibunchmodeTab, 'numeric');
            app.Sort_WrappingtimeEditField.ValueDisplayFormat = '%.5f';
            app.Sort_WrappingtimeEditField.ValueChangedFcn = createCallbackFcn(app, @Sort_UpdateplotsButtonPushed, true);
            app.Sort_WrappingtimeEditField.Value = 5000;
            app.PercentageofeventstosortEditFieldLabel = uilabel(app.Calib_SortingMultibunchmodeTab);
            app.PercentageofeventstosortEditFieldLabel.Text = 'Percentage of events to sort:';
            app.Sort_PercentageofeventstosortEditField = uieditfield(app.Calib_SortingMultibunchmodeTab, 'numeric');
            app.Sort_PercentageofeventstosortEditField.Value = 100;
            app.Sort_UpdateplotsButton = uibutton(app.Calib_SortingMultibunchmodeTab, 'push');
            app.Sort_UpdateplotsButton.ButtonPushedFcn = createCallbackFcn(app, @Sort_UpdateplotsButtonPushed, true);
            app.Sort_UpdateplotsButton.Text = 'Update plots';
            app.Calib_DuplicatespeciesButton = uibutton(app.Calib_SortingMultibunchmodeTab, 'push');
            app.Calib_DuplicatespeciesButton.ButtonPushedFcn = createCallbackFcn(app, @Calib_DuplicatespeciesButtonPushed, true);
            app.Calib_DuplicatespeciesButton.Text = 'Duplicate fr ion tab';
            app.SliderLabel = uilabel(app.CalibrationTab);
            app.SliderLabel.Text = '+/- %';
            app.dmEditFieldLabel = uilabel(app.CalibrationTab);
            app.dmEditFieldLabel.Text = 'd (m)';
            app.Calib_exDEditField = uieditfield(app.CalibrationTab, 'numeric');
            app.Calib_exDEditField.ValueDisplayFormat = '%.2e';
            app.Calib_exDEditField.ValueChangedFcn = createCallbackFcn(app, @Calib_UpdateplotsButtonPushed, true);
            app.Calib_exDEditField.Value = 0.18;
            app.exDEditFieldLabel = uilabel(app.CalibrationTab);
            app.exDEditFieldLabel.Text = 'exD';
            app.toffsetEditFieldLabel = uilabel(app.CalibrationTab);
            app.toffsetEditFieldLabel.Text = 't offset';
            app.CyclotronperiodLabel = uilabel(app.CalibrationTab);
            app.CyclotronperiodLabel.Text = 'Cyclotron period';
            app.Slider_2Label = uilabel(app.CalibrationTab);
            app.Slider_2Label.Text = '+/- %';
            app.Calib_LEditField = uieditfield(app.CalibrationTab, 'numeric');
            app.Calib_LEditField.ValueDisplayFormat = '%.2e';
            app.Calib_LEditField.ValueChangedFcn = createCallbackFcn(app, @Calib_UpdateplotsButtonPushed, true);
            app.Calib_LEditField.Value = 0.1992;
            app.Calib_dEditField = uieditfield(app.CalibrationTab, 'numeric');
            app.Calib_dEditField.ValueDisplayFormat = '%.2e';
            app.Calib_dEditField.ValueChangedFcn = createCallbackFcn(app, @Calib_UpdateplotsButtonPushed, true);
            app.Calib_dEditField.Value = 0.095;
            app.Calib_CyPeriodEditField = uieditfield(app.CalibrationTab, 'numeric');
            app.Calib_CyPeriodEditField.ValueChangedFcn = createCallbackFcn(app, @Calib_UpdateplotsButtonPushed, true);
            app.Calib_CyPeriodEditField.Editable = 'off';
            app.LLabel = uilabel(app.CalibrationTab);
            app.LLabel.Text = 'L';
            app.EFieldVLabel = uilabel(app.CalibrationTab);
            app.EFieldVLabel.Text = 'E-Field (V)';
            app.EFieldVLabel_2 = uilabel(app.CalibrationTab);
            app.EFieldVLabel_2.Text = 'B-Field (T)';
            app.EFieldVLabel_3 = uilabel(app.CalibrationTab);
            app.EFieldVLabel_3.Text = 'E-Field Sum (V/m)';
            app.EFieldVLabel_4 = uilabel(app.CalibrationTab);
            app.EFieldVLabel_4.Text = 'B-Field Sum (T)';
            app.Calib_BFieldSumEditField = uieditfield(app.CalibrationTab, 'numeric');
            app.Calib_BFieldSumEditField.ValueDisplayFormat = '%.2e';
            app.Calib_BFieldSumEditField.Editable = 'off';
            app.Calib_BFieldSumEditField.Value = 0.0007;
            app.Calib_BFieldSlider = uislider(app.CalibrationTab);
            app.Calib_BFieldSlider.Limits = [-5 5];
            app.Calib_BFieldSlider.ValueChangedFcn = createCallbackFcn(app, @Calib_BFieldSliderValueChanged, true);
            app.Calib_BFieldEditField = uieditfield(app.CalibrationTab, 'numeric');
            app.Calib_BFieldEditField.Limits = [0 Inf];
            app.Calib_BFieldEditField.ValueDisplayFormat = '%.2e';
            app.Calib_BFieldEditField.ValueChangedFcn = createCallbackFcn(app, @Calib_UpdateplotsButtonPushed, true);
            app.Calib_BFieldEditField.Value = 0.0007;
            app.Calib_EFieldSumEditField = uieditfield(app.CalibrationTab, 'numeric');
            app.Calib_EFieldSumEditField.Editable = 'off';
            app.Calib_EFieldSumEditField.Value = 100;
            app.Calib_EFieldSlider = uislider(app.CalibrationTab);
            app.Calib_EFieldSlider.Limits = [-5 5];
            app.Calib_EFieldSlider.ValueChangedFcn = createCallbackFcn(app, @Calib_EFieldSliderValueChanged, true);
            app.Calib_EFieldEditField = uieditfield(app.CalibrationTab, 'numeric');
            app.Calib_EFieldEditField.Limits = [0 Inf];
            app.Calib_EFieldEditField.ValueChangedFcn = createCallbackFcn(app, @Calib_UpdateplotsButtonPushed, true);
            app.Calib_EFieldEditField.Value = 100;
            app.Calib_ScaleEBandtoffsCheckBox = uicheckbox(app.CalibrationTab);
            app.Calib_ScaleEBandtoffsCheckBox.Text = 'Scale E, B, and toffs';
            app.Calib_toffsetEditField = uieditfield(app.CalibrationTab, 'numeric');
            app.Calib_toffsetEditField.ValueChangedFcn = createCallbackFcn(app, @Calib_toffsetEditFieldValueChanged, true);
            app.Calib_CIELparametersLabel = uilabel(app.CalibrationTab);
            app.Calib_CIELparametersLabel.Text = 'CIEL parameters';
            app.EBoffsetEditFieldLabel = uilabel(app.CalibrationTab);
            app.EBoffsetEditFieldLabel.Text = 'E/B offset';
            app.Calib_EBoffsetEditField = uieditfield(app.CalibrationTab, 'numeric');
            app.Calib_UpdateliveCheckBox = uicheckbox(app.CalibrationTab);
            app.Calib_UpdateliveCheckBox.Text = 'Update live';
            app.ScansTab = uitab(app.TabGroup);
            app.ScansTab.Title = 'Scans';
            app.Scans_DelayUP = uiaxes(app.ScansTab);
            title(app.Scans_DelayUP, 'Delay scan UP')
            xlabel(app.Scans_DelayUP, 'X')
            ylabel(app.Scans_DelayUP, 'Y')
            zlabel(app.Scans_DelayUP, 'Z')
            app.Scans_DelayDWN = uiaxes(app.ScansTab);
            title(app.Scans_DelayDWN, 'Delay scan DWN')
            xlabel(app.Scans_DelayDWN, 'X')
            ylabel(app.Scans_DelayDWN, 'Y')
            zlabel(app.Scans_DelayDWN, 'Z')
            app.Scans_CEPDWN = uiaxes(app.ScansTab);
            title(app.Scans_CEPDWN, 'CEP scan UP')
            xlabel(app.Scans_CEPDWN, 'X')
            ylabel(app.Scans_CEPDWN, 'Y')
            zlabel(app.Scans_CEPDWN, 'Z')
            app.Scans_CEPUP = uiaxes(app.ScansTab);
            title(app.Scans_CEPUP, 'CEP scan UP')
            xlabel(app.Scans_CEPUP, 'X')
            ylabel(app.Scans_CEPUP, 'Y')
            zlabel(app.Scans_CEPUP, 'Z')
            app.AngleupLabel = uilabel(app.ScansTab);
            app.AngleupLabel.Text = 'Angle up';
            app.Scans_AngleUPEditField = uieditfield(app.ScansTab, 'numeric');
            app.Scans_AngleUPEditField.Limits = [-180 180];
            app.Scans_AngleUPEditField.Value = 90;
            app.AngledownLabel = uilabel(app.ScansTab);
            app.AngledownLabel.Text = 'Angle down';
            app.Scans_AngleDWNEditField = uieditfield(app.ScansTab, 'numeric');
            app.Scans_AngleDWNEditField.Limits = [-180 180];
            app.Scans_AngleDWNEditField.Value = -90;
            app.Scans_IonposfilterCheckBox = uicheckbox(app.ScansTab);
            app.Scans_IonposfilterCheckBox.Text = '';
            app.Scans_SpeciesfilterCheckBox = uicheckbox(app.ScansTab);
            app.Scans_SpeciesfilterCheckBox.Text = '';
            app.IonposfilterEditFieldLabel_3 = uilabel(app.ScansTab);
            app.IonposfilterEditFieldLabel_3.Text = 'Ion pos filter';
            app.Scans_IonposfilterEditField = uieditfield(app.ScansTab, 'numeric');
            app.Scans_IonposfilterEditField.Limits = [0 Inf];
            app.Scans_IonposfilterEditField.Value = 40;
            app.SpeciesfilterEditFieldLabel_3 = uilabel(app.ScansTab);
            app.SpeciesfilterEditFieldLabel_3.Text = 'Species filter';
            app.Scans_SpeciesfilterEditField = uieditfield(app.ScansTab, 'numeric');
            app.Scans_SpeciesfilterEditField.Limits = [0 Inf];
            app.Scans_SpeciesfilterEditField.RoundFractionalValues = 'on';
            app.Scans_SpeciesfilterEditField.Value = 1;
            app.AngledownLabel_2 = uilabel(app.ScansTab);
            app.AngledownLabel_2.Text = 'Angle span';
            app.Scans_AngleSpanEditField = uieditfield(app.ScansTab, 'numeric');
            app.Scans_AngleSpanEditField.Limits = [2.22044604925031e-16 360];
            app.Scans_AngleSpanEditField.Value = 30;
            app.Scans_UpdateplotsButton = uibutton(app.ScansTab, 'push');
            app.Scans_UpdateplotsButton.ButtonPushedFcn = createCallbackFcn(app, @Scans_UpdateplotsButtonPushed, true);
            app.Scans_UpdateplotsButton.Text = 'Update plots';
            app.EnergyminEditFieldLabel = uilabel(app.ScansTab);
            app.EnergyminEditFieldLabel.Text = 'Energy min';
            app.Scans_EnergyminEditField = uieditfield(app.ScansTab, 'numeric');
            app.Scans_EnergyminEditField.Limits = [0 Inf];
            app.EnergymaxEditFieldLabel = uilabel(app.ScansTab);
            app.EnergymaxEditFieldLabel.Text = 'Energy max';
            app.Scans_EnergymaxEditField = uieditfield(app.ScansTab, 'numeric');
            app.Scans_EnergymaxEditField.Value = 25;
            app.Scans_NormalizesumperdelayCheckBox = uicheckbox(app.ScansTab);
            app.Scans_NormalizesumperdelayCheckBox.Text = 'Normalize sum per delay';
            app.Scans_SavescansButton = uibutton(app.ScansTab, 'push');
            app.Scans_SavescansButton.ButtonPushedFcn = createCallbackFcn(app, @Scans_SavescansButtonPushed, true);
            app.Scans_SavescansButton.Text = 'Save scans';
            app.EnergystepLabel = uilabel(app.ScansTab);
            app.EnergystepLabel.Text = 'Energy step';
            app.Scans_EnergystepEditField = uieditfield(app.ScansTab, 'numeric');
            app.Scans_EnergystepEditField.Value = 0.25;
            app.AnacondaTab = uitab(app.TabGroup);
            app.AnacondaTab.Title = 'Anaconda';
            app.Ana_MdataTree = uitree(app.AnacondaTab);
            app.Ana_MdataTree.SelectionChangedFcn = createCallbackFcn(app, @Ana_MdataTreeSelectionChanged, true);
            app.Ana_DataTree = uitree(app.AnacondaTab);
            app.Ana_DataTree.SelectionChangedFcn = createCallbackFcn(app, @Ana_DataTreeSelectionChanged, true);
            app.Ana_LoadTablesButton = uibutton(app.AnacondaTab, 'push');
            app.Ana_LoadTablesButton.ButtonPushedFcn = createCallbackFcn(app, @Ana_LoadTablesButtonPushed, true);
            app.Ana_LoadTablesButton.Text = 'Load Tables';
            app.FieldvalueTextAreaLabel = uilabel(app.AnacondaTab);
            app.FieldvalueTextAreaLabel.Text = 'Field value';
            app.Ana_FieldvalueTextArea = uitextarea(app.AnacondaTab);
            app.CommandEditFieldLabel = uilabel(app.AnacondaTab);
            app.CommandEditFieldLabel.Text = 'Command:';
            app.Ana_CommandEditField = uieditfield(app.AnacondaTab, 'text');
            app.Ana_mdataLabel = uilabel(app.AnacondaTab);
            app.Ana_mdataLabel.Text = 'mdata';
            app.Ana_DataLabel = uilabel(app.AnacondaTab);
            app.Ana_DataLabel.Text = 'Data';
            app.Ana_RemovefieldButton = uibutton(app.AnacondaTab, 'push');
            app.Ana_RemovefieldButton.ButtonPushedFcn = createCallbackFcn(app, @Ana_RemovefieldButtonPushed, true);
            app.Ana_RemovefieldButton.Text = 'Remove field';
            app.Ana_CorrectButton = uibutton(app.AnacondaTab, 'push');
            app.Ana_CorrectButton.ButtonPushedFcn = createCallbackFcn(app, @Ana_CorrectButtonPushed, true);
            app.Ana_CorrectButton.Text = 'Correct';
            app.Ana_ConvertButton = uibutton(app.AnacondaTab, 'push');
            app.Ana_ConvertButton.ButtonPushedFcn = createCallbackFcn(app, @Ana_ConvertButtonPushed, true);
            app.Ana_ConvertButton.Text = 'Convert';
            app.Ana_PlotButton = uibutton(app.AnacondaTab, 'push');
            app.Ana_PlotButton.ButtonPushedFcn = createCallbackFcn(app, @Ana_PlotButtonPushed, true);
            app.Ana_PlotButton.Text = 'Plot';
            app.LogLabel = uilabel(app.AnacondaTab);
            app.LogLabel.Text = 'Log';
            app.Ana_AnacondaLogTextArea = uitextarea(app.AnacondaTab);
            app.Ana_GoButton = uibutton(app.AnacondaTab, 'push');
            app.Ana_GoButton.ButtonPushedFcn = createCallbackFcn(app, @Ana_GoButtonPushed, true);
            app.Ana_GoButton.Text = 'Go';
            app.SetallifdofieldstoEditFieldLabel = uilabel(app.AnacondaTab);
            app.SetallifdofieldstoEditFieldLabel.Text = 'Set all "ifdo" fields to:';
            app.Ana_SetallifdofieldstoEditField = uieditfield(app.AnacondaTab, 'numeric');
            app.Ana_SetallifdofieldstoEditField.Limits = [0 1];
            app.Ana_SetallifdofieldstoEditField.RoundFractionalValues = 'on';
            app.Ana_corrCheckBox = uicheckbox(app.AnacondaTab);
            app.Ana_corrCheckBox.Text = 'corr';
            app.Ana_convCheckBox = uicheckbox(app.AnacondaTab);
            app.Ana_convCheckBox.Text = 'conv';
            app.Ana_plotCheckBox = uicheckbox(app.AnacondaTab);
            app.Ana_plotCheckBox.Text = 'plot';
            app.Ana_GoifdoButton = uibutton(app.AnacondaTab, 'push');
            app.Ana_GoifdoButton.ButtonPushedFcn = createCallbackFcn(app, @Ana_GoifdoButtonPushed, true);
            app.Ana_GoifdoButton.Text = 'Go';
            app.Ana_CloseallfiguresButton = uibutton(app.AnacondaTab, 'push');
            app.Ana_CloseallfiguresButton.ButtonPushedFcn = createCallbackFcn(app, @Ana_CloseallfiguresButtonPushed, true);
            app.Ana_CloseallfiguresButton.Text = 'Close all figures';
            app.Ana_CreateUpdateTabs = uitabgroup(app.AnacondaTab);
            app.PlotsTab = uitab(app.Ana_CreateUpdateTabs);
            app.PlotsTab.Title = 'Plots';
            app.Ana_CP_CreateButton = uibutton(app.PlotsTab, 'push');
            app.Ana_CP_CreateButton.ButtonPushedFcn = createCallbackFcn(app, @Ana_CP_CreateButtonPushed, true);
            app.Ana_CP_CreateButton.Text = 'Create/Update';
            app.Signal1EditFieldLabel = uilabel(app.PlotsTab);
            app.Signal1EditFieldLabel.Text = 'Signal 1';
            app.Ana_CP_Signal1EditField = uieditfield(app.PlotsTab, 'text');
            app.Ana_CP_Signal1EditField.Value = 'i_TOF';
            app.Signal2EditFieldLabel = uilabel(app.PlotsTab);
            app.Signal2EditFieldLabel.Text = 'Signal 2';
            app.Ana_CP_Signal2EditField = uieditfield(app.PlotsTab, 'text');
            app.Ana_CP_Signal2EditField.Value = 'i_Y';
            app.LimLabel = uilabel(app.PlotsTab);
            app.LimLabel.Text = 'Lim';
            app.Ana_CP_XLim1EditField = uieditfield(app.PlotsTab, 'text');
            app.Ana_CP_XLim1EditField.Value = '0,10000';
            app.XLabelEditFieldLabel = uilabel(app.PlotsTab);
            app.Ana_CP_XLabel1EditField = uieditfield(app.PlotsTab, 'text');
            app.Ana_CP_XLabel1EditField.Value = 'Ion TOF (ns)';
            app.YLabelEditField_2Label = uilabel(app.PlotsTab);
            app.YLabelEditField_2Label.Text = 'Title';
            app.Ana_CP_Title1EditField = uieditfield(app.PlotsTab, 'text');
            app.Ana_CP_Title1EditField.Value = 'Ion FishY';
            app.LimLabel_2 = uilabel(app.PlotsTab);
            app.LimLabel_2.Text = 'Lim';
            app.Ana_CP_XLim2EditField = uieditfield(app.PlotsTab, 'text');
            app.Ana_CP_XLim2EditField.Value = '-40,40';
            app.XLabelEditField_2Label_2 = uilabel(app.PlotsTab);
            app.Ana_CP_XLabel2EditField = uieditfield(app.PlotsTab, 'text');
            app.Ana_CP_XLabel2EditField.Value = 'Ion Y (mm)';
            app.ConditionEditFieldLabel = uilabel(app.PlotsTab);
            app.ConditionEditFieldLabel.Text = 'Condition';
            app.Ana_CP_ConditionEditField = uieditfield(app.PlotsTab, 'text');
            app.NameEditFieldLabel = uilabel(app.PlotsTab);
            app.NameEditFieldLabel.Text = 'Name';
            app.Ana_CP_NameEditField = uieditfield(app.PlotsTab, 'text');
            app.Ana_CP_NameEditField.Value = 'FishY';
            app.DetectorEditFieldLabel = uilabel(app.PlotsTab);
            app.DetectorEditFieldLabel.Text = 'Detector';
            app.Ana_CP_DetectorEditField = uieditfield(app.PlotsTab, 'text');
            app.Ana_CP_DetectorEditField.Value = 'det2';
            app.SignalsTab = uitab(app.Ana_CreateUpdateTabs);
            app.SignalsTab.Title = 'Signals';
            app.Ana_CopyfieldtocommandlineButton = uibutton(app.AnacondaTab, 'push');
            app.Ana_CopyfieldtocommandlineButton.ButtonPushedFcn = createCallbackFcn(app, @Ana_CopyfieldtocommandlineButtonPushed, true);
            app.Ana_CopyfieldtocommandlineButton.Text = 'Copy field to command line';
            app.AboutTab = uitab(app.TabGroup);
            app.AboutTab.Title = 'About';
            app.AbouttheappTextAreaLabel = uilabel(app.AboutTab);
            app.AbouttheappTextAreaLabel.Text = 'About the app';
            app.AbouttheappTextArea = uitextarea(app.AboutTab);
            app.AbouttheappTextArea.Editable = 'off';
            app.AbouttheappTextArea.Value = {'This app visualizes data taken with the CIEL spectrometer.'; ''; 'All design was made using data aquired in the MHz lab at Atomic Physics. In theory it should work with data from other sources, and with a little tweaking, other spectrometers, but there might be unforseen conflicts. '; ''; 'In the preview tab, the data from the different detectors is visualized and statistics regarding the measurement are calculated.'; ''; 'In the calibration tab, The data can be corrected and the spectrometer settings tweaked in various ways. The raw data can then be converted to e.g. momentum.'; ''; 'In the scans tab, plots of data vs. source parameters (e.g. delay) can be made.'; ''; 'By Sara Mikaelsson, sara.mikaelsson@gmail.com, Spring 2022'};
            app.LoaddataButton = uibutton(app.CIELAnalysisAppUIFigure, 'push');
            app.LoaddataButton.ButtonPushedFcn = createCallbackFcn(app, @LoaddataButtonPushed, true);
            app.LoaddataButton.Text = 'Load';
            app.BrowsedataButton = uibutton(app.CIELAnalysisAppUIFigure, 'push');
            app.BrowsedataButton.ButtonPushedFcn = createCallbackFcn(app, @BrowsedataButtonPushed, true);
            app.BrowsedataButton.Text = 'Browse';
            app.PathtodatafileLabel = uilabel(app.CIELAnalysisAppUIFigure);
            app.PathtodatafileLabel.Text = 'Path to data:';
            app.PathtofileEditField = uieditfield(app.CIELAnalysisAppUIFigure, 'text');
            app.PathtofileEditField.Tooltip = {''};
            app.PathtofileEditField.Value = 'G:\Atto\Lab transfers\MHz_CIEL\';
            app.LoadmetaButton = uibutton(app.CIELAnalysisAppUIFigure, 'push');
            app.LoadmetaButton.ButtonPushedFcn = createCallbackFcn(app, @LoadmetaButtonPushed, true);
            app.LoadmetaButton.Text = 'Load ';
            app.BrowsemetaButton = uibutton(app.CIELAnalysisAppUIFigure, 'push');
            app.BrowsemetaButton.ButtonPushedFcn = createCallbackFcn(app, @BrowsemetaButtonPushed, true);
            app.BrowsemetaButton.Text = 'Browse';
            app.PathtodatafileLabel_2 = uilabel(app.CIELAnalysisAppUIFigure);
            app.PathtodatafileLabel_2.Text = 'Path to metadata:';
            app.PathtometaEditField = uieditfield(app.CIELAnalysisAppUIFigure, 'text');
            app.PathtometaEditField.Value = 'G:\Atto\Lab transfers\MHz_CIEL\';
            app.SavemetaButton = uibutton(app.CIELAnalysisAppUIFigure, 'push');
            app.SavemetaButton.ButtonPushedFcn = createCallbackFcn(app, @SavemetaButtonPushed, true);
            app.SavemetaButton.Text = 'Save metadata';
            app.ReduceButton = uibutton(app.CIELAnalysisAppUIFigure, 'push');
            app.ReduceButton.ButtonPushedFcn = createCallbackFcn(app, @ReduceButtonPushed, true);
            app.ReduceButton.Text = 'Reduce';
            app.toLabel = uilabel(app.CIELAnalysisAppUIFigure);
            app.toLabel.Text = 'to %';
            app.ReducebyEditField = uieditfield(app.CIELAnalysisAppUIFigure, 'numeric');
            app.ReducebyEditField.Value = 50;
            app.OverwritecurrentmetaCheckBox = uicheckbox(app.CIELAnalysisAppUIFigure);
            app.OverwritecurrentmetaCheckBox.Text = 'Overwrite current';
            app.SavereducedButton = uibutton(app.CIELAnalysisAppUIFigure, 'push');
            app.SavereducedButton.ButtonPushedFcn = createCallbackFcn(app, @SavereducedButtonPushed, true);
            app.SavereducedButton.Text = 'Save reduced';
            % Show the figure after all components are created
            app.CIELAnalysisAppUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CIEL_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.CIELAnalysisAppUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.CIELAnalysisAppUIFigure)
        end
    end
end